classdef esr_rig_analysis_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryandPlotsforFlowDataUIFigure  matlab.ui.Figure
        ColumnsListBoxLabel          matlab.ui.control.Label
        esrListBox                   matlab.ui.control.ListBox
        OpenFilesButton              matlab.ui.control.Button
        TestPointsDropDownLabel      matlab.ui.control.Label
        TestPointsDropDown           matlab.ui.control.DropDown
        PlotESRButton                matlab.ui.control.Button
        rigListBox                   matlab.ui.control.ListBox
        SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel  matlab.ui.control.Label
        PlotRIGButton                matlab.ui.control.Button
        AverageCheckBox              matlab.ui.control.CheckBox
        MaximumCheckBox              matlab.ui.control.CheckBox
        MinimumCheckBox              matlab.ui.control.CheckBox
        SaveSummaryTableAsButton     matlab.ui.control.Button
        OpenSavedSummaryButton       matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ButtonGroup_3                matlab.ui.container.ButtonGroup
        R10Button                    matlab.ui.control.RadioButton
        R8Button                     matlab.ui.control.RadioButton
        CalculateandPlotFlowButton   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        ExportFlowDataButton         matlab.ui.control.Button
        K1ChangeEditFieldLabel       matlab.ui.control.Label
        K1ChangeEditField            matlab.ui.control.NumericEditField
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        ToSummaryButton              matlab.ui.control.RadioButton
        AsNewButton                  matlab.ui.control.RadioButton
        SavedLabel                   matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        OpenButton                   matlab.ui.control.Button
        SlopeEditField_2             matlab.ui.control.NumericEditField
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        HoseEndButton                matlab.ui.control.RadioButton
        LatchButton                  matlab.ui.control.RadioButton
        ELabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        PlotCouplingLossButton       matlab.ui.control.Button
        ButtonGroup_6                matlab.ui.container.ButtonGroup
        AllTestPointsButton_2        matlab.ui.control.RadioButton
        SelectedTestPointButton_2    matlab.ui.control.RadioButton
        CouplingRegulationKnobLabel  matlab.ui.control.Label
        CouplingRegulationKnob       matlab.ui.control.DiscreteKnob
    end

    properties (Access = public)
        
        % Global variables
        esr
        rig
        testpoint = 'Select'
        file
        path
        sfile
        spath
        dfile
        dpath
        matrix
        matrix2
        hose
        latch
        Qrig
        
    end

    methods (Access = private)

        % Button pushed function: OpenFilesButton
        function OpenFilesButtonPushed(app, event)
            % Opens file selection window
            [app.file,app.path] = uigetfile('*.csv','MultiSelect', 'on');
            app.file = sort(app.file);
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised
            if ~isequal(app.file, 0)            
                app.TestPointsDropDown.Items = {'Select'};
                % check number of files are even
                if mod(length(app.file),2) == 0
                    % check no. of esr = no. of rig
                    if app.file{length(app.file)/2}(1:3) ~= app.file{length(app.file)/2+1}(1:3)
                        for i = 1:length(app.file)/2
                            % Assign test point names to drop down menu
                            app.TestPointsDropDown.Items = [app.TestPointsDropDown.Items cellstr(app.file{i}(5:end-4))];                    
                        end
                    else
                        msgbox('Make sure each test point has a PAIR of csv files selected', 'Error', 'error');
                        app.file = []; % empty file name for future checks
                    end
                else
                    msgbox('Make sure each test point has a PAIR of csv files selected', 'Error', 'error');
                    app.file = [];
                end
            else
                app.file = [];
            end
        end

        % Button pushed function: PlotESRButton
        function PlotESRButtonPushed(app, event)
            % Check correct inputs
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if length(app.esrListBox.Value)<2                
                msgbox('Select Time Axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.esrListBox.Value{1},'Time')))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            % Plots selected columns against time column
            figure(1)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('ESR')
            grid           
            for i = 2:length(app.esrListBox.Value)
                plot(app.esr.(app.esrListBox.Value{1}),app.esr.(app.esrListBox.Value{i}))
            end
            legend(app.esrListBox.Value(2:end))
        end

        % Button pushed function: PlotRIGButton
        function PlotRIGButtonPushed(app, event)
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if length(app.rigListBox.Value)<2                
                msgbox('Select Time axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.rigListBox.Value{1},'Time')))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            figure(2)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('RIG')
            grid           
            for i = 2:length(app.rigListBox.Value)
                plot(app.rig.(app.rigListBox.Value{1}),app.rig.(app.rigListBox.Value{i}))
            end
            legend(app.rigListBox.Value(2:end))
        end

        % Value changed function: TestPointsDropDown
        function TestPointsDropDownValueChanged(app, event)
            % Seletet test point for plotting selected columns and for later export single test point summary
            
            app.testpoint = app.TestPointsDropDown.Value;
            if ~isequal(app.testpoint,'Select')
                k = find(contains(app.file,app.testpoint));           
                fid = fopen(fullfile(app.path,app.file{k(1)}),'r'); %Found there was a ',' at the end of csv variables, so readtable thinks there's +1 variables.
                str = fgetl(fid);
                fclose(fid);
                vars = regexp(str, ',', 'split');
                if isempty(vars{end}) %gets rid of the ',' end term.
                    vars = matlab.lang.makeValidName(vars(1:end-1));
                    app.esr = readtable(fullfile(app.path,app.file{k(1)}), 'readvariablenames', false);
                    app.esr.Properties.VariableNames = vars;
                else
                    app.esr = readtable(fullfile(app.path,app.file{k(1)}), 'readvariablenames', true);
                end
                fid2 = fopen(fullfile(app.path,app.file{k(2)}),'r');
                str = fgetl(fid2);
                fclose(fid2);
                vars = regexp(str, ',', 'split');
                if isempty(vars{end}) 
                    vars = matlab.lang.makeValidName(vars(1:end-1));
                    app.rig = readtable(fullfile(app.path,app.file{k(2)}), 'readvariablenames', false);
                    app.rig.Properties.VariableNames = vars;
                else
                    app.rig = readtable(fullfile(app.path,app.file{k(2)}), 'readvariablenames', true);
                end                                   
               
                %Load Column headers list box
                for i = 1:width(app.esr)
                    app.esrListBox.Items{i} = app.esr.Properties.VariableNames{i};
                end
                for i = 1:width(app.rig)
                    app.rigListBox.Items{i} = app.rig.Properties.VariableNames{i};
                end
            end
        end

        % Button pushed function: SaveSummaryTableAsButton
        function SaveSummaryTableAsButtonPushed(app, event)
            % Check correct input
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if app.AverageCheckBox.Value == false & app.MaximumCheckBox.Value == false & app.MinimumCheckBox.Value == false
                msgbox('Select Type of Statistic to Save', 'Error', 'error');
                return
            end
            
            % Opens 'save as' window for user to choose file path and name
            [app.sfile , app.spath] = uiputfile({'*.xls'},'Save As','summary.xls');
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            if app.sfile ~=0
                % Makes sure file is writable (not opened)
                try
                    xlswrite([app.spath app.sfile],1:5) % tries to write a random array to xls
                catch ME
                    msgbox('Close summary excel window and try again','Error','error');
                    return
                end
            end
            
             % Check to export all or single test point
            if app.AllTestPointsButton_2.Value == true
                m=1;
                n = length(app.file)/2;
            else                
                if isequal(app.testpoint,'Select')
                    msgbox('Select data test point', 'Error', 'error');
                    return               
                else
                    k=find(contains(app.file,app.testpoint));
                    m=k(1);
                    n=m;
                end
            end
            
            for i = m:n %iterate over each test point
                tic
                fid = fopen(fullfile(app.path,app.file{i}),'r'); 
                str = fgetl(fid);
                fclose(fid);
                vars = regexp(str, ',', 'split');
                if isempty(vars{end})
                    vars = matlab.lang.makeValidName(vars(1:end-1));
                    esr = readtable(fullfile(app.path,app.file{i}), 'readvariablenames', false);
                    esr.Properties.VariableNames = vars;
                else
                    esr = readtable(fullfile(app.path,app.file{i}), 'readvariablenames', true);
                end
                fid2 = fopen(fullfile(app.path,app.file{(length(app.file)/2)+i}),'r');
                str = fgetl(fid2);
                fclose(fid2);
                vars = regexp(str, ',', 'split');
                if isempty(vars{end}) 
                    vars = matlab.lang.makeValidName(vars(1:end-1));
                    rig = readtable(fullfile(app.path,app.file{(length(app.file)/2)+i}), 'readvariablenames', false);
                    rig.Properties.VariableNames = vars;
                else
                    rig = readtable(fullfile(app.path,app.file{(length(app.file)/2)+i}), 'readvariablenames', true);
                end
                toc
                % Declare variables into correct size and titles
                if i == m
                    [average_esr,max_esr,min_esr] = deal(zeros(1,width(esr)-1));
                    [average_rig,max_rig,min_rig] = deal(zeros(1,width(rig)-3));
                    avg = [{'Average'},cell(1,width(esr)),{'Average'},cell(1,width(rig)-3)];
                    maxi = [{'Maximum'},cell(1,width(esr)),{'Maximum'},cell(1,width(rig)-3)];
                    mini = [{'Minimum'},cell(1,width(esr)),{'Minimum'},cell(1,width(rig)-3)];
                end
                
                % average,max,min value calculation for this test point in the iteration, and is appended to corresponding matrix
                if app.AverageCheckBox.Value == true                    
                    for j = 2:width(esr)
                        average_esr(j-1) = mean(esr{:,j});
                    end
                    for j = 4:width(rig)
                        average_rig(j-3) = mean(rig{:,j});
                    end
                    avg = [avg;cellstr(app.file{i}(1:end-4)),num2cell(average_esr),cell(1,1),cellstr(app.file{(length(app.file)/2)+i}(1:end-4)),num2cell(average_rig)];
                else
                    avg = [];
                end
                
                if app.MaximumCheckBox.Value == true                    
                    for j = 2:width(esr)
                        max_esr(j-1) = max(esr{:,j});
                    end
                    for j = 4:width(rig)
                        max_rig(j-3) = max(rig{:,j});
                    end                
                    maxi = [maxi;cellstr(app.file{i}(1:end-4)),num2cell(max_esr),cell(1,1),cellstr(app.file{(length(app.file)/2)+i}(1:end-4)),num2cell(max_rig)];
                else
                    maxi = [];
                end
                
                if app.MinimumCheckBox.Value == true                    
                    for j = 2:width(esr)
                        min_esr(j-1) = min(esr{:,j});
                    end
                    for j = 4:width(rig)
                        min_rig(j-3) = min(rig{:,j});
                    end                
                    mini = [mini;cellstr(app.file{i}(1:end-4)),num2cell(min_esr),cell(1,1),cellstr(app.file{(length(app.file)/2)+i}(1:end-4)),num2cell(min_rig)];                
                else
                    mini = [];
                end
            end
            
            % combine avg, maxi and mini into main matrix to be exported
            app.matrix=[cell(1,1), esr.Properties.VariableNames(2:end),cell(1,2),rig.Properties.VariableNames(4:end);
                avg;cell(1,width(esr)+width(rig)-1);maxi;cell(1,width(esr)+width(rig)-1);mini;cell(1,width(esr)+width(rig)-1)];
            
            if app.sfile ~=0
                if exist([app.spath app.sfile],'file')
                    delete([app.spath app.sfile]);
                end
                xlswrite([app.spath app.sfile],app.matrix)
                app.Lamp.Color = [0 1 0];
            else
                app.sfile = '';
            end
        end

        % Button pushed function: OpenSavedSummaryButton
        function OpenSavedSummaryButtonPushed(app, event)
            % Opens saved summary file
            if exist([app.spath app.sfile],'file')
                winopen([app.spath app.sfile])
            else
                msgbox('No Summary File Found', 'Error', 'error');
            end
        end

        % Button pushed function: CalculateandPlotFlowButton
        function CalculateandPlotFlowButtonPushed(app, event)
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            % Different K1 values for different RCU versions
            K2 = 1.1544;
            if app.R10Button.Value == true
                K1 = 8.12e-5;                
            else
                K1 = 7.91e-5;
            end
            
            % Iterate each test point and do calculations 
            for i = 1:length(app.file)/2
                fid = fopen(fullfile(app.path,app.file{i}),'r'); 
                str = fgetl(fid);
                fclose(fid);
                vars = regexp(str, ',', 'split');
                if isempty(vars{end}) 
                    vars = matlab.lang.makeValidName(vars(1:end-1));
                    esr = readtable(fullfile(app.path,app.file{i}), 'readvariablenames', false);
                    esr.Properties.VariableNames = vars;
                else
                    esr = readtable(fullfile(app.path,app.file{i}), 'readvariablenames', true);
                end
                fid2 = fopen(fullfile(app.path,app.file{(length(app.file)/2)+i}),'r');
                str = fgetl(fid2);
                fclose(fid2);
                vars = regexp(str, ',', 'split');
                if isempty(vars{end}) 
                    vars = matlab.lang.makeValidName(vars(1:end-1));
                    rig = readtable(fullfile(app.path,app.file{(length(app.file)/2)+i}), 'readvariablenames', false);
                    rig.Properties.VariableNames = vars;
                else
                    rig = readtable(fullfile(app.path,app.file{(length(app.file)/2)+i}), 'readvariablenames', true);
                end
                
                % find columns with flow rate equation variables to calculate + plot
                if i == 1
                    p=find(contains(rig.Properties.VariableNames,'DensityMeter') & ~contains(rig.Properties.VariableNames,'Coriolis'));                    
                    q=find(contains(esr.Properties.VariableNames,'OutletPressure'));
                    h=find(contains(rig.Properties.VariableNames,'HoseEnd'));
                    l=find(contains(rig.Properties.VariableNames,'Latch'));
                    s=find(contains(esr.Properties.VariableNames,'FuelFlow') & ~contains(esr.Properties.VariableNames,'Limit'));
                    t=find(contains(rig.Properties.VariableNames,'FuelFlow'));
                    u=find(contains(esr.Properties.VariableNames,'HoseEnd'));
                    [Pout,Pher,Pl,Qe,Qr,rho,calPhe,Phee] = deal(zeros(1,length(app.file)/2));
                end
                
                % create array of average values for searched columns for each test point 
                rho(i) = mean(rig{:,p});                
                Pout(i) = mean(esr{:,q});
                Pl(i) = mean(rig{:,l});
                Pher(i) = mean(rig{:,h});  
                Qe(i) = (mean(esr{:,s}))^2;
                Qr(i) = (mean(rig{:,t}))^2;
                calPhe(i) = Pout(i) - (K1*rho(i)/119.8)*Qe(i) + K2*rho(i)/119.8;
                Phee(i) = mean(esr{:,u});
            end
            
            % For later plotting coupling losses use
            app.hose = Pher;
            app.latch = Pl;
            app.Qrig = Qr;
            
            % Clears axes, calculates specific values in flow equation and plots as required to find slope
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            avgrho = mean(rho);
            
            % If exclude chosen, changes arrays to shorter version, excluding data if Hose End Pressure > 50
            include = Pher <= 50;
            if app.LatchButton.Value == true
                if isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{2}) & app.LatchButton.Value == true
                    avgrho = mean(rho(include));
                    Pe = Pl(include);
                    Pout = Pout(include);
                    Qe = Qe(include);
                    Qr = Qr(include);
                elseif isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{1}) & app.LatchButton.Value == true
                    Pe = Pl;
                else
                    avgrho = mean(rho(~include));
                    Pe = Pl(~include);
                    Pout = Pout(~include);
                    Qe = Qe(~include);
                    Qr = Qr(~include);
                end
            else
                Pe = Pher;
            end
            
            Phead = avgrho*9.81*4.21/6894.757; % Pressure gained from catenary (change of units)
            y = Pout - Pe + Phead;
            if isempty(Qe) % To prevent index error on dP below
                msgbox('All points excluded','Warning','warn');
                return
            end
            dP = (avgrho/119.8)*K1*Qe(end);             
            
            m1 = Qe'\y'; % Slope of y = mx
            app.K1ChangeEditField.Value = 100*(m1/(avgrho/119.8)-K1)/K1;
            plot(app.UIAxes,Qe,y, 'or')
            plot(app.UIAxes,[0,Qe],[0,Qe*m1],'b')
            plot(app.UIAxes,[0,Qe(end)],[0,dP],'g')
            legend(app.UIAxes,'Data points','Best Fit through 0','RCU estimation','Location','northwest') 
            m2 = Qr'\y'; % Slope of y = mx
            app.SlopeEditField_2.Value = 100*(m2/(avgrho/119.8)-K1)/K1;
            plot(app.UIAxes_2,Qr,y,'or')
            plot(app.UIAxes_2,[0,Qr],[0,Qr*m2],'b')
            plot(app.UIAxes_2,[0,Qe(end)],[0,dP],'g')
            
            % for later export use (flow calculation data)
            app.matrix2 = [{'avg. rho'},avgrho,cell(1,length(rho)-1);{'Phead'},Phead,cell(1,length(rho)-1);cell(1,length(rho)+1); {'Pout-Pend+Phead'},num2cell(y),cell(1,length(rho)-length(y));{'Q^2 esr'},num2cell(Qe),cell(1,length(rho)-length(y));{'Q^2 rig'},num2cell(Qr),cell(1,length(rho)-length(y));
                {'Q^2 (RCU)'},num2cell(Qe(end)),cell(1,length(rho)-1);{'dP (RCU)'},num2cell(dP),cell(1,length(rho)-1);cell(1,length(rho)+1);{'Calculated Phe'},num2cell(calPhe);{'% Difference'},num2cell(100*(Phee-calPhe)./Phee);
                cell(1,length(rho)+1);[{'slope esr'},m1,{'New K1'},m1/(avgrho/119.8),{'% Difference'},100*(m1/(avgrho/119.8)-K1)/K1]',[{'slope rig'},m2,{'New K1'},m2/(avgrho/119.8),{'% Difference'},100*(m2/(avgrho/119.8)-K1)/K1]',cell(6,length(rho)-1)];
            app.matrix2 = app.matrix2';    
        end

        % Button pushed function: ExportFlowDataButton
        function ExportFlowDataButtonPushed(app, event)
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end            
            if isempty(app.matrix2)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Appends new data to summary or make new excel file 
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    % Makes sure file is writable (not opened)
                    try
                        xlswrite([app.spath app.sfile],app.matrix)
                    catch ME
                        msgbox('Close summary excel window and try again','Error','error');
                        return
                    end
                    matrix3 = [app.matrix;cell(1,size(app.matrix,2));app.matrix2,cell(size(app.matrix2,1),size(app.matrix,2)-size(app.matrix2,2))];
                    xlswrite([app.spath app.sfile],matrix3)
                    app.Lamp_2.Color = [0 1 0];
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else                
                [app.dfile , app.dpath] = uiputfile({'*.xls'},'Save As','flow_data.xls');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if app.dfile ~=0
                    if exist([app.dpath app.dfile],'file')
                        delete([app.dpath app.dfile]);
                    end
                    try
                        xlswrite([app.dpath app.dfile],app.matrix2)
                    catch ME
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    end
                    xlswrite([app.dpath app.dfile],app.matrix2)
                    app.Lamp_2.Color = [0 1 0];
                else
                    app.dfile = '';
                end
            end    
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Opens Flow Data (either summary or new)
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    winopen([app.spath app.sfile])
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else
                if exist([app.dpath app.dfile],'file')
                    winopen([app.dpath app.dfile])
                else
                    msgbox('No Data File Found', 'Error', 'error');
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            % Changes y axes labels for Hose End / Latch Pressure
            if app.HoseEndButton.Value == true
                ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)');
                ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)');
                app.CouplingRegulationKnob.Enable = 'off';
            else
                ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (psi)');
                ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (psi)');
                app.CouplingRegulationKnob.Enable = 'on';
            end            
        end

        % Button pushed function: PlotCouplingLossButton
        function PlotCouplingLossButtonPushed(app, event)
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.hose)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Import and separate variables
            Phe = app.hose;
            Pl = app.latch;
            Qr = app.Qrig;
            dP = Phe - Pl;            
            dP_1 = dP(Phe<=50);
            dP_2 = dP(Phe>50);
            Qr_1 = Qr(Phe<=50);
            Qr_2 = Qr(Phe>50);
            
            % Plot the 2 sets of data as scattered points and different colours
            hold on
            xlabel('Rig Fuel Flow (USgpm)')
            ylabel('MA4 Losses (psi)')
            title('Coupling Pressure Losses')
            grid
            plot(Qr_1,dP_1,'ob','MarkerFaceColor', 'b')
            plot(Qr_2,dP_2,'or','MarkerFaceColor', 'r')
            legend('Regulators Opened','Regulators Closed','Location','northwest')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryandPlotsforFlowDataUIFigure = uifigure;
            app.SummaryandPlotsforFlowDataUIFigure.Name = 'Summary and Plots for Flow Data';
            app.ColumnsListBoxLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.ColumnsListBoxLabel.Text = 'Columns';
            app.esrListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.esrListBox.Items = {};
            app.esrListBox.Multiselect = 'on';
            app.esrListBox.Value = {};
            app.OpenFilesButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenFilesButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFilesButtonPushed, true);
            app.OpenFilesButton.Text = 'Open Files';
            app.TestPointsDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDownLabel.Text = 'Test Points';
            app.TestPointsDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDown.Items = {'Select'};
            app.TestPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointsDropDownValueChanged, true);
            app.TestPointsDropDown.Value = 'Select';
            app.PlotESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotESRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotESRButtonPushed, true);
            app.PlotESRButton.Text = 'Plot ESR';
            app.rigListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.rigListBox.Items = {};
            app.rigListBox.Multiselect = 'on';
            app.rigListBox.Value = {};
            app.SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel.Text = 'Select test point and  columns to plot, include the Time column as first selection';
            app.PlotRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotRIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRIGButtonPushed, true);
            app.PlotRIGButton.Text = 'Plot RIG';
            app.AverageCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MaximumCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MinimumCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.MinimumCheckBox.Text = 'Minimum';
            app.SaveSummaryTableAsButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.SaveSummaryTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSummaryTableAsButtonPushed, true);
            app.SaveSummaryTableAsButton.Text = 'Save Summary Table As...';
            app.OpenSavedSummaryButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenSavedSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSavedSummaryButtonPushed, true);
            app.OpenSavedSummaryButton.Text = 'Open Saved Summary';
            app.UIAxes = uiaxes(app.SummaryandPlotsforFlowDataUIFigure);
            title(app.UIAxes, 'ESR Fuel Flow')
            xlabel(app.UIAxes, 'Q^2 (USgpm^2)')
            ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.ButtonGroup_3 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.R10Button = uiradiobutton(app.ButtonGroup_3);
            app.R10Button.Text = 'R10';
            app.R10Button.Value = true;
            app.R8Button = uiradiobutton(app.ButtonGroup_3);
            app.R8Button.Text = 'R8';
            app.CalculateandPlotFlowButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.CalculateandPlotFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotFlowButtonPushed, true);
            app.CalculateandPlotFlowButton.Text = 'Calculate and Plot Flow';
            app.UIAxes_2 = uiaxes(app.SummaryandPlotsforFlowDataUIFigure);
            title(app.UIAxes_2, 'RIG Fuel Flow')
            xlabel(app.UIAxes_2, 'Q^2 (USgpm^2)')
            ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.ExportFlowDataButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ExportFlowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFlowDataButtonPushed, true);
            app.ExportFlowDataButton.Text = 'Export Flow Data';
            app.K1ChangeEditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.K1ChangeEditFieldLabel.Text = 'K1 Change (%)';
            app.K1ChangeEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.K1ChangeEditField.ValueDisplayFormat = '%.5f';
            app.K1ChangeEditField.Editable = 'off';
            app.ButtonGroup_4 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ToSummaryButton = uiradiobutton(app.ButtonGroup_4);
            app.ToSummaryButton.Text = 'To Summary';
            app.ToSummaryButton.Value = true;
            app.AsNewButton = uiradiobutton(app.ButtonGroup_4);
            app.AsNewButton.Text = 'As New';
            app.SavedLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SavedLabel.Text = 'Saved?';
            app.Lamp = uilamp(app.SummaryandPlotsforFlowDataUIFigure);
            app.Lamp_2 = uilamp(app.SummaryandPlotsforFlowDataUIFigure);
            app.OpenButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SlopeEditField_2 = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SlopeEditField_2.ValueDisplayFormat = '%.5f';
            app.SlopeEditField_2.Editable = 'off';
            app.ButtonGroup_5 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.HoseEndButton = uiradiobutton(app.ButtonGroup_5);
            app.HoseEndButton.Text = 'Hose End';
            app.HoseEndButton.Value = true;
            app.LatchButton = uiradiobutton(app.ButtonGroup_5);
            app.LatchButton.Text = 'Latch';
            app.ELabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.RLabel.Text = 'R';
            app.PlotCouplingLossButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotCouplingLossButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCouplingLossButtonPushed, true);
            app.PlotCouplingLossButton.Text = 'Plot Coupling Loss';
            app.ButtonGroup_6 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.AllTestPointsButton_2 = uiradiobutton(app.ButtonGroup_6);
            app.AllTestPointsButton_2.Text = 'All Test Points';
            app.AllTestPointsButton_2.Value = true;
            app.SelectedTestPointButton_2 = uiradiobutton(app.ButtonGroup_6);
            app.SelectedTestPointButton_2.Text = 'Selected Test Point';
            app.CouplingRegulationKnobLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.CouplingRegulationKnobLabel.Enable = 'off';
            app.CouplingRegulationKnobLabel.Text = 'Coupling Regulation';
            app.CouplingRegulationKnob = uiknob(app.SummaryandPlotsforFlowDataUIFigure, 'discrete');
            app.CouplingRegulationKnob.Items = {'All Data', 'Remove >50', 'Excluded'};
            app.CouplingRegulationKnob.Enable = 'off';
            app.CouplingRegulationKnob.Value = 'All Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = esr_rig_analysis_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryandPlotsforFlowDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryandPlotsforFlowDataUIFigure)
        end
    end
end