classdef esr_rig_analysis_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryandPlotsforFlowDataUIFigure  matlab.ui.Figure
        ColumnsListBoxLabel          matlab.ui.control.Label
        esrListBox                   matlab.ui.control.ListBox
        OpenRIGButton                matlab.ui.control.Button
        TestPointsDropDownLabel      matlab.ui.control.Label
        TestPointsDropDown           matlab.ui.control.DropDown
        PlotESRButton                matlab.ui.control.Button
        rigListBox                   matlab.ui.control.ListBox
        SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel  matlab.ui.control.Label
        PlotRIGButton                matlab.ui.control.Button
        AverageCheckBox              matlab.ui.control.CheckBox
        MaximumCheckBox              matlab.ui.control.CheckBox
        MinimumCheckBox              matlab.ui.control.CheckBox
        SaveSummaryTableAsButton     matlab.ui.control.Button
        OpenSavedSummaryButton       matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ButtonGroup_3                matlab.ui.container.ButtonGroup
        R10Button                    matlab.ui.control.RadioButton
        R8Button                     matlab.ui.control.RadioButton
        CalculateandPlotFlowButton   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        ExportFlowDataButton         matlab.ui.control.Button
        K1ChangeEditFieldLabel       matlab.ui.control.Label
        K1ChangeEditField            matlab.ui.control.NumericEditField
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        ToSummaryButton              matlab.ui.control.RadioButton
        AsNewButton                  matlab.ui.control.RadioButton
        SavedLabel                   matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        OpenButton                   matlab.ui.control.Button
        SlopeEditField_2             matlab.ui.control.NumericEditField
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        HoseEndButton                matlab.ui.control.RadioButton
        LatchButton                  matlab.ui.control.RadioButton
        ELabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        PlotCouplingLossButton       matlab.ui.control.Button
        CouplingRegulationKnobLabel  matlab.ui.control.Label
        CouplingRegulationKnob       matlab.ui.control.DiscreteKnob
        PodDropDownLabel             matlab.ui.control.Label
        PodDropDown                  matlab.ui.control.DropDown
        OpenESRButton                matlab.ui.control.Button
        ConstantColumnsButtonGroup   matlab.ui.container.ButtonGroup
        ExcludeButton                matlab.ui.control.RadioButton
        IncludeButton                matlab.ui.control.RadioButton
    end

    properties (Access = public)
        
        % Global variables
        
       
        esr % for first plots (1 test point)
        rig
        % file = file names, path = full paths
        efile % esr
        epath
        rfile % rig
        rpath
        sfile % save summary
        spath
        avg
        release
        dfile % save flow data
        dpath
        matrix
        matrix2
        hose % for coupling loss plot
        latch
        Qrig
        
    end
    
    methods (Access = public)
        
        % function which opens and returns a table for a given file path
        function rig = gettable(app,path)
            fid = fopen(path,'r'); %Found there was a ',' at the end of csv variables, so readtable thinks there's +1 variables.
            str = fgetl(fid);
            fclose(fid);
            vars = regexp(str, ',', 'split');
            if isempty(vars{end}) %gets rid of the ',' end term.
                vars = matlab.lang.makeValidName(vars(1:end-1));
                rig = readtable(path, 'readvariablenames', false);
                rig.Properties.VariableNames = vars;
            else
                rig = readtable(path, 'readvariablenames', true);
            end
        end
    end

    methods (Access = private)

        % Button pushed function: OpenRIGButton
        function OpenRIGButtonPushed(app, event)
            % Opens folder selection window
            main = uigetdir();
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            if ~isequal(main, 0)
                dirinfo = dir(main);
                dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                [app.rfile,app.rpath] = deal([]);
                
                % Iterates over each subfolder
                for i = 1:length(dirinfo)
                    sub = dirinfo(i).name; % Gets the ith subfolder name
                    subfiles = fullfile(main,sub,'*.csv');
                    finfo = dir(subfiles);
                    if isempty(finfo) % checks selected right folder
                        msgbox('Select the correct RIG (DAQ) folder','Error','error')
                        return
                    end
                    if length(finfo)>1 % Checks if more than 1 csv in each subfolder, combines multiple csv into first
                        first = gettable(app,fullfile(main,sub,finfo(1).name));
                        rarray = table2array(first);
                        for j = 2:length(finfo)
                            rig_next = gettable(app,fullfile(main,sub,finfo(j).name));
                            rarray = [rarray;table2array(rig_next)];
                            delete(fullfile(main,sub,finfo(j).name)); % Deletes all but the first, so won't keep on appending tables when operating again
                        end
                        first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                        if exist(fullfile(main,sub,finfo(1).name),'file')
                            delete(fullfile(main,sub,finfo(1).name));
                        end
                        writetable(first, fullfile(main,sub,finfo(1).name))
                        % Appends file names and paths to array
                        app.rpath = [app.rpath, cellstr(fullfile(main,sub,finfo(1).name))];
                        app.rfile = [app.rfile, cellstr(finfo(1).name)];
                    else
                        app.rpath = [app.rpath, cellstr(fullfile(main,sub,finfo.name))];
                        app.rfile = [app.rfile, cellstr(finfo.name)];
                    end
                end

                % Combines esr and rig file&path into one and check equal length
                if ~isempty(app.efile)
                    if length(app.efile) ~= length(app.rfile)
                        msgbox('Number of ESR and RIG files do not match','Error','error');
                        return
                    end
                end
            else
                app.rfile = [];
                app.rpath = [];
            end
        end

        % Button pushed function: PlotESRButton
        function PlotESRButtonPushed(app, event)
            % Check correct inputs
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.esrListBox.Value)<2                
                msgbox('Select Time Axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.esrListBox.Value{1},'Time'),1)) | isempty(find(contains(app.rigListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            % Plots selected columns against time column
            figure(1)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('ESR')
            grid           
            for i = 2:length(app.esrListBox.Value)
                plot(app.esr.(app.esrListBox.Value{1}),app.esr.(app.esrListBox.Value{i}))
            end
            legend(app.esrListBox.Value(2:end))
        end

        % Button pushed function: PlotRIGButton
        function PlotRIGButtonPushed(app, event)
            if isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.rigListBox.Value)<2                
                msgbox('Select Time axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.rigListBox.Value{1},'Time'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            figure(2)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('RIG')
            grid           
            for i = 2:length(app.rigListBox.Value)
                plot(app.rig.(app.rigListBox.Value{1}),app.rig.(app.rigListBox.Value{i}))
            end
            legend(app.rigListBox.Value(2:end))
        end

        % Value changed function: TestPointsDropDown
        function TestPointsDropDownValueChanged(app, event)
            % Seletet test point for plotting selected columns
            
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open both ESR and RIG data files first','Error','error')
                return
            end
            
            if ~isequal(app.TestPointsDropDown.Value,'Select')
                k = find(ismember(app.TestPointsDropDown.Items,app.TestPointsDropDown.Value))-1; % Get index of selected point
                app.esr = gettable(app,app.epath{k});
                app.rig = gettable(app,app.rpath{k});                                
               
                %Load Column headers list box
                for i = 1:width(app.esr)
                    app.esrListBox.Items{i} = app.esr.Properties.VariableNames{i};
                end
                for i = 1:width(app.rig)
                    app.rigListBox.Items{i} = app.rig.Properties.VariableNames{i};
                end
            end
        end

        % Button pushed function: SaveSummaryTableAsButton
        function SaveSummaryTableAsButtonPushed(app, event)
            % Check correct input
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if app.AverageCheckBox.Value == false && app.MaximumCheckBox.Value == false && app.MinimumCheckBox.Value == false
                msgbox('Select Type of Statistic to Save', 'Error', 'error');
                return
            end
            
            % Opens 'save as' window for user to choose file path and name
            [app.sfile , app.spath] = uiputfile({'*.xls'},'Save As','summary.xls');
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            if app.sfile ~=0
                % Makes sure file is writable (not opened)
                try
                    xlswrite([app.spath app.sfile],1:5) % tries to write a random array to xls
                catch ME
                    msgbox('Close summary excel window and try again','Error','error');
                    return
                end
            else
                app.sfile = [];
                return
            end
            
            % Declare variables into correct size and titles
            avg = cell(length(app.efile)+1,19);
            avg(1,1) = {'Average'};
            maxi = cell(length(app.efile)+1,19);
            maxi(1,1) = {'Maximum'};
            mini = cell(length(app.efile)+1,19);
            mini(1,1) = {'Minimum'};
            % Columns to be searched and included in summary
            vars = ["OutletPressure","InletPressure","HoseEndPressure","MassFuelFlow","RATBladeAngle","RATSpeed1","TargetFuel","Density","FuelMassFlow","FuelFlowRate"];
            xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate"]; % Exclude due to dublicate names
            xvars_r = ["Time","Coriolis"];
            r_num = 1;
            n=0;
            
            for i = 1:length(app.efile) %iterate over each test point
                esr = gettable(app,app.epath{i});
                rig = gettable(app,app.rpath{i});
                
                % Change table to shortened version
                esr_include = contains(esr.Properties.VariableNames,vars) & ~contains(esr.Properties.VariableNames,xvars_e);
                esr = esr(:,esr_include);
                esr = esr(:,sort(esr.Properties.VariableNames)); % sorts columns from headers (different order)
                rig = rig(:,~contains(rig.Properties.VariableNames,xvars_r));
                rig = rig(:,sort(rig.Properties.VariableNames));
                
                % average,max,min value calculation for this test point in the iteration, and is added to corresponding matrix
                if app.AverageCheckBox.Value == true         
                    average_esr = varfun(@mean,esr);
                    average_esr = table2array(average_esr);
                    average_rig = varfun(@mean,rig);
                    average_rig = table2array(average_rig);
                    
                    % 'Release' defines and separates different release versions for later plotting
                    idx1 = strfind(app.efile{i},'_R'); idx2 = strfind(app.efile{i},'Step');
                    avg(i+1,1) = cellstr([app.efile{i}(idx1+1:idx1+2),' ',app.efile{i}(idx2:end)]);
                    if app.efile{i}(idx1+2) ~= r_num
                        % Row named with simplified testpoints
                        if isempty(app.release)
                            app.release = 1;
                        else
                            app.release = [app.release,app.release(end)+1];
                        end
                        r_num = app.efile{i}(idx1+2);
                    else
                        app.release(end) = app.release(end)+1;
                    end
                    
                    
                    avg(i+1,2:length(average_esr)+1) = num2cell(average_esr);
                    avg(i+1,end-length(average_rig)+1:end) = num2cell(average_rig);
                else
                    avg = [];
                end
                
                if app.MaximumCheckBox.Value == true                    
                    max_esr = varfun(@max,esr);
                    max_esr = table2array(max_esr);
                    max_rig = varfun(@max,rig);
                    max_rig = table2array(max_rig);  

                    maxi(i+1,1) = cellstr(app.efile{i});
                    maxi(i+1,2:length(max_esr)+1) = num2cell(max_esr);
                    maxi(i+1,end-length(max_rig)+1:end) = num2cell(max_rig);
                else
                    maxi = [];
                end
                
                if app.MinimumCheckBox.Value == true                    
                    min_esr = varfun(@min,esr);
                    min_esr = table2array(min_esr);
                    min_rig = varfun(@min,rig);
                    min_rig = table2array(min_rig); 
                    
                    mini(i+1,1) = cellstr(app.efile{i});
                    mini(i+1,2:length(min_esr)+1) = num2cell(min_esr);
                    mini(i+1,end-length(min_rig)+1:end) = num2cell(min_rig);
                else
                    mini = [];
                end
            end
            
            n=0
            for i = 1:length(app.release)-1
                avg = [avg(1:app.release(i)+1+n,:) ; cell(1,size(avg,2)) ; avg(app.release(i)+2+n:end,:)];
                n=n+1
            end
            
            % combine avg, maxi and mini into main matrix to be exported
            app.avg = array2table(avg(2:end,:),'VariableNames',[{'TestPoint'},esr.Properties.VariableNames,{'Blank'},rig.Properties.VariableNames]);
            app.matrix=[cell(1,1), esr.Properties.VariableNames,cell(1,1),rig.Properties.VariableNames;
                avg;cell(1,width(esr)+width(rig)+2);maxi;cell(1,width(esr)+width(rig)+2);mini;cell(1,width(esr)+width(rig)+2)];
            
            if app.sfile ~=0
                if exist([app.spath app.sfile],'file')
                    delete([app.spath app.sfile]);
                end
                xlswrite([app.spath app.sfile],app.matrix)
                app.Lamp.Color = [0 1 0];
            else
                app.sfile = '';
            end
        end

        % Button pushed function: OpenSavedSummaryButton
        function OpenSavedSummaryButtonPushed(app, event)
            % Opens saved summary file
            if isempty(app.sfile)
                msgbox('No Summary File Found', 'Error', 'error');
            elseif exist([app.spath app.sfile],'file')
                if app.Lamp.Color == [0 1 0] % Ensures new file is complete (takes 10 seconds)
                    winopen([app.spath app.sfile])
                end
            end
        end

        % Button pushed function: CalculateandPlotFlowButton
        function CalculateandPlotFlowButtonPushed(app, event)
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            % Different K1 values for different RCU versions
            K2 = 1.1544;
            if app.R10Button.Value == true
                K1 = 8.12e-5;                
            else
                K1 = 7.91e-5;
            end
            
            % Iterate each test point and do calculations 
            for i = 1:length(app.efile)
                esr = gettable(app,app.epath{i});
                rig = gettable(app,app.rpath{i});
                
                % find columns with flow rate equation variables to calculate + plot
                if i == 1
                    p=contains(rig.Properties.VariableNames,'DensityMeter') & ~contains(rig.Properties.VariableNames,'Coriolis');                    
                    q=contains(esr.Properties.VariableNames,'OutletPressure') & ~contains(esr.Properties.VariableNames,'Pump');
                    h=contains(rig.Properties.VariableNames,'HoseEnd'); 
                    l=contains(rig.Properties.VariableNames,'Latch');
                    s=contains(esr.Properties.VariableNames,'FuelFlow') & ~contains(esr.Properties.VariableNames,'Limit');
                    t=contains(rig.Properties.VariableNames,'FuelFlow');
                    u=contains(esr.Properties.VariableNames,'HoseEnd')  & ~contains(esr.Properties.VariableNames,'psi'); 
                    [Pout,Pher,Pl,Qe,Qr,rho,calPhe,Phee] = deal(zeros(1,length(app.efile)));
                end
                
                % create array of average values for searched columns for each test point 
                rho(i) = mean(rig{:,p});                
                Pout(i) = mean(esr{:,q});
                Pl(i) = mean(rig{:,l});
                Pher(i) = mean(rig{:,h});  
                Qe(i) = (mean(esr{:,s}))^2;
                Qr(i) = (mean(rig{:,t}))^2;
                calPhe(i) = Pout(i) - (K1*rho(i)/119.8)*Qe(i) + K2*rho(i)/119.8;
                Phee(i) = mean(esr{:,u});
            end
            
            % For later plotting coupling losses use
            app.hose = Pher;
            app.latch = Pl;
            app.Qrig = Qr;
            
            % Clears axes, calculates specific values in flow equation and plots as required to find slope
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            avgrho = mean(rho);
            
            % If exclude chosen, changes arrays to shorter version, excluding data if Hose End Pressure > 50
            include = Pher <= 50;
            if app.LatchButton.Value == true
                if isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{2}) && app.LatchButton.Value == true
                    avgrho = mean(rho(include));
                    Pe = Pl(include);
                    Pout = Pout(include);
                    Qe = Qe(include);
                    Qr = Qr(include);
                elseif isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{1}) && app.LatchButton.Value == true
                    Pe = Pl;
                else
                    avgrho = mean(rho(~include));
                    Pe = Pl(~include);
                    Pout = Pout(~include);
                    Qe = Qe(~include);
                    Qr = Qr(~include);
                end
            else
                Pe = Pher;
            end
            
            Phead = avgrho*9.81*4.21/6894.757; % Pressure gained from catenary (change of units)
            y = Pout - Pe + Phead;
            if isempty(Qe) % To prevent index error on dP below
                msgbox('All points excluded','Warning','warn');
                return
            end
            dP = (avgrho/119.8)*K1*Qe(end);             
            
            m1 = Qe'\y'; % Slope of y = mx
            app.K1ChangeEditField.Value = 100*(m1/(avgrho/119.8)-K1)/K1;
            plot(app.UIAxes,Qe,y, 'or')
            plot(app.UIAxes,[0,Qe],[0,Qe*m1],'b')
            plot(app.UIAxes,[0,Qe(end)],[0,dP],'g')
            legend(app.UIAxes,'Data points','Best Fit through 0','RCU estimation','Location','northwest') 
            m2 = Qr'\y'; % Slope of y = mx
            app.SlopeEditField_2.Value = 100*(m2/(avgrho/119.8)-K1)/K1;
            plot(app.UIAxes_2,Qr,y,'or')
            plot(app.UIAxes_2,[0,Qr],[0,Qr*m2],'b')
            plot(app.UIAxes_2,[0,Qe(end)],[0,dP],'g')
            
            % for later export use (flow calculation data)
            app.matrix2 = [{'avg. rho'},avgrho,cell(1,length(rho)-1);{'Phead'},Phead,cell(1,length(rho)-1);cell(1,length(rho)+1); {'Pout-Pend+Phead'},num2cell(y),cell(1,length(rho)-length(y));{'Q^2 esr'},num2cell(Qe),cell(1,length(rho)-length(y));{'Q^2 rig'},num2cell(Qr),cell(1,length(rho)-length(y));
                {'Q^2 (RCU)'},num2cell(Qe(end)),cell(1,length(rho)-1);{'dP (RCU)'},num2cell(dP),cell(1,length(rho)-1);cell(1,length(rho)+1);{'Calculated Phe'},num2cell(calPhe);{'% Difference'},num2cell(100*(Phee-calPhe)./Phee);
                cell(1,length(rho)+1);[{'slope esr'},m1,{'New K1'},m1/(avgrho/119.8),{'% Difference'},100*(m1/(avgrho/119.8)-K1)/K1]',[{'slope rig'},m2,{'New K1'},m2/(avgrho/119.8),{'% Difference'},100*(m2/(avgrho/119.8)-K1)/K1]',cell(6,length(rho)-1)];
            app.matrix2 = app.matrix2';    
        end

        % Button pushed function: ExportFlowDataButton
        function ExportFlowDataButtonPushed(app, event)
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end            
            if isempty(app.matrix2)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Appends new data to summary or make new excel file 
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    % Makes sure file is writable (not opened)
                    try
                        xlswrite([app.spath app.sfile],[1:2])
                    catch ME
                        msgbox('Close summary excel window and try again','Error','error');
                        return
                    end
                    matrix3 = [app.matrix;cell(1,size(app.matrix,2)),cell(size(app.matrix2,1),1),app.matrix2];
                    xlswrite([app.spath app.sfile],matrix3)
                    app.Lamp_2.Color = [0 1 0];
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else                
                [app.dfile , app.dpath] = uiputfile({'*.xls'},'Save As','flow_data.xls');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if app.dfile ~=0
                    if exist([app.dpath app.dfile],'file')
                        delete([app.dpath app.dfile]);
                    end
                    try
                        xlswrite([app.dpath app.dfile],app.matrix2)
                    catch ME
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    end
                    xlswrite([app.dpath app.dfile],app.matrix2)
                    app.Lamp_2.Color = [0 1 0];
                else
                    app.dfile = '';
                end
            end    
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Opens Flow Data (either summary or new)
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    winopen([app.spath app.sfile])
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else
                if exist([app.dpath app.dfile],'file')
                    winopen([app.dpath app.dfile])
                else
                    msgbox('No Data File Found', 'Error', 'error');
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            % Changes y axes labels for Hose End / Latch Pressure
            if app.HoseEndButton.Value == true
                ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)');
                ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)');
                app.CouplingRegulationKnob.Enable = 'off';
            else
                ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (psi)');
                ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (psi)');
                app.CouplingRegulationKnob.Enable = 'on';
            end            
        end

        % Button pushed function: PlotCouplingLossButton
        function PlotCouplingLossButtonPushed(app, event)
            if isempty(app.file)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.hose)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Import and separate variables
            Phe = app.hose;
            Pl = app.latch;
            Qr = app.Qrig;
            dP = Phe - Pl;            
            dP_1 = dP(Phe<=50);
            dP_2 = dP(Phe>50);
            Qr_1 = Qr(Phe<=50);
            Qr_2 = Qr(Phe>50);
            
            % Plot the 2 sets of data as scattered points and different colours
            hold on
            xlabel('Rig Fuel Flow (USgpm)')
            ylabel('MA4 Losses (psi)')
            title('Coupling Pressure Losses')
            grid
            plot(Qr_1,dP_1,'ob','MarkerFaceColor', 'b')
            plot(Qr_2,dP_2,'or','MarkerFaceColor', 'r')
            legend('Regulators Opened','Regulators Closed','Location','northwest')
        end

        % Button pushed function: OpenESRButton
        function OpenESRButtonPushed(app, event)
             % Opens folder selection window
            main = uigetdir();
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            if ~isequal(main, 0)
                dirinfo = dir(main);
                dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                [app.epath,app.efile] = deal(cell(1,length(dirinfo)));
                for i = 1:length(dirinfo)
                    app.epath(i) = cellstr(fullfile(main,dirinfo(i).name));
                    app.efile(i) = cellstr(dirinfo(i).name);
                end
                
                app.TestPointsDropDown.Items = {'Select'};
                for i = 1:length(app.efile)
                    % Assign test point names to drop down menu
                    app.TestPointsDropDown.Items = [app.TestPointsDropDown.Items, cellstr([app.efile{i}(5:7),app.efile{i}(13:end)])];                    
                end
                
                if ~isempty(app.rfile)
                    if length(app.efile) ~= length(app.rfile)
                        msgbox('Number of ESR and RIG files do not match','Error','error');
                        return
                    end
                end
                
            else
                app.efile = [];
                app.epath = [];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryandPlotsforFlowDataUIFigure = uifigure;
            app.SummaryandPlotsforFlowDataUIFigure.Name = 'Summary and Plots for Flow Data';
            app.ColumnsListBoxLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.ColumnsListBoxLabel.Text = 'Columns';
            app.esrListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.esrListBox.Items = {};
            app.esrListBox.Multiselect = 'on';
            app.esrListBox.Value = {};
            app.OpenRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenRIGButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRIGButtonPushed, true);
            app.OpenRIGButton.Text = 'Open RIG';
            app.TestPointsDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDownLabel.Text = 'Test Points';
            app.TestPointsDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDown.Items = {'Select'};
            app.TestPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointsDropDownValueChanged, true);
            app.TestPointsDropDown.Value = 'Select';
            app.PlotESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotESRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotESRButtonPushed, true);
            app.PlotESRButton.Text = 'Plot ESR';
            app.rigListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.rigListBox.Items = {};
            app.rigListBox.Multiselect = 'on';
            app.rigListBox.Value = {};
            app.SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel.Text = 'Select test point and  columns to plot, include the Time column as first selection';
            app.PlotRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotRIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRIGButtonPushed, true);
            app.PlotRIGButton.Text = 'Plot RIG';
            app.AverageCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MaximumCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MinimumCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.MinimumCheckBox.Text = 'Minimum';
            app.SaveSummaryTableAsButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.SaveSummaryTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSummaryTableAsButtonPushed, true);
            app.SaveSummaryTableAsButton.Text = 'Save Summary Table As...';
            app.OpenSavedSummaryButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenSavedSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSavedSummaryButtonPushed, true);
            app.OpenSavedSummaryButton.Text = 'Open Saved Summary';
            app.UIAxes = uiaxes(app.SummaryandPlotsforFlowDataUIFigure);
            title(app.UIAxes, 'ESR Fuel Flow')
            xlabel(app.UIAxes, 'Q^2 (USgpm^2)')
            ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.ButtonGroup_3 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.R10Button = uiradiobutton(app.ButtonGroup_3);
            app.R10Button.Text = 'R10';
            app.R10Button.Value = true;
            app.R8Button = uiradiobutton(app.ButtonGroup_3);
            app.R8Button.Text = 'R8';
            app.CalculateandPlotFlowButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.CalculateandPlotFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotFlowButtonPushed, true);
            app.CalculateandPlotFlowButton.Text = 'Calculate and Plot Flow';
            app.UIAxes_2 = uiaxes(app.SummaryandPlotsforFlowDataUIFigure);
            title(app.UIAxes_2, 'RIG Fuel Flow')
            xlabel(app.UIAxes_2, 'Q^2 (USgpm^2)')
            ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.ExportFlowDataButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ExportFlowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFlowDataButtonPushed, true);
            app.ExportFlowDataButton.Text = 'Export Flow Data';
            app.K1ChangeEditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.K1ChangeEditFieldLabel.Text = 'K1 Change (%)';
            app.K1ChangeEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.K1ChangeEditField.ValueDisplayFormat = '%.5f';
            app.K1ChangeEditField.Editable = 'off';
            app.ButtonGroup_4 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ToSummaryButton = uiradiobutton(app.ButtonGroup_4);
            app.ToSummaryButton.Text = 'To Summary';
            app.ToSummaryButton.Value = true;
            app.AsNewButton = uiradiobutton(app.ButtonGroup_4);
            app.AsNewButton.Text = 'As New';
            app.SavedLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SavedLabel.Text = 'Saved?';
            app.Lamp = uilamp(app.SummaryandPlotsforFlowDataUIFigure);
            app.Lamp_2 = uilamp(app.SummaryandPlotsforFlowDataUIFigure);
            app.OpenButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SlopeEditField_2 = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SlopeEditField_2.ValueDisplayFormat = '%.5f';
            app.SlopeEditField_2.Editable = 'off';
            app.ButtonGroup_5 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.HoseEndButton = uiradiobutton(app.ButtonGroup_5);
            app.HoseEndButton.Text = 'Hose End';
            app.HoseEndButton.Value = true;
            app.LatchButton = uiradiobutton(app.ButtonGroup_5);
            app.LatchButton.Text = 'Latch';
            app.ELabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.RLabel.Text = 'R';
            app.PlotCouplingLossButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotCouplingLossButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCouplingLossButtonPushed, true);
            app.PlotCouplingLossButton.Text = 'Plot Coupling Loss';
            app.CouplingRegulationKnobLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.CouplingRegulationKnobLabel.Enable = 'off';
            app.CouplingRegulationKnobLabel.Text = 'Coupling Regulation';
            app.CouplingRegulationKnob = uiknob(app.SummaryandPlotsforFlowDataUIFigure, 'discrete');
            app.CouplingRegulationKnob.Items = {'All Data', 'Remove >50', 'Excluded'};
            app.CouplingRegulationKnob.Enable = 'off';
            app.CouplingRegulationKnob.Value = 'All Data';
            app.PodDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.PodDropDownLabel.Text = 'Pod';
            app.PodDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.PodDropDown.Items = {'905', '908', '910', '912'};
            app.PodDropDown.Value = '905';
            app.OpenESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenESRButton.ButtonPushedFcn = createCallbackFcn(app, @OpenESRButtonPushed, true);
            app.OpenESRButton.Text = 'Open ESR';
            app.ConstantColumnsButtonGroup = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ConstantColumnsButtonGroup.Title = 'Constant Columns';
            app.ExcludeButton = uiradiobutton(app.ConstantColumnsButtonGroup);
            app.ExcludeButton.Text = 'Exclude';
            app.ExcludeButton.Value = true;
            app.IncludeButton = uiradiobutton(app.ConstantColumnsButtonGroup);
            app.IncludeButton.Text = 'Include';
        end
    end

    methods (Access = public)

        % Construct app
        function app = esr_rig_analysis_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryandPlotsforFlowDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryandPlotsforFlowDataUIFigure)
        end
    end
end