classdef esr_rig_analysis_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryandPlotsforFlowDataUIFigure  matlab.ui.Figure
        ColumnsListBoxLabel          matlab.ui.control.Label
        esrListBox                   matlab.ui.control.ListBox
        OpenRIGButton                matlab.ui.control.Button
        TestPointsDropDownLabel      matlab.ui.control.Label
        TestPointsDropDown           matlab.ui.control.DropDown
        PlotESRButton                matlab.ui.control.Button
        rigListBox                   matlab.ui.control.ListBox
        SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel  matlab.ui.control.Label
        PlotRIGButton                matlab.ui.control.Button
        AverageCheckBox              matlab.ui.control.CheckBox
        MaximumCheckBox              matlab.ui.control.CheckBox
        MinimumCheckBox              matlab.ui.control.CheckBox
        SaveSummaryTableAsButton     matlab.ui.control.Button
        OpenSavedSummaryButton       matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        CalculateandPlotFlowButton   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        ExportFlowDataButton         matlab.ui.control.Button
        K1ChangeEditFieldLabel       matlab.ui.control.Label
        K1ChangeEditField            matlab.ui.control.NumericEditField
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        ToSummaryButton              matlab.ui.control.RadioButton
        AsNewButton                  matlab.ui.control.RadioButton
        SavedLabel                   matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        OpenButton                   matlab.ui.control.Button
        SlopeEditField_2             matlab.ui.control.NumericEditField
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        HoseEndButton                matlab.ui.control.RadioButton
        LatchButton                  matlab.ui.control.RadioButton
        ELabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        PlotCouplingLossButton       matlab.ui.control.Button
        CouplingRegulationKnobLabel  matlab.ui.control.Label
        CouplingRegulationKnob       matlab.ui.control.DiscreteKnob
        K1EditFieldLabel             matlab.ui.control.Label
        K1EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        K2EditField                  matlab.ui.control.NumericEditField
        PodDropDownLabel             matlab.ui.control.Label
        PodDropDown                  matlab.ui.control.DropDown
        OpenESRButton                matlab.ui.control.Button
    end

    properties (Access = public)
        
        % Global variables
       
        esr % for first plots (1 test point)
        rig
        % file = file names, path = full paths
        efile % esr
        epath
        rfile % rig
        rpath
        sfile % save summary
        spath
        avg
        dfile % save flow data
        dpath
        matrix
        matrix2
        hose % for coupling loss plot
        latch
        Q
        name
        
    end
    
    methods (Access = public)
        
        % function which opens and returns a table for a given file path
        function rig = gettable(app,path)
            fid = fopen(path,'r'); %Found there was a ',' at the end of csv variables, so readtable thinks there's +1 variables.
            str = fgetl(fid);
            fclose(fid);
            vars = regexp(str, ',', 'split');
            if isempty(vars{end}) %gets rid of the ',' end term.
                vars = matlab.lang.makeValidName(vars(1:end-1));
                rig = readtable(path, 'readvariablenames', false);
                rig.Properties.VariableNames = vars;
            else
                rig = readtable(path, 'readvariablenames', true);
            end
        end
        
        % gets a column in a table as an array searching by variable name
        function col = getcolumn(app,table,name)
            col = cell2mat(table{:,contains(table.Properties.VariableNames,name)});
        end
    end

    methods (Access = private)

        % Button pushed function: OpenRIGButton
        function OpenRIGButtonPushed(app, event)
            % Opens folder selection window
            main = uigetdir();
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            if ~isequal(main, 0)
                dirinfo = dir(main);
                dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                [app.rfile,app.rpath] = deal([]);
                
                % Iterates over each subfolder
                for i = 1:length(dirinfo)
                    sub = dirinfo(i).name; % Gets the ith subfolder name
                    subfiles = fullfile(main,sub,'*.csv');
                    finfo = dir(subfiles);
                    if ~dirinfo(i).isdir % checks selected right folder
                        msgbox('Select the correct RIG folder (contains subfolders with .csv in each)','Error','error')
                        return
                    end
                    if length(finfo)>1 % Checks if more than 1 csv in each subfolder, combines multiple csv into first
                        first = gettable(app,fullfile(main,sub,finfo(1).name));
                        rarray = table2array(first);
                        for j = 2:length(finfo)
                            rig_next = gettable(app,fullfile(main,sub,finfo(j).name));
                            rarray = [rarray;table2array(rig_next)];
                            delete(fullfile(main,sub,finfo(j).name)); % Deletes all but the first, so won't keep on appending tables when operating again
                        end
                        first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                        if exist(fullfile(main,sub,finfo(1).name),'file')
                            delete(fullfile(main,sub,finfo(1).name));
                        end
                        writetable(first, fullfile(main,sub,finfo(1).name))
                        % Appends file names and paths to array
                        app.rpath = [app.rpath, cellstr(fullfile(main,sub,finfo(1).name))];
                        app.rfile = [app.rfile, cellstr(finfo(1).name)];
                    else
                        app.rpath = [app.rpath, cellstr(fullfile(main,sub,finfo.name))];
                        app.rfile = [app.rfile, cellstr(finfo.name)];
                    end
                end

                % Combines esr and rig file&path into one and check equal length
                if ~isempty(app.efile)
                    if length(app.efile) ~= length(app.rfile)
                        msgbox('Number of ESR and RIG files do not match','Error','error');
                        return
                    end
                end
            else
                app.rfile = [];
                app.rpath = [];
            end
        end

        % Button pushed function: PlotESRButton
        function PlotESRButtonPushed(app, event)
            % Check correct inputs
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.esrListBox.Value)<2                
                msgbox('Select Time Axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.esrListBox.Value{1},'Time'),1)) && isempty(find(contains(app.esrListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            % Plots selected columns against time column
            figure(1)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('ESR')
            grid           
            for i = 2:length(app.esrListBox.Value)
                plot(app.esr.(app.esrListBox.Value{1}),app.esr.(app.esrListBox.Value{i}))
            end
            legend(app.esrListBox.Value(2:end))
        end

        % Button pushed function: PlotRIGButton
        function PlotRIGButtonPushed(app, event)
            if isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.rigListBox.Value)<2                
                msgbox('Select Time axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.rigListBox.Value{1},'Time'),1)) && isempty(find(contains(app.rigListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            figure(2)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('RIG')
            grid           
            for i = 2:length(app.rigListBox.Value)
                plot(app.rig.(app.rigListBox.Value{1}),app.rig.(app.rigListBox.Value{i}))
            end
            legend(app.rigListBox.Value(2:end))
        end

        % Value changed function: TestPointsDropDown
        function TestPointsDropDownValueChanged(app, event)
            % Seletet test point for plotting selected columns
            
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open both ESR and RIG data files first','Error','error')
                return
            end
            
            if ~isequal(app.TestPointsDropDown.Value,'Select')
                k = find(ismember(app.TestPointsDropDown.Items,app.TestPointsDropDown.Value))-1; % Get index of selected point
                app.esr = gettable(app,app.epath{k});
                app.rig = gettable(app,app.rpath{k});                                
               
                %Load Column headers list box
                for i = 1:width(app.esr)
                    app.esrListBox.Items{i} = app.esr.Properties.VariableNames{i};
                end
                for i = 1:width(app.rig)
                    app.rigListBox.Items{i} = app.rig.Properties.VariableNames{i};
                end
            end
        end

        % Button pushed function: SaveSummaryTableAsButton
        function SaveSummaryTableAsButtonPushed(app, event)
            % Check correct input
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if app.AverageCheckBox.Value == false && app.MaximumCheckBox.Value == false && app.MinimumCheckBox.Value == false
                msgbox('Select Type of Statistic to Save', 'Error', 'error');
                return
            end
            
            % Opens 'save as' window for user to choose file path and name
            [app.sfile , app.spath] = uiputfile({'*.xlsx'},'Save As','summary.xlsx');
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            if app.sfile ~=0
                % Makes sure file is writable (not opened)
                if fopen([app.spath app.sfile],'a') == -1
                    msgbox('Close existing flow data window and try again','Error','error');
                    return
                else
                    fclose('all');
                end
                delete([app.spath app.sfile]);
            else
                app.sfile = [];
                return
            end
            
            % Columns to be searched and included in summary
            vars = ["OutletPressure","InletPressure","HoseEndPressure","MassFuelFlow","RATBladeAngle","RATSpeed1","TargetFuel","Density","FuelMassFlow","FuelFlowRate"];
            xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate"]; % Exclude due to dublicated names
            xvars_r = ["Time","Coriolis"];
            
            tic
            for i = 1:length(app.efile) %iterate over each test point
                esr = gettable(app,app.epath{i});
                rig = gettable(app,app.rpath{i});
                
                % Change table to shortened version
                esr_include = contains(esr.Properties.VariableNames,vars) & ~contains(esr.Properties.VariableNames,xvars_e);
                rig_include = ~contains(rig.Properties.VariableNames,xvars_r);
                esr = esr(:,esr_include);
                esr = esr(:,sort(esr.Properties.VariableNames)); % sorts columns from headers (different order)
                rig = rig(:,rig_include);
                rig = rig(:,sort(rig.Properties.VariableNames));
                
                if i == 1
                    % Declare variables into correct size and titles
                    avg = cell(length(app.efile)+1,length(find(esr_include))+length(find(rig_include))+2);
                    avg(1,1) = {'Average'};
                    maxi = cell(length(app.efile)+1,length(find(esr_include))+length(find(rig_include))+2);
                    maxi(1,1) = {'Maximum'};
                    mini = cell(length(app.efile)+1,length(find(esr_include))+length(find(rig_include))+2);
                    mini(1,1) = {'Minimum'};
                end
                
                % average,max,min value calculation for this test point in the iteration, and is added to corresponding matrix
                if app.AverageCheckBox.Value == true         
                    average_esr = varfun(@mean,esr); % single row of mean values
                    average_esr = table2array(average_esr);
                    average_rig = varfun(@mean,rig);
                    average_rig = table2array(average_rig);
                    
                    % add shortened name of file
                    avg(i+1,1) = app.name(i);
                    
                    avg(i+1,2:length(average_esr)+1) = num2cell(average_esr);
                    avg(i+1,end-length(average_rig)+1:end) = num2cell(average_rig);
                else
                    avg = [];
                end
                
                if app.MaximumCheckBox.Value == true                    
                    max_esr = varfun(@max,esr);
                    max_esr = table2array(max_esr);
                    max_rig = varfun(@max,rig);
                    max_rig = table2array(max_rig);  

                    maxi(i+1,1) = cellstr(app.efile{i});
                    maxi(i+1,2:length(max_esr)+1) = num2cell(max_esr);
                    maxi(i+1,end-length(max_rig)+1:end) = num2cell(max_rig);
                else
                    maxi = [];
                end
                
                if app.MinimumCheckBox.Value == true                    
                    min_esr = varfun(@min,esr);
                    min_esr = table2array(min_esr);
                    min_rig = varfun(@min,rig);
                    min_rig = table2array(min_rig); 
                    
                    mini(i+1,1) = cellstr(app.efile{i});
                    mini(i+1,2:length(min_esr)+1) = num2cell(min_esr);
                    mini(i+1,end-length(min_rig)+1:end) = num2cell(min_rig);
                else
                    mini = [];
                end
                toc
            end
            app.avg = array2table(avg(2:end,:),'VariableNames',[{'TestPoint'},esr.Properties.VariableNames,{'Blank'},rig.Properties.VariableNames]);
            
            % combine avg, maxi and mini into main matrix to be exported
            app.matrix=[cell(1,1), esr.Properties.VariableNames,cell(1,1),rig.Properties.VariableNames;
                avg;cell(1,width(esr)+width(rig)+2);maxi;cell(1,width(esr)+width(rig)+2);mini;cell(1,width(esr)+width(rig)+2)];
            
            
            xlswrite([app.spath app.sfile],app.matrix,1)
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: OpenSavedSummaryButton
        function OpenSavedSummaryButtonPushed(app, event)
            % Opens saved summary file
            if isempty(app.sfile)
                msgbox('No Summary File Found', 'Error', 'error');
            elseif exist([app.spath app.sfile],'file')
                if app.Lamp.Color == [0 1 0] % Ensures new file is complete (takes 10 seconds)
                    winopen([app.spath app.sfile])
                end
            end
        end

        % Button pushed function: CalculateandPlotFlowButton
        function CalculateandPlotFlowButtonPushed(app, event)
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif isempty(app.avg)
                msgbox('Save saummary first', 'Error', 'error');
                return
            end
            % Different K1 values from user input
            K2 = app.K2EditField.Value;
            K1 = app.K1EditField.Value;
            
            % find columns with flow rate equation variables to calculate + plot
            rho  = getcolumn(app,app.avg,'DensityMeter'); % rig                  
            Pout = getcolumn(app,app.avg,'OutletPressure'); % esr 
            Pher = getcolumn(app,app.avg,'HoseEndSteady'); % rig
            Pl = getcolumn(app,app.avg,'Latch'); % rig
            Q = find(contains(app.avg.Properties.VariableNames,'FuelFlow'));
            Qe = (cell2mat(app.avg{:,Q(1)})/60).^2; % esr (L/s)
            Qr= (cell2mat(app.avg{:,Q(end)})/15.85032).^2; % rig (L/s)
            Phee = getcolumn(app,app.avg,'HoseEndPressure'); % esr

            % For later plotting coupling losses use
            app.hose = Pher;
            app.latch = Pl;
            app.Q = Qe;
            
            % Clears axes, calculates specific values in flow equation and plots as required to find slope
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            avgrho = mean(rho);
            Phead = avgrho*9.81*4.21/6894.757; % Pressure gained from catenary (psi)
            calPhe = Pout*6.894757 - (K1*avgrho).*Qe + K2*avgrho/6.894757;
            dP = (avgrho)*K1*Qe(end); 
            
            if app.LatchButton.Value == true
                y = (Pout - Pl + Phead)*6.894757; % kPa
                header = {'P(OUT)-P(L) corrected for catenary'};
            else
                y = (Pout - Pher + Phead)*6.894757;
                header = {'P(OUT)-P(HE) corrected for catenary'};
            end
            % for later export use (flow calculation data)
            app.matrix2 = [{'avg. rho'},avgrho,cell(1,length(rho)-1);{'Pcatenary'},Phead,cell(1,length(rho)-1);cell(1,length(rho)+1); header,num2cell(y'),cell(1,length(rho)-length(y));{'Q^2 esr'},num2cell(Qe'),cell(1,length(rho)-length(y));{'Q^2 rig'},num2cell(Qr'),cell(1,length(rho)-length(y));
                {'Q^2 (RCU)'},num2cell(Qe(end)),cell(1,length(rho)-1);{'dP (RCU)'},num2cell(dP),cell(1,length(rho)-1);cell(1,length(rho)+1);{'Calculated Phe'},num2cell(calPhe');{'% Difference'},num2cell(100*(Phee'-calPhe')./Phee')];
                
            % If exclude chosen, changes arrays to shorter version, excluding data if Hose End Pressure > 50
            include = Pher <= 50;
            if isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{2})
                avgrho = mean(rho(include));
                y = y(include);
                Qe = Qe(include);
                Qr = Qr(include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('All points excluded','Warning','warn');
                    return
                end
            elseif isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{3})
                avgrho = mean(rho(~include));
                y = y(~include);
                Qe = Qe(~include);
                Qr = Qr(~include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('No points excluded','Warning','warn');
                    return
                end
            end
            
            dP = (avgrho)*K1*Qe(end);             
            
            m1 = Qe\y; % Slope of y = mx
            app.K1ChangeEditField.Value = 100*(m1/(avgrho)-K1)/K1;
            plot(app.UIAxes,Qe,y, 'or')
            plot(app.UIAxes,[0;Qe],[0;Qe*m1],'b')
            plot(app.UIAxes,[0;Qe(end)],[0;dP],'g')
            legend(app.UIAxes,'Data points','Best Fit through 0','RCU estimation','Location','northwest') 
            m2 = Qr\y; % Slope of y = mx
            app.SlopeEditField_2.Value = 100*(m2/(avgrho)-K1)/K1;
            plot(app.UIAxes_2,Qr,y,'or')
            plot(app.UIAxes_2,[0;Qr],[0;Qr*m2],'b')
            plot(app.UIAxes_2,[0,Qe(end)],[0,dP],'g')
            
            % for later export use (flow calculation data)
            app.matrix2 = [app.matrix2 ; cell(1,length(rho)+1);[{'slope esr'},m1,{'K loss'},m1/(avgrho),{'% Difference'},100*(m1/(avgrho)-K1)/K1]',[{'slope rig'},m2,{'K loss'},m2/(avgrho),{'% Difference'},100*(m2/(avgrho)-K1)/K1]',cell(6,length(rho)-1)];
            app.matrix2 = app.matrix2';    
        end

        % Button pushed function: ExportFlowDataButton
        function ExportFlowDataButtonPushed(app, event)
            if isempty(app.efile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end            
            if isempty(app.matrix2)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Appends new data to summary or make new excel file 
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    % Makes sure file is writable (not opened)
                    if fopen([app.spath app.sfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    xlswrite([app.spath app.sfile],app.matrix2,1,[char(size(app.matrix,2)+66) , '2'])
                    app.Lamp_2.Color = [0 1 0];
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else                
                [app.dfile , app.dpath] = uiputfile({'*.xls'},'Save As','flow_data.xls');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if app.dfile ~=0
                    if fopen([app.dpath app.dfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    delete([app.dpath app.dfile]);
                    xlswrite([app.dpath app.dfile],app.matrix2)
                    app.Lamp_2.Color = [0 1 0];
                else
                    app.dfile = [];
                end
            end    
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Opens Flow Data (either summary or new)
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    winopen([app.spath app.sfile])
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else
                if exist([app.dpath app.dfile],'file')
                    winopen([app.dpath app.dfile])
                else
                    msgbox('No Data File Found', 'Error', 'error');
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            % Changes y axes labels for Hose End / Latch Pressure
            if app.HoseEndButton.Value == true
                ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)');
                ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)');
                app.CouplingRegulationKnob.Enable = 'off';
            else
                ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (psi)');
                ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (psi)');
                app.CouplingRegulationKnob.Enable = 'on';
            end            
        end

        % Button pushed function: PlotCouplingLossButton
        function PlotCouplingLossButtonPushed(app, event)
            if isempty(app.efile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.hose)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Import and separate variables
            Phe = app.hose;
            Pl = app.latch;
            Q = app.Q;
            dP = Phe - Pl;            
            dP_1 = dP(Phe<=50);
            dP_2 = dP(Phe>50);
            Q_1 = Q(Phe<=50);
            Q_2 = Q(Phe>50);
            
            % Plot the 2 sets of data as scattered points and different colours
            hold on
            xlabel('Rig Fuel Flow (USgpm)')
            ylabel('MA4 Losses (psi)')
            title('Coupling Pressure Losses')
            grid
            plot(Q_1,dP_1,'ob','MarkerFaceColor', 'b')
            plot(Q_2,dP_2,'or','MarkerFaceColor', 'r')
            legend('Regulators Opened','Regulators Closed','Location','northwest')
        end

        % Button pushed function: OpenESRButton
        function OpenESRButtonPushed(app, event)
             % Opens folder selection window
            main = uigetdir();
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            if ~isequal(main, 0)
                dirinfo = dir(main);
                dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                [app.epath,app.efile] = deal(cell(1,length(dirinfo)));
                for i = 1:length(dirinfo)
                    if dirinfo(i).isdir
                        msgbox('Select correct ESR folder (only contains .csv files)','Error','error')
                        return
                    end
                    app.epath(i) = cellstr(fullfile(main,dirinfo(i).name));
                    app.efile(i) = cellstr(dirinfo(i).name);
                end
                
                app.TestPointsDropDown.Items = {'Select'};
                app.name = cell(1,length(app.efile));
                for i = 1:length(app.efile)
                    % find componets to remove in names and assign these names to drop down menu, app.name to be used in summary row names
                    k = strfind(app.efile{i},'ESR_');
                    if isempty(k)
                        k = -3;
                    end
                    if contains(app.efile{i}, '.csv')
                        app.TestPointsDropDown.Items = [app.TestPointsDropDown.Items, cellstr([app.efile{i}(1:k-1),app.efile{i}(k+4:end-4)])];
                        app.name(i) = cellstr([app.efile{i}(1:k-1),app.efile{i}(k+4:end-4)]);
                    else
                        app.TestPointsDropDown.Items = [app.TestPointsDropDown.Items, cellstr([app.efile{i}(1:k-1),app.efile{i}(k+4:end)])];
                        app.name(i) = cellstr([app.efile{i}(1:k-1),app.efile{i}(k+4:end)]);
                    end             
                end
                app.name
                
                if ~isempty(app.rfile)
                    if length(app.efile) ~= length(app.rfile)
                        msgbox('Number of ESR and RIG files do not match','Error','error');
                        return
                    end
                end
                
            else
                app.efile = [];
                app.epath = [];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryandPlotsforFlowDataUIFigure = uifigure;
            app.SummaryandPlotsforFlowDataUIFigure.Name = 'Summary and Plots for Flow Data';
            app.ColumnsListBoxLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.ColumnsListBoxLabel.Text = 'Columns';
            app.esrListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.esrListBox.Items = {};
            app.esrListBox.Multiselect = 'on';
            app.esrListBox.Value = {};
            app.OpenRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenRIGButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRIGButtonPushed, true);
            app.OpenRIGButton.Text = 'Open RIG';
            app.TestPointsDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDownLabel.Text = 'Test Points';
            app.TestPointsDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDown.Items = {'Select'};
            app.TestPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointsDropDownValueChanged, true);
            app.TestPointsDropDown.Value = 'Select';
            app.PlotESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotESRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotESRButtonPushed, true);
            app.PlotESRButton.Text = 'Plot ESR';
            app.rigListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.rigListBox.Items = {};
            app.rigListBox.Multiselect = 'on';
            app.rigListBox.Value = {};
            app.SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectcolumnstoplotincludetheTimecolumnasfirstselectionLabel.Text = 'Select test point and  columns to plot, include the Time column as first selection';
            app.PlotRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotRIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRIGButtonPushed, true);
            app.PlotRIGButton.Text = 'Plot RIG';
            app.AverageCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MaximumCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MinimumCheckBox = uicheckbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.MinimumCheckBox.Text = 'Minimum';
            app.SaveSummaryTableAsButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.SaveSummaryTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSummaryTableAsButtonPushed, true);
            app.SaveSummaryTableAsButton.Text = 'Save Summary Table As...';
            app.OpenSavedSummaryButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenSavedSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSavedSummaryButtonPushed, true);
            app.OpenSavedSummaryButton.Text = 'Open Saved Summary';
            app.UIAxes = uiaxes(app.SummaryandPlotsforFlowDataUIFigure);
            title(app.UIAxes, 'ESR Fuel Flow')
            xlabel(app.UIAxes, 'Q^2 (L/s)^2')
            ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.CalculateandPlotFlowButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.CalculateandPlotFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotFlowButtonPushed, true);
            app.CalculateandPlotFlowButton.Text = 'Calculate and Plot Flow';
            app.UIAxes_2 = uiaxes(app.SummaryandPlotsforFlowDataUIFigure);
            title(app.UIAxes_2, 'RIG Fuel Flow')
            xlabel(app.UIAxes_2, 'Q^2 (L/s)^2')
            ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.ExportFlowDataButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ExportFlowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFlowDataButtonPushed, true);
            app.ExportFlowDataButton.Text = 'Export Flow Data';
            app.K1ChangeEditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.K1ChangeEditFieldLabel.Text = 'K1 Change (%)';
            app.K1ChangeEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.K1ChangeEditField.ValueDisplayFormat = '%.5f';
            app.K1ChangeEditField.Editable = 'off';
            app.ButtonGroup_4 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ToSummaryButton = uiradiobutton(app.ButtonGroup_4);
            app.ToSummaryButton.Text = 'To Summary';
            app.ToSummaryButton.Value = true;
            app.AsNewButton = uiradiobutton(app.ButtonGroup_4);
            app.AsNewButton.Text = 'As New';
            app.SavedLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SavedLabel.Text = 'Saved?';
            app.Lamp = uilamp(app.SummaryandPlotsforFlowDataUIFigure);
            app.Lamp_2 = uilamp(app.SummaryandPlotsforFlowDataUIFigure);
            app.OpenButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SlopeEditField_2 = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SlopeEditField_2.ValueDisplayFormat = '%.5f';
            app.SlopeEditField_2.Editable = 'off';
            app.ButtonGroup_5 = uibuttongroup(app.SummaryandPlotsforFlowDataUIFigure);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.HoseEndButton = uiradiobutton(app.ButtonGroup_5);
            app.HoseEndButton.Text = 'Hose End';
            app.HoseEndButton.Value = true;
            app.LatchButton = uiradiobutton(app.ButtonGroup_5);
            app.LatchButton.Text = 'Latch';
            app.ELabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.RLabel.Text = 'R';
            app.PlotCouplingLossButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotCouplingLossButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCouplingLossButtonPushed, true);
            app.PlotCouplingLossButton.Text = 'Plot Coupling Loss';
            app.CouplingRegulationKnobLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.CouplingRegulationKnobLabel.Enable = 'off';
            app.CouplingRegulationKnobLabel.Text = 'Coupling Regulation';
            app.CouplingRegulationKnob = uiknob(app.SummaryandPlotsforFlowDataUIFigure, 'discrete');
            app.CouplingRegulationKnob.Items = {'All Data', 'Remove >50', 'Excluded'};
            app.CouplingRegulationKnob.Enable = 'off';
            app.CouplingRegulationKnob.Value = 'All Data';
            app.PodDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.PodDropDownLabel.Text = 'Pod';
            app.PodDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.PodDropDown.Items = {'905', '908', '910', '912'};
            app.PodDropDown.Value = '905';
            app.OpenESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.OpenESRButton.ButtonPushedFcn = createCallbackFcn(app, @OpenESRButtonPushed, true);
            app.OpenESRButton.Text = 'Open ESR';
            app.K1EditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.K1EditField.Value = 0.000995;
            app.K2EditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.K2EditField.Value = 1.1544;
        end
    end

    methods (Access = public)

        % Construct app
        function app = esr_rig_analysis_4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryandPlotsforFlowDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryandPlotsforFlowDataUIFigure)
        end
    end
end