classdef esr_rig_analysis_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryandPlotsforFlowDataUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        CalculateandPlotFlowButton   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        Lamp_2                       matlab.ui.control.Lamp
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        HoseEndButton                matlab.ui.control.RadioButton
        LatchButton                  matlab.ui.control.RadioButton
        PlotCouplingLossButton       matlab.ui.control.Button
        ELabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        SlopeEditField_2             matlab.ui.control.NumericEditField
        KlossEditFieldLabel          matlab.ui.control.Label
        KlossEditField               matlab.ui.control.NumericEditField
        ExportFlowDataButton         matlab.ui.control.Button
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        ToSummaryButton              matlab.ui.control.RadioButton
        AsNewButton                  matlab.ui.control.RadioButton
        OpenButton                   matlab.ui.control.Button
        CouplingRegulationKnobLabel  matlab.ui.control.Label
        CouplingRegulationKnob       matlab.ui.control.DiscreteKnob
        K1EditFieldLabel             matlab.ui.control.Label
        K1EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        K2EditField                  matlab.ui.control.NumericEditField
        Panel_4                      matlab.ui.container.Panel
        SaveSummaryButton            matlab.ui.control.Button
        OpenSummaryButton            matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        ExpandColmunsButton          matlab.ui.control.Button
        AverageCheckBox              matlab.ui.control.CheckBox
        MaximumCheckBox              matlab.ui.control.CheckBox
        MinimumCheckBox              matlab.ui.control.CheckBox
        Panel_3                      matlab.ui.container.Panel
        OpenRIGButton                matlab.ui.control.Button
        OpenESRButton                matlab.ui.control.Button
        HelpOnFilesButton            matlab.ui.control.Button
        TestPointsDropDownLabel      matlab.ui.control.Label
        TestPointsDropDown           matlab.ui.control.DropDown
        PodDropDownLabel             matlab.ui.control.Label
        PodDropDown                  matlab.ui.control.DropDown
        PlotRIGButton                matlab.ui.control.Button
        rigListBox                   matlab.ui.control.ListBox
        PlotESRButton                matlab.ui.control.Button
        esrListBox                   matlab.ui.control.ListBox
    end

    properties (Access = public)
        
        % Global variables
       
        esr1 % for first plots (1 test point)
        rig1
        % file = file names, path = full paths
        efile % esr
        epath
        rfile % rig
        rpath
        sfile % save summary
        spath
        avg
        dfile % save flow data
        dpath
        matrix
        matrix2
        hose % for coupling loss plot
        latch
        Q
        name
        status = 0
        error = 0
        
    end
    
    methods (Access = public)
        
        function rig = gettable(app,path)
            % function which opens and returns a table for a given file path
            fid = fopen(path,'r'); %Found there was a ',' at the end of csv variables, so readtable thinks there's +1 variables.
            str = fgetl(fid);
            fclose(fid);
            vars = regexp(str, ',', 'split');
            if isempty(vars{end}) %gets rid of the ',' end term.
                vars = matlab.lang.makeValidName(vars(1:end-1));
                rig = readtable(path, 'readvariablenames', false);
                rig.Properties.VariableNames = vars;
            else
                rig = readtable(path, 'readvariablenames', true);
            end
            if ~isempty(find(contains(rig.Properties.VariableNames,'Var'), 1))
                msgbox('Non-csv file exists in folder, delete and try again','Error','error')
                return
            end
        end
        
        function col = getcolumn(app,table,name)
            % gets a column in a table as an array searching by variable name
            col = cell2mat(table{:,contains(table.Properties.VariableNames,name)});
        end
        
        % gets logical array of columns to include given the list of exact column header names
        function log_array = getlog(app,var,list)
            log_array =  string(var)==list{1};
            for i = 2:length(list)
                log_array = log_array | string(var)==list{i}; % combine all 'ones'
            end
            
        end
    end

    methods (Access = private)

        % Button pushed function: OpenRIGButton
        function OpenRIGButtonPushed(app, event)
            % Opens folder selection window
            main = uigetdir();
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            if ~isequal(main, 0)
                dirinfo = dir(main);
                dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                [app.rfile,app.rpath] = deal({});
                
                % Iterates over each subfolder
                for i = 1:length(dirinfo)
                    sub = dirinfo(i).name; % Gets the ith subfolder name
                    subfiles = fullfile(main,sub,'*.csv');
                    finfo = dir(subfiles);
                    if ~dirinfo(i).isdir % checks selected right folder
%                         msgbox('Select the correct RIG folder (contains subfolders with .csv in each)','Error','error')
                        continue
                    elseif isempty(finfo)
                        msgbox('Select the correct RIG folder (contains subfolders with .csv in each)','Error','error')
                        return
                    end
                    if length(finfo)>1 % Checks if more than 1 csv in each subfolder, combines multiple csv into first
                        dirinfo(i).name
                        first = gettable(app,fullfile(main,sub,finfo(1).name));
                        rarray = table2array(first);
                        for j = 2:length(finfo)
                            rig_next = gettable(app,fullfile(main,sub,finfo(j).name));
                            rarray = [rarray;table2array(rig_next)];
                            delete(fullfile(main,sub,finfo(j).name)); % Deletes all but the first, so won't keep on appending tables when operating again
                        end
                        first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                        if exist(fullfile(main,sub,finfo(1).name),'file')
                            delete(fullfile(main,sub,finfo(1).name));
                        end
                        writetable(first, fullfile(main,sub,finfo(1).name))
                        % Appends file names and paths to array
                        app.rpath(end+1) = cellstr(fullfile(main,sub,finfo(1).name));
                        app.rfile(end+1) = cellstr(finfo(1).name);
                    else
                        app.rpath(end+1) = cellstr(fullfile(main,sub,finfo.name));
                        app.rfile(end+1) = cellstr(finfo.name);
                    end
                end

                % check equal length
                if ~isempty(app.efile)
                    if length(app.efile) < length(app.rfile)
                        msgbox(['There are ' num2str(length(app.rfile)-length(app.efile)) ' more RIG files than ESR. Move extra testpoint csv into one subfolder'],'Error','error');
                        app.error = 1;
                    elseif length(app.efile) > length(app.rfile)
                        msgbox(['There are ' num2str(length(app.efile)-length(app.rfile)) ' more ESR files than RIG. Merge same testpoint csv nto same spreadsheet'],'Error','error');
                        app.error = 1;
                    else
                        app.error = 0;
                    end
                    disp('Files opened');
                end
                
                %Load and select Column headers list box
                rig = gettable(app,app.rpath{1});
                app.rigListBox.Items = sort(rig.Properties.VariableNames);
                xvars_r = ["Time","Coriolis"];
                rlog = ~contains(app.rigListBox.Items,xvars_r);
                app.rigListBox.Value = string(app.rigListBox.Items(rlog));
                
                
            else
                app.rfile = [];
                app.rpath = [];
                disp('No files selected');
            end
        end

        % Button pushed function: PlotESRButton
        function PlotESRButtonPushed(app, event)
            % Check correct inputs
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.esrListBox.Value)<2                
                msgbox('Select Time Axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.esrListBox.Value{1},'Time'),1)) && isempty(find(contains(app.esrListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            % Plots selected columns against time column
            figure(1)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('ESR')
            grid           
            for i = 2:length(app.esrListBox.Value)
                plot(app.esr1.(app.esrListBox.Value{1}),app.esr1.(app.esrListBox.Value{i}))
            end
            legend(app.esrListBox.Value(2:end))
        end

        % Button pushed function: PlotRIGButton
        function PlotRIGButtonPushed(app, event)
            if isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            elseif length(app.rigListBox.Value)<2                
                msgbox('Select Time axis AND other axes to plot', 'Error', 'error');
                return
            elseif isempty(find(contains(app.rigListBox.Value{1},'Time'),1)) && isempty(find(contains(app.rigListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            figure(2)
            hold on
            xlabel('Time (ms)')
            ylabel('Pressure (psig), Flow (USgpm)')
            title('RIG')
            grid           
            for i = 2:length(app.rigListBox.Value)
                plot(app.rig1.(app.rigListBox.Value{1}),app.rig1.(app.rigListBox.Value{i}))
            end
            legend(app.rigListBox.Value(2:end))
        end

        % Value changed function: TestPointsDropDown
        function TestPointsDropDownValueChanged(app, event)
            % Seletet test point for plotting selected columns
            
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open both ESR and RIG data files first','Error','error')
                return
            end
            
            if ~isequal(app.TestPointsDropDown.Value,'Select')
                k = find(ismember(app.TestPointsDropDown.Items,app.TestPointsDropDown.Value))-1; % Get index of selected point
                app.esr1 = gettable(app,app.epath{k});
                app.rig1 = gettable(app,app.rpath{k});                                
               
                
            end
        end

        % Button pushed function: SaveSummaryButton
        function SaveSummaryButtonPushed(app, event)
            % Check correct input
            if isempty(app.efile) || isempty(app.rfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif app.AverageCheckBox.Value == false && app.MaximumCheckBox.Value == false && app.MinimumCheckBox.Value == false
                msgbox('Select Type of Statistic to Save', 'Error', 'error');
                return
            elseif isempty(app.esrListBox.Value) || isempty(app.rigListBox.Value)
                msgbox('Select columns in the List Box first','Error','error')
            elseif app.error == 1
                msgbox('Different number of files in ESR/RIG','Error','error')
                return
            end
            
            % Opens 'save as' window for user to choose file path and name
            [app.sfile , app.spath] = uiputfile({'*.xlsx'},'Save As','summary.xlsx');
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            
            if app.sfile ~=0
                % Makes sure file is writable (not opened)
                if fopen([app.spath app.sfile],'a') == -1
                    msgbox('Close existing flow data window and try again','Error','error');
                    return
                else
                    fclose('all');
                end
                delete([app.spath app.sfile]);
            else
                app.sfile = [];
                return
            end
            
            bar = waitbar(0,'Loading...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
            setappdata(bar,'canceling',0);
            
            for i = 1:length(app.efile) % iterate over each test point
                esr = gettable(app,app.epath{i});
                rig = gettable(app,app.rpath{i});
                
                % sorts columns from headers (different order)
                esr = esr(:,sort(esr.Properties.VariableNames));
                rig = rig(:,sort(rig.Properties.VariableNames));
                
                if i == 1
                    we = width(esr); % for checking if testpoint has extra columns 
                    wr = width(rig);
                    elist = length(app.esrListBox.Value); % for checking new selection's lengths equal later
                    rlist = length(app.rigListBox.Value);
                    esr_include = getlog(app,esr.Properties.VariableNames,app.esrListBox.Value); % gets logical array for indexing of columns to include
                    rig_include = getlog(app,rig.Properties.VariableNames,app.rigListBox.Value);
                    
                    % Declare variables into correct size and titles
                    avg = cell(length(app.efile)+1,length(find(esr_include))+length(find(rig_include))+2);
                    avg(1,1) = {'Average'};
                    maxi = cell(length(app.efile)+1,length(find(esr_include))+length(find(rig_include))+2);
                    maxi(1,1) = {'Maximum'};
                    mini = cell(length(app.efile)+1,length(find(esr_include))+length(find(rig_include))+2);
                    mini(1,1) = {'Minimum'};
                
                % if different number of columns in next csv file    
                elseif width(esr) ~= we || width(rig) ~= wr
                    uiwait(msgbox(['Different number of columns in ' app.efile{i} ', select columns again'],'Warning','warn'));
                    figure(app.SummaryandPlotsforFlowDataUIFigure);
                    app.esrListBox.Items = esr.Properties.VariableNames; % updates listbox's contents to current file
                    app.rigListBox.Items = rig.Properties.VariableNames;
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","MassFuelFlow","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                    xvars_r = ["Coriolis","Time"];
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.esrListBox.Value = string(app.esrListBox.Items(elog));
                    app.rigListBox.Value = string(app.rigListBox.Items(rlog));
                    msg = msgbox('Select new columns then click OK','Select Columns','modal');
                    frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                    frames(end).setAlwaysOnTop(1);
                    uiwait(msg);
                    while length(app.esrListBox.Value) ~= elist % Checks new selection is compatible (same length)
                        uiwait(msgbox(['There should be ' num2str(elist) ' columns in ESR (' num2str(length(app.esrListBox.Value)) ' selected). Click OK when corrected'],'Select New','modal'));
                    end
                    while length(app.rigListBox.Value) ~= rlist
                        uiwait(msgbox(['There should be ' num2str(rlist) ' columns in RIG (' num2str(length(app.rigListBox.Value)) ' selected). Click OK when corrected'],'Select New','modal'));
                    end
                    figure(bar);
                    esr_include = getlog(app,esr.Properties.VariableNames,app.esrListBox.Value); % new logical array for indexing
                    rig_include = getlog(app,rig.Properties.VariableNames,app.rigListBox.Value);
                    we = width(esr); % new no. of columns
                    wr = width(rig);
                end
                
                esr = esr(:,esr_include);
                rig = rig(:,rig_include);
                
                % average,max,min value calculation for this test point in the iteration, and is added to corresponding matrix
                if app.AverageCheckBox.Value == true         
                    average_esr = varfun(@mean,esr); % single row of mean values
                    average_esr = table2array(average_esr);
                    average_rig = varfun(@mean,rig);
                    average_rig = table2array(average_rig);
                    
                    % add shortened name of file
                    avg(i+1,1) = app.name(i);
                    avg(i+1,2:length(average_esr)+1) = num2cell(average_esr);
                    avg(i+1,end-length(average_rig)+1:end) = num2cell(average_rig);
                else
                    avg = [];
                end
                
                if app.MaximumCheckBox.Value == true                
                    max_esr = varfun(@max,esr);
                    max_esr = table2array(max_esr);
                    max_rig = varfun(@max,rig);
                    max_rig = table2array(max_rig);

                    maxi(i+1,1) = cellstr(app.efile{i});
                    maxi(i+1,2:length(max_esr)+1) = num2cell(max_esr);
                    maxi(i+1,end-length(max_rig)+1:end) = num2cell(max_rig);
                else
                    maxi = [];
                end
                
                if app.MinimumCheckBox.Value == true                    
                    min_esr = varfun(@min,esr);
                    min_esr = table2array(min_esr);
                    min_rig = varfun(@min,rig);
                    min_rig = table2array(min_rig); 
                    
                    mini(i+1,1) = cellstr(app.efile{i});
                    mini(i+1,2:length(min_esr)+1) = num2cell(min_esr);
                    mini(i+1,end-length(min_rig)+1:end) = num2cell(min_rig);
                else
                    mini = [];
                end
                
                if getappdata(bar,'canceling')
                    delete(bar)
                    app.sfile = [];
                    disp('Canceled');
                    return
                end
                progress = i/length(app.efile)-0.01;
                waitbar(progress,bar,['Loading...(' num2str(progress*100,'%.1f') '%)']);
            end
            
            % for flow equation calculations use later
            app.avg = array2table(avg(2:end,:),'VariableNames',[{'TestPoint'},esr.Properties.VariableNames,{'Blank'},rig.Properties.VariableNames]);
            
            % combine avg, maxi and mini into main matrix to be exported
            app.matrix=[cell(1,1), esr.Properties.VariableNames,cell(1,1),rig.Properties.VariableNames;
                avg;cell(1,width(esr)+width(rig)+2);maxi;cell(1,width(esr)+width(rig)+2);mini;cell(1,width(esr)+width(rig)+2)];
            
            
            xlswrite([app.spath app.sfile],app.matrix,1)
            disp('Sumamry Saved');
            app.Lamp.Color = [0 1 0];
            waitbar(1,bar,'Summary Saved!');
            pause(1)
            delete(bar)
        end

        % Button pushed function: OpenSummaryButton
        function OpenSummaryButtonPushed(app, event)
            % Opens saved summary file
            if isempty(app.sfile)
                msgbox('No Summary File Found', 'Error', 'error');
            elseif exist([app.spath app.sfile],'file')
                if app.Lamp.Color == [0 1 0] % Ensures new file is complete (takes 10 seconds)
                    winopen([app.spath app.sfile])
                end
            end
        end

        % Button pushed function: CalculateandPlotFlowButton
        function CalculateandPlotFlowButtonPushed(app, event)
            if isempty(app.avg)
                msgbox('Save saummary first', 'Error', 'error');
                return
            end
            % Different K1 values from user input
            K2 = app.K2EditField.Value;
            K1 = app.K1EditField.Value;
            
            % find columns with flow rate equation variables to calculate + plot
            rho  = getcolumn(app,app.avg,'DensityMeter'); % rig
            if isempty(find(rho, 1))
                rho = getcolumn(app,app.avg,'FuelDensity');
                msgbox('No density in RIG, used density in ESR instead','Info','help');
            end
            Pout = getcolumn(app,app.avg,'OutletPressure'); % esr 
            Pher = getcolumn(app,app.avg,'HoseEndSteady'); % rig
            Pl = getcolumn(app,app.avg,'Latch'); % rig
            Q = find(contains(app.avg.Properties.VariableNames,'FuelFlow'));
            Qe = (cell2mat(app.avg{:,Q(1)})).^2; % esr usgpm
            Qr= (cell2mat(app.avg{:,Q(end)})).^2; % rig usgpm
            Phee = getcolumn(app,app.avg,'HoseEndPressure'); % esr

            % For later plotting coupling losses use
            app.hose = Pher;
            app.latch = Pl;
            app.Q = Qe;
            
            % Clears axes, calculates specific values in flow equation and plots as required to find slope
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            avgrho = mean(rho);
            Phead = avgrho*9.81*4.21/6894.757; % Pressure gained from catenary (psi)
            
            % change export column header and use latch/hose end pressure
            if app.LatchButton.Value == true
                y = Pout - Pl + Phead; % psi
                header = {'P(OUT)-P(L) corrected for catenary'};
            else
                y = Pout - Pher + Phead;
                header = {'P(OUT)-P(HE) corrected for catenary'};
            end
            
            % Change formulae/values if 908/905 (metric/imperial)
            if app.PodDropDown.Value == '908'
                Qe = Qe/(60^2); % L/s
                Qr = Qr/(15.85032^2); % L/s
                calPhe = Pout - (K1*avgrho).*Qe/6.89475 + K2*avgrho/6.894757; % psi
                y = y*6.894757; % kPa
            elseif app.PodDropDown.Value == '905'
                calPhe = Pout - (K1*avgrho/119.8).*Qe + K2*avgrho/119.8; % psi
                avgrho = avgrho / 119.8;
            end  
            
            dP = avgrho*K1; % slope of RCU estimation
            
            % for later export use (flow calculation data)
            try
                app.matrix2 = [{'avg. rho'},avgrho,cell(1,length(rho)-1);{'P(catenary)'},Phead,cell(1,length(rho)-1);cell(1,length(rho)+1); header,num2cell(y'),cell(1,length(rho)-length(y));{'Q^2 esr'},num2cell(Qe'),cell(1,length(rho)-length(y));{'Q^2 rig'},num2cell(Qr'),cell(1,length(rho)-length(y));
                    {'Q^2 (RCU)'},num2cell(max(Qe)),cell(1,length(rho)-1);{'dP (RCU)'},num2cell(dP*max(Qe)),cell(1,length(rho)-1);cell(1,length(rho)+1);{'Calculated P(HE)'},num2cell(calPhe');{'% Difference'},num2cell(100*(calPhe'-Phee')./Phee')];
            catch
                msgbox(sprintf('One or more variables missing!\nESR: Outlet Pressure, Inlet Pressure, Fuel Flow, Hose End Pressure\nRIG: Density, Hose End Pressure, Latch Pressure'),'Error','error');
                return
            end
                
            % If exclude chosen, changes arrays to shorter version, excluding data if Hose End Pressure > 50
            include = Pher <= 50;
            if isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{2})
                avgrho = mean(rho(include));
                y = y(include);
                Qe = Qe(include);
                Qr = Qr(include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('All points excluded','Warning','warn');
                    return
                end
            elseif isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{3})
                avgrho = mean(rho(~include));
                y = y(~include);
                Qe = Qe(~include);
                Qr = Qr(~include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('No points excluded','Warning','warn');
                    return
                end
            end          
            
            % plot in preview axes
            m1 = Qe\y; % Slope of y = mx
            app.KlossEditField.Value = m1/avgrho;
            plot(app.UIAxes,Qe,y, 'or')
            plot(app.UIAxes,[0;Qe],[0;Qe*m1],'b')
            plot(app.UIAxes,[0,max(Qe)],[0,dP*max(Qe)],'g')
            legend(app.UIAxes,'Data points','Best Fit through 0','RCU estimation','Location','northwest') 
            m2 = Qr\y; % Slope of y = mx
            app.SlopeEditField_2.Value = m2/avgrho;
            plot(app.UIAxes_2,Qr,y,'or')
            plot(app.UIAxes_2,[0;Qr],[0;Qr*m2],'b')
            plot(app.UIAxes_2,[0,max(Qr)],[0,dP*max(Qr)],'g')
            
            
            % for later export use (flow calculation data)
            app.matrix2 = [app.matrix2 ; cell(1,length(rho)+1);[{'slope esr'},m1,{'K loss'},m1/(avgrho),{'% Difference'},100*(m1/(avgrho)-K1)/K1]',[{'slope rig'},m2,{'K loss'},m2/(avgrho),{'% Difference'},100*(m2/(avgrho)-K1)/K1]',cell(6,length(rho)-1)];
            app.matrix2 = app.matrix2';    
        end

        % Button pushed function: ExportFlowDataButton
        function ExportFlowDataButtonPushed(app, event)
            if isempty(app.efile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end            
            if isempty(app.matrix2)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Appends new data to summary or make new excel file 
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    % Makes sure file is writable (not opened)
                    if fopen([app.spath app.sfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    xlswrite([app.spath app.sfile],app.matrix2,1,[char(size(app.matrix,2)+66) , '2'])
                    app.Lamp_2.Color = [0 1 0];
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else                
                [app.dfile , app.dpath] = uiputfile({'*.xlsx'},'Save As','flow_data.xlsx');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if app.dfile ~=0
                    if fopen([app.dpath app.dfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    delete([app.dpath app.dfile]);
                    xlswrite([app.dpath app.dfile],app.matrix2)
                    app.Lamp_2.Color = [0 1 0];
                else
                    app.dfile = [];
                end
            end    
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Opens Flow Data (either summary or new)
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    winopen([app.spath app.sfile])
                else
                    msgbox('No Summary File Found', 'Error', 'error');
                end
            else
                if exist([app.dpath app.dfile],'file')
                    winopen([app.dpath app.dfile])
                else
                    msgbox('No Data File Found', 'Error', 'error');
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            % Changes y axes labels for Hose End / Latch Pressure
            if app.HoseEndButton.Value == true
                if app.PodDropDown.Value == '905'
                    ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)');
                elseif app.PodDropDown.Value == '908'
                    ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (kPa)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (kPa)');
                end
                app.CouplingRegulationKnob.Enable = 'off';
            else
                if app.PodDropDown.Value == '905'
                    ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (psi)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (psi)');
                elseif app.PodDropDown.Value == '908'
                    ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (kPa)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (kPa)');
                end
                app.CouplingRegulationKnob.Enable = 'on';
            end            
        end

        % Button pushed function: PlotCouplingLossButton
        function PlotCouplingLossButtonPushed(app, event)
            if isempty(app.efile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.hose)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Import and separate variables
            Phe = app.hose;
            Pl = app.latch;
            Q = app.Q;
            dP = Phe - Pl;            
            dP_1 = dP(Phe<=50);
            dP_2 = dP(Phe>50);
            Q_1 = Q(Phe<=50);
            Q_2 = Q(Phe>50);
            
            % Plot the 2 sets of data as scattered points and different colours
            hold on
            xlabel('Rig Fuel Flow (USgpm)')
            ylabel('MA4 Losses (psi)')
            title('Coupling Pressure Losses')
            grid
            plot(Q_1,dP_1,'ob','MarkerFaceColor', 'b')
            plot(Q_2,dP_2,'or','MarkerFaceColor', 'r')
            legend('Regulators Opened','Regulators Closed','Location','northwest')
        end

        % Button pushed function: OpenESRButton
        function OpenESRButtonPushed(app, event)
            % Opens folder selection window
            main = uigetdir();
            figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            if ~isequal(main, 0)
                dirinfo = dir(main);
                dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                [app.epath,app.efile] = deal({});
                app.TestPointsDropDown.Items = {'Select'};
                app.name = {};
                for i = 1:length(dirinfo)
                    if dirinfo(i).isdir
%                         msgbox('Select correct ESR folder (only contains .csv files)','Error','error')
                        
                        continue
                    end
                    if ~contains(dirinfo(i).name, '.xlsx') || ~contains(dirinfo(i).name, '.xls') % check for non csv files
                        app.epath(end+1) = cellstr(fullfile(main,dirinfo(i).name));
                        app.efile(end+1) = cellstr(dirinfo(i).name);
                    else
                        msgbox('Non-csv file present in folder, delete and try again','Error','error');
                        return
                    end
                    % find componets to remove in names and assign these names to drop down menu, app.name to be used in summary row names
                    k = strfind(app.efile{end},'ESR_');
                    if isempty(k)
                        k = -3;
                    end
                    if contains(app.efile{end}, '.csv')
                        app.TestPointsDropDown.Items(end+1) = cellstr([app.efile{end}(1:k-1),app.efile{end}(k+4:end-4)]);
                        app.name(end+1) = cellstr([app.efile{end}(1:k-1),app.efile{end}(k+4:end-4)]);
                    else
                        app.TestPointsDropDown.Items(end+1) = cellstr([app.efile{end}(1:k-1),app.efile{end}(k+4:end)]);
                        app.name(end+1) = cellstr([app.efile{end}(1:k-1),app.efile{end}(k+4:end)]);
                    end
                end
                
                % check equal length
                if ~isempty(app.rfile)
                    if length(app.efile) < length(app.rfile)
                        msgbox(['There are ' num2str(length(app.rfile)-length(app.efile)) ' more RIG files than ESR. Move extra testpoint csv into one subfolder'],'Error','error');
                        app.error = 1;                        
                    elseif length(app.efile) > length(app.rfile)
                        msgbox(['There are ' num2str(length(app.efile)-length(app.rfile)) ' more RIG files than ESR. Merge same testpoint csv nto same spreadsheet'],'Error','error');
                        app.error = 1;
                    else
                        app.error = 0;
                    end
                    disp('Files opened');
                end
                
                %Load and select Column headers list box
                esr = gettable(app,app.epath{1});
                app.esrListBox.Items = sort(esr.Properties.VariableNames);
                vars = ["OutletPressure","InletPressure","HoseEndPressure","MassFuelFlow","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                app.esrListBox.Value = string(app.esrListBox.Items(elog));
                
            else
                app.efile = [];
                app.epath = [];
            end
        end

        % Button pushed function: ExpandColmunsButton
        function ExpandColmunsButtonPushed(app, event)
            % expands the list box to allow most/all items to be seen at once, also auto selects the searched common column names to save time
            if app.status == 0
                app.esrListBox.Position(4) = app.esrListBox.Position(4) + 450;
                app.esrListBox.Position(2) = app.esrListBox.Position(2) - 450;
                app.rigListBox.Position(4) = app.rigListBox.Position(4) + 120;
                app.rigListBox.Position(2) = app.rigListBox.Position(2) - 120;
                app.status = 1;
                
                
            else % returns box to default size
                app.esrListBox.Position(4) = app.esrListBox.Position(4) - 450;
                app.esrListBox.Position(2) = app.esrListBox.Position(2) + 450;
                app.rigListBox.Position(4) = app.rigListBox.Position(4) - 120;
                app.rigListBox.Position(2) = app.rigListBox.Position(2) + 120;
                app.status = 0;
            end
        end

        % Button pushed function: HelpOnFilesButton
        function HelpOnFilesButtonPushed(app, event)
            h = msgbox(sprintf('ESR folder: only csv files (can have empty extensions)\nRIG folder: 1 subfolder for each test point (subfolder can contain multiple csv files)') ,'About !','help');
        end

        % Value changed function: PodDropDown
        function PodDropDownValueChanged(app, event)
            if app.PodDropDown.Value == '905'
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.K1EditField.Value = 8.12e-5;
                app.K2EditField.Value = 1.1544;
            elseif app.PodDropDown.Value == '908'
                xlabel(app.UIAxes, 'Q^2 (L/s)^2');
                xlabel(app.UIAxes_2, 'Q^2 (L/s)^2');
                app.K1EditField.Value = 9.95e-4; % R9 Khl (will change when latch is selected)
                app.K2EditField.Value = 4.56e-2;
            elseif app.PodDropDown.Value == '912'
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.K1EditField.Value = 8.077e-5;
                app.K2EditField.Value = 1.11;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryandPlotsforFlowDataUIFigure = uifigure;
            app.SummaryandPlotsforFlowDataUIFigure.Name = 'Summary and Plots for Flow Data';
            app.Panel = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'ESR Fuel Flow')
            xlabel(app.UIAxes, 'Q^2 (USgpm)^2')
            ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.CalculateandPlotFlowButton = uibutton(app.Panel, 'push');
            app.CalculateandPlotFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotFlowButtonPushed, true);
            app.CalculateandPlotFlowButton.Tooltip = {'Gets new K1 from slope and compares with RCU''s estimation'};
            app.CalculateandPlotFlowButton.Text = 'Calculate and Plot Flow';
            app.UIAxes_2 = uiaxes(app.Panel);
            title(app.UIAxes_2, 'RIG Fuel Flow')
            xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2')
            ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.Lamp_2 = uilamp(app.Panel);
            app.ButtonGroup_5 = uibuttongroup(app.Panel);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.HoseEndButton = uiradiobutton(app.ButtonGroup_5);
            app.HoseEndButton.Text = 'Hose End';
            app.HoseEndButton.Value = true;
            app.LatchButton = uiradiobutton(app.ButtonGroup_5);
            app.LatchButton.Text = 'Latch';
            app.PlotCouplingLossButton = uibutton(app.Panel, 'push');
            app.PlotCouplingLossButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCouplingLossButtonPushed, true);
            app.PlotCouplingLossButton.Tooltip = {'P(HE) - P(L) against Flow Rate'; ' indicates regulator opened/closed'};
            app.PlotCouplingLossButton.Text = 'Plot Coupling Loss';
            app.ELabel = uilabel(app.Panel);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.Panel);
            app.RLabel.Text = 'R';
            app.SlopeEditField_2 = uieditfield(app.Panel, 'numeric');
            app.SlopeEditField_2.ValueDisplayFormat = '%.4e';
            app.SlopeEditField_2.Editable = 'off';
            app.KlossEditFieldLabel = uilabel(app.Panel);
            app.KlossEditFieldLabel.Text = 'K(loss)';
            app.KlossEditField = uieditfield(app.Panel, 'numeric');
            app.KlossEditField.ValueDisplayFormat = '%.4e';
            app.KlossEditField.Editable = 'off';
            app.ExportFlowDataButton = uibutton(app.Panel, 'push');
            app.ExportFlowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFlowDataButtonPushed, true);
            app.ExportFlowDataButton.Tooltip = {'Add data to summary or on its own'};
            app.ExportFlowDataButton.Text = 'Export Flow Data';
            app.ButtonGroup_4 = uibuttongroup(app.Panel);
            app.ToSummaryButton = uiradiobutton(app.ButtonGroup_4);
            app.ToSummaryButton.Text = 'To Summary';
            app.ToSummaryButton.Value = true;
            app.AsNewButton = uiradiobutton(app.ButtonGroup_4);
            app.AsNewButton.Text = 'As New';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open excel of summary or new flow data'};
            app.OpenButton.Text = 'Open';
            app.CouplingRegulationKnobLabel = uilabel(app.Panel);
            app.CouplingRegulationKnobLabel.Enable = 'off';
            app.CouplingRegulationKnobLabel.Text = 'Coupling Regulation';
            app.CouplingRegulationKnob = uiknob(app.Panel, 'discrete');
            app.CouplingRegulationKnob.Items = {'All Data', 'Remove >50', 'Excluded'};
            app.CouplingRegulationKnob.Enable = 'off';
            app.CouplingRegulationKnob.Tooltip = {'Exclude points if P(HE) > 50 psi'};
            app.CouplingRegulationKnob.Value = 'All Data';
            app.K1EditFieldLabel = uilabel(app.Panel);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.Panel, 'numeric');
            app.K1EditField.Tooltip = {'Enter K1 and K2 values'};
            app.K1EditField.Value = 0.000995;
            app.K2EditFieldLabel = uilabel(app.Panel);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.Panel, 'numeric');
            app.K2EditField.Value = 1.1544;
            app.Panel_4 = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SaveSummaryButton = uibutton(app.Panel_4, 'push');
            app.SaveSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSummaryButtonPushed, true);
            app.SaveSummaryButton.Tooltip = {'Saves summary of selected columns as .xlsx'; 'Make sure to have same columns when re-selecting'};
            app.SaveSummaryButton.Text = 'Save Summary';
            app.OpenSummaryButton = uibutton(app.Panel_4, 'push');
            app.OpenSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSummaryButtonPushed, true);
            app.OpenSummaryButton.Text = 'Open Summary';
            app.Lamp = uilamp(app.Panel_4);
            app.Lamp.Tooltip = {'Green for successful save'};
            app.ExpandColmunsButton = uibutton(app.Panel_4, 'push');
            app.ExpandColmunsButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandColmunsButtonPushed, true);
            app.ExpandColmunsButton.Tooltip = {'Press again to collapse'};
            app.ExpandColmunsButton.Text = 'Expand Colmuns';
            app.AverageCheckBox = uicheckbox(app.Panel_4);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MaximumCheckBox = uicheckbox(app.Panel_4);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MinimumCheckBox = uicheckbox(app.Panel_4);
            app.MinimumCheckBox.Text = 'Minimum';
            app.Panel_3 = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.OpenRIGButton = uibutton(app.Panel_3, 'push');
            app.OpenRIGButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRIGButtonPushed, true);
            app.OpenRIGButton.Tooltip = {'RIG folder containing subfolder for each testpoint (one or more csv files in each)'};
            app.OpenRIGButton.Text = 'Open RIG';
            app.OpenESRButton = uibutton(app.Panel_3, 'push');
            app.OpenESRButton.ButtonPushedFcn = createCallbackFcn(app, @OpenESRButtonPushed, true);
            app.OpenESRButton.Tooltip = {'ESR folder containing only csv (or empty extension) files'};
            app.OpenESRButton.Text = 'Open ESR';
            app.HelpOnFilesButton = uibutton(app.Panel_3, 'push');
            app.HelpOnFilesButton.ButtonPushedFcn = createCallbackFcn(app, @HelpOnFilesButtonPushed, true);
            app.HelpOnFilesButton.Tooltip = {'File importng format tips'};
            app.HelpOnFilesButton.Text = 'Help On Files';
            app.TestPointsDropDownLabel = uilabel(app.Panel_3);
            app.TestPointsDropDownLabel.Text = 'Test Points';
            app.TestPointsDropDown = uidropdown(app.Panel_3);
            app.TestPointsDropDown.Items = {'Select'};
            app.TestPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointsDropDownValueChanged, true);
            app.TestPointsDropDown.Tooltip = {'For individual variable plots'};
            app.TestPointsDropDown.Value = 'Select';
            app.PodDropDownLabel = uilabel(app.Panel_3);
            app.PodDropDownLabel.Text = 'Pod';
            app.PodDropDown = uidropdown(app.Panel_3);
            app.PodDropDown.Items = {'905', '908', '910', '912'};
            app.PodDropDown.ValueChangedFcn = createCallbackFcn(app, @PodDropDownValueChanged, true);
            app.PodDropDown.Tooltip = {'905 (imperial)'; '908 (SI)'; 'Affects flow equation calculations'};
            app.PodDropDown.Value = '905';
            app.PlotRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotRIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRIGButtonPushed, true);
            app.PlotRIGButton.Tooltip = {'Select x-axis as the first selection'; 'then multiselect one or more y-axes'};
            app.PlotRIGButton.Text = 'Plot RIG';
            app.rigListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.rigListBox.Items = {};
            app.rigListBox.Multiselect = 'on';
            app.rigListBox.Tooltip = {'Hold down Ctrl to multiselect'};
            app.rigListBox.Value = {};
            app.PlotESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotESRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotESRButtonPushed, true);
            app.PlotESRButton.Tooltip = {'Select x-axis as the first selection'; 'then multiselect one or more y-axes'};
            app.PlotESRButton.Text = 'Plot ESR';
            app.esrListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.esrListBox.Items = {};
            app.esrListBox.Multiselect = 'on';
            app.esrListBox.Tooltip = {'Hold down Ctrl to multiselect'};
            app.esrListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = esr_rig_analysis_8

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryandPlotsforFlowDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryandPlotsforFlowDataUIFigure)
        end
    end
end