classdef esr_rig_analysis_15 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryandPlotsforFlowDataUIFigure  matlab.ui.Figure
        flow                         matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        CalculateandPlotFlowButton   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        Lamp_2                       matlab.ui.control.Lamp
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        HoseEndButton                matlab.ui.control.RadioButton
        LatchButton                  matlab.ui.control.RadioButton
        PlotCouplingLossButton       matlab.ui.control.Button
        ELabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        SlopeEditField_2             matlab.ui.control.NumericEditField
        KlossEditFieldLabel          matlab.ui.control.Label
        KlossEditField               matlab.ui.control.NumericEditField
        ExportFlowDataButton         matlab.ui.control.Button
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        ToSummaryButton              matlab.ui.control.RadioButton
        AsNewButton                  matlab.ui.control.RadioButton
        SummaryCopyButton            matlab.ui.control.RadioButton
        OpenButton                   matlab.ui.control.Button
        CouplingRegulationKnobLabel  matlab.ui.control.Label
        CouplingRegulationKnob       matlab.ui.control.DiscreteKnob
        KHLEditFieldLabel            matlab.ui.control.Label
        KHLEditField                 matlab.ui.control.NumericEditField
        KCGEditFieldLabel            matlab.ui.control.Label
        KCGEditField                 matlab.ui.control.NumericEditField
        KCLEditFieldLabel            matlab.ui.control.Label
        KCLEditField                 matlab.ui.control.NumericEditField
        summary                      matlab.ui.container.Panel
        SaveSummaryButton            matlab.ui.control.Button
        OpenSummaryButton            matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        ExpandColmunsButton          matlab.ui.control.Button
        AverageCheckBox              matlab.ui.control.CheckBox
        MaximumCheckBox              matlab.ui.control.CheckBox
        MinimumCheckBox              matlab.ui.control.CheckBox
        ImportSummaryButton          matlab.ui.control.Button
        top                          matlab.ui.container.Panel
        HelpOnFilesButton            matlab.ui.control.Button
        PodDropDownLabel             matlab.ui.control.Label
        PodDropDown                  matlab.ui.control.DropDown
        ButtonGroup_6                matlab.ui.container.ButtonGroup
        csvButton                    matlab.ui.control.RadioButton
        noextensionsButton           matlab.ui.control.RadioButton
        OpenESRButton                matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        ButtonGroup_7                matlab.ui.container.ButtonGroup
        SubfoldersButton             matlab.ui.control.RadioButton
        Allin1folderButton           matlab.ui.control.RadioButton
        OpenRIGButton                matlab.ui.control.Button
        FlowEquationButton           matlab.ui.control.Button
        FileOrderButton              matlab.ui.control.Button
        PlotRIGButton                matlab.ui.control.Button
        PlotESRButton                matlab.ui.control.Button
        TestPointsDropDownLabel      matlab.ui.control.Label
        TestPointsDropDown           matlab.ui.control.DropDown
        RefreshButton                matlab.ui.control.Button
        SelectedEditFieldLabel       matlab.ui.control.Label
        SelectedEsrEditField         matlab.ui.control.NumericEditField
        SelectedEditField_2Label     matlab.ui.control.Label
        SelectedRigEditField         matlab.ui.control.NumericEditField
        esrListBox                   matlab.ui.control.ListBox
        rigListBox                   matlab.ui.control.ListBox
        EditField                    matlab.ui.control.EditField
        ExecuteButton                matlab.ui.control.Button
        CorrectButton                matlab.ui.control.Button
        IncorrectButton              matlab.ui.control.Button
        Swap2Button                  matlab.ui.control.Button
        ResettoDefaultButton         matlab.ui.control.Button
        ReorderAllButton             matlab.ui.control.StateButton
        InsertAboveButton            matlab.ui.control.StateButton
    end

    properties (Access = public)
        
        % Global variables
       
        esr1 % for first plots (1 test point)
        rig1
        % file = file names, path = full paths
        epath% esr
        rpath% rig
        sfile % save summary
        spath
        eavg % Fuel equation
        ravg
        dfile % save flow data
        dpath
        matrix
        matrix2
        hose % for coupling loss plot
        latch
        Q
        name
        status = 0 % expand listbox
        error = 0 % open file imbalance esr/rig
        error2 = 0 % listbox testpoint arrya error
        reorder = 0 % reorder listbox file name order
        insert_idx
        V = {}
        
    end
    
    methods (Access = public)
        
        function rig = gettable(app,path)
            % function which opens and returns a table for a given file path
            fid = fopen(path,'r'); %Found there was a ',' at the end of csv variables, so readtable thinks there's +1 variables.
            str = fgetl(fid);
            fclose(fid);
            vars = regexp(str, ',', 'split');
            if isempty(vars{end}) %gets rid of the ',' end term.
                vars = matlab.lang.makeValidName(vars(1:end-1));
                rig = readtable(path, 'readvariablenames', false);
                rig.Properties.VariableNames = vars;
            else
                rig = readtable(path, 'readvariablenames', true);
            end
            if any(contains(rig.Properties.VariableNames,'Var'))
                error('Non-csv file exists in folder, correct and try again (Don''t worry, this is a programmed warning)') % Produce error to terminate all code outside of this function
            end
        end
        
        function col = getcolumn(app,table,name)
            % gets a column in a table as an array searching by variable name
            if isequal(name,'FuelFlow')
                col = cell2mat(table{:,contains(table.Properties.VariableNames,name) & ~contains(table.Properties.VariableNames,'Mass') & ~contains(table.Properties.VariableNames,'Limit')});
            else
                col = cell2mat(table{:,contains(table.Properties.VariableNames,name)});
            end
        end
       
        function log_array = getlog(app,var,list)
            % gets logical array of columns to include given the list of exact column header names (from all var)
            a =  string(var)==list{1};
            for i = 2:length(list)
                a = a | string(var)==list{i}; % combine all 'ones'
            end
            log_array = a;
        end
        
        function C = rho2c(app,density,flowrate)
            % takes density => viscosity => Reynold's number => Reynold's Correction Factor to be used in 910 flow equation
            if density <= 773
                A = [8.59415305976075E-09   -5.59208021888923E-06];
            elseif density <= 832
                A = [2.38402723512495E-15   -9.14947972622544E-12   1.40392422313046E-08    -1.07659438678346E-05   4.12584245862576E-03    -6.32131305709171E-01];	
            elseif density >832
                A = [1.70744624416575E-11   -5.74268841367930E-08   7.24368939505538E-05    -4.06129135030113E-02   8.53963882581498E+00];
            end
            V = polyval(A, density);
            
            Re = 4*0.001*flowrate/(pi*0.05588*V); % K(LtoM) * Hose Diameter * Flow Rate / (Cross Area * Viscosity) as defined in RP910E-75-ES-045_9
            
            if Re <= 5078
                B = [-4.267201030479049e-09    -2.153867836103919e-04   3.112789509388798e+00];
                C = polyval(B,Re);
            elseif Re <= 7.5e4
                B = [6.398531531217704e+01   -4.592807528896194e-01    7.298284194055338e-01];
               	f = @(b,x) b(1).*x.^b(2)+b(3); % Non-integer power (non-discrete polynomial)
                C = f(B,Re);
            elseif Re <= 5e5
                B = [3.207728059299550e+02   -6.305019204696392e-01    8.299952643688508e-01];
                f = @(b,x) b(1).*x.^b(2)+b(3);
                C = f(B,Re);
            elseif Re > 5e5
                B = [-2.135027382370963e-20    9.674970001826801e-14   -1.590283356135035e-07     9.692671422144649e-01];
                C = polyval(B,Re);
            end
        end
        
        function [X,ndx,dbg] = natsortfiles(app,X,varargin)
            % Taken from Google, will sort X into correct order, ie 1.1 1.2 1.11 instead of 1.1 1.11 1.12
            
            %% Input Wrangling %%
            assert(iscell(X),'First input <X> must be a cell array.')
            tmp = cellfun('isclass',X,'char') & cellfun('size',X,1)<2 & cellfun('ndims',X)<3;
            assert(all(tmp(:)),'First input <X> must be a cell array of strings (1xN character).')
            
            %% Split and Sort File Names/Paths %%
            % Split full filepaths into file [path,name,extension]:
            [pth,fnm,ext] = cellfun(@fileparts,X(:),'UniformOutput',false);
            % Split path into {dir,subdir,subsubdir,...}:
            pth = regexp(pth,'[^/\\]+','match'); % either / or \ as filesep.
            len = cellfun('length',pth);
            num = max(len);
            vec{numel(len)} = [];
            % Natural-order sort of the file extensions and filenames:
            if nargout<3 % faster:
            	[~,ndx] = natsort(app,ext,varargin{:});
            	[~,ids] = natsort(app,fnm(ndx),varargin{:});
            else % for debugging:
            	[~,ndx,dbg{num+2}] = natsort(app,ext,varargin{:});
            	[~,ids,tmp] = natsort(app,fnm(ndx),varargin{:});
            	[~,idd] = sort(ndx);
            	dbg{num+1} = tmp(idd,:);
            end
            ndx = ndx(ids);
            % Natural-order sort of the directory names:
            for k = num:-1:1
            	idx = len>=k;
            	vec(:) = {''};
            	vec(idx) = cellfun(@(c)c(k),pth(idx));
            	if nargout<3 % faster:
            		[~,ids] = natsort(app,vec(ndx),varargin{:});
            	else % for debugging:
            		[~,ids,tmp] = natsort(app,vec(ndx),varargin{:});
            		[~,idd] = sort(ndx);
            		dbg{k} = tmp(idd,:);
            	end
            	ndx = ndx(ids);
            end
            % Return the sorted array and indices:
            ndx = reshape(ndx,size(X));
            X = X(ndx);
        end
        
        function [X,ndx,dbg] = natsort(app,X,xpr,varargin) % used in natsortfiles
            assert(iscell(X),'First input <X> must be a cell array.')
            tmp = cellfun('isclass',X,'char') & cellfun('size',X,1)<2 & cellfun('ndims',X)<3;
            assert(all(tmp(:)),'First input <X> must be a cell array of char row vectors (1xN char).')
            % Regular expression:
            if nargin<3 || isnumeric(xpr)&&isempty(xpr)
            	xpr = '\d+';
            else
            	assert(ischar(xpr)&&isrow(xpr),'Second input <xpr> must be a regular expression (char row vector).')
            end
            % Optional arguments:
            tmp = cellfun('isclass',varargin,'char') & 1==cellfun('size',varargin,1) & 2==cellfun('ndims',varargin);
            assert(all(tmp(:)),'All optional arguments must be char row vectors (1xN char).')
            % Character case matching:
            ChrM = strcmpi(varargin,'matchcase');
            ChrX = strcmpi(varargin,'ignorecase')|ChrM;
            % Sort direction:
            DrnD = strcmpi(varargin,'descend');
            DrnX = strcmpi(varargin,'ascend')|DrnD;
            % Relative sort-order of numbers compared to characters:
            RsoB = strcmpi(varargin,'beforechar');
            RsoA = strcmpi(varargin,'afterchar');
            RsoX = strcmpi(varargin,'asdigit')|RsoB|RsoA;
            % SSCANF conversion format:
            FmtX = ~(ChrX|DrnX|RsoX);
            %
            if nnz(FmtX)>1
            	tmp = sprintf(', ''%s''',varargin{FmtX});
            	error('Overspecified optional arguments:%s.',tmp(2:end))
            end
            if nnz(DrnX)>1
            	tmp = sprintf(', ''%s''',varargin{DrnX});
            	error('Sort direction is overspecified:%s.',tmp(2:end))
            end
            if nnz(RsoX)>1
            	tmp = sprintf(', ''%s''',varargin{RsoX});
            	error('Relative sort-order is overspecified:%s.',tmp(2:end))
            end
            %% Split Strings %%
            % Split strings into number and remaining substrings:
            [MtS,MtE,MtC,SpC] = regexpi(X(:),xpr,'start','end','match','split',varargin{ChrX});
            % Determine lengths:
            MtcD = cellfun(@minus,MtE,MtS,'UniformOutput',false);
            LenZ = cellfun('length',X(:))-cellfun(@sum,MtcD);
            LenY = max(LenZ);
            LenX = numel(MtC);
            dbg = cell(LenX,LenY);
            NuI = false(LenX,LenY);
            ChI = false(LenX,LenY);
            ChA = char(double(ChI));
            ndx = 1:LenX;
            for k = ndx(LenZ>0)
            	% Determine indices of numbers and characters:
            	ChI(k,1:LenZ(k)) = true;
            	if ~isempty(MtS{k})
            		tmp = MtE{k} - cumsum(MtcD{k});
            		dbg(k,tmp) = MtC{k};
            		NuI(k,tmp) = true;
            		ChI(k,tmp) = false;
            	end
            	% Transfer characters into char array:
            	if any(ChI(k,:))
            		tmp = SpC{k};
            		ChA(k,ChI(k,:)) = [tmp{:}];
            	end
            end
            %% Convert Number Substrings %%
            if nnz(FmtX) % One format specifier
            	fmt = varargin{FmtX};
            	err = ['The supplied format results in an empty output from sscanf: ''',fmt,''''];
            	pct = '(?<!%)(%%)*%'; % match an odd number of % characters.
            	[T,S] = regexp(fmt,[pct,'(\d*)([bdiuoxfeg]|l[diuox])'],'tokens','split');
            	assert(isscalar(T),'Unsupported optional argument: ''%s''',fmt)
            	assert(isempty(T{1}{2}),'Format specifier cannot include field-width: ''%s''',fmt)
            	switch T{1}{3}(1)
            		case 'b' % binary
            			fmt = regexprep(fmt,[pct,'(\*?)b'],'$1%$2[01]');
            			val = dbg(NuI);
            			if numel(S{1})<2 || ~strcmpi('0B',S{1}(end-1:end))
            				% Remove '0B' if not specified in the format string:
            				val = regexprep(val,'(0B)?([01]+)','$2','ignorecase');
            			end
            			val = cellfun(@(s)sscanf(s,fmt),val, 'UniformOutput',false);
            			assert(~any(cellfun('isempty',val)),err)
            			NuA(NuI) = cellfun(@(s)sum(pow2(s-'0',numel(s)-1:-1:0)),val);
            		case 'l' % 64-bit
            			NuA(NuI) = cellfun(@(s)sscanf(s,fmt),dbg(NuI)); %slow!
            		otherwise % double
            			NuA(NuI) = sscanf(sprintf('%s\v',dbg{NuI}),[fmt,'\v']); % fast!
            	end
            else % No format specifier -> double
            	NuA(NuI) = sscanf(sprintf('%s\v',dbg{NuI}),'%f\v');
            end
            % Note: NuA's class is determined by SSCANF or the custom binary parser.
            NuA(~NuI) = 0;
            NuA = reshape(NuA,LenX,LenY);
            %% Debugging Array %%
            if nargout>2
            	dbg(:) = {''};
            	for k = reshape(find(NuI),1,[])
            		dbg{k} = NuA(k);
            	end
            	for k = reshape(find(ChI),1,[])
            		dbg{k} = ChA(k);
            	end
            end
            %% Sort Columns %%
            if ~any(ChrM) % ignorecase
            	ChA = upper(ChA);
            end
            ide = ndx.';
            % From the last column to the first...
            for n = LenY:-1:1
            	% ...sort the characters and number values:
            	[C,idc] = sort(ChA(ndx,n),1,varargin{DrnX});
            	[~,idn] = sort(NuA(ndx,n),1,varargin{DrnX});
            	% ...keep only relevant indices:
            	jdc = ChI(ndx(idc),n); % character
            	jdn = NuI(ndx(idn),n); % number
            	jde = ~ChI(ndx,n)&~NuI(ndx,n); % empty
            	% ...define the sort-order of numbers and characters:
            	jdo = any(RsoA)|(~any(RsoB)&C<'0');
            	% ...then combine these indices in the requested direction:
            	if any(DrnD) % descending
            		idx = [idc(jdc&~jdo);idn(jdn);idc(jdc&jdo);ide(jde)];
            	else % ascending
            		idx = [ide(jde);idc(jdc&jdo);idn(jdn);idc(jdc&~jdo)];
            	end
            	ndx = ndx(idx);
            end
            ndx  = reshape(ndx,size(X));
            X = X(ndx);
        end
        
    end

    methods (Access = private)

        % Button pushed function: OpenRIGButton
        function OpenRIGButtonPushed(app, event)
            % Opens folder selection window
            try
                main = uigetdir('','Open Rig Folder');
                figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
                if ~isequal(main, 0)
                    dirinfo = dir(main);
                    dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                    [path,app.rpath] = deal({});
                    
                    bar = waitbar(0.1,'Loading...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
                    setappdata(bar,'canceling',0);
                    
                    
                    sub = cell(1,length(dirinfo));
                    for i = 1:length(dirinfo)
                        sub{i} = dirinfo(i).name;
                    end
                    nameold = sub;
                    
                    % Iterates over each subfolder
                    for i = 1:length(dirinfo)
                        if app.Allin1folderButton.Value == true % simiar to ESR
                            if contains(dirinfo(i).name,'.csv') % check only take .csv files
                                
                                % Renames file names by replacing . with _ so sorting is consistent in esr/rig
                                dot_index = strfind(sub{i},'.'); 
                                dot_index = dot_index(1:end-1);
                                sub{i}(dot_index) = '_';
                                
                                dot_index = strfind(sub{i},' ');
                                sub{i}(dot_index) = '_';
                                
                                dot_index = strfind(sub{i},'zero');
                                sub{i}(dot_index) = '0';
                                
                                path(end+1) = cellstr(fullfile(main,sub{i}));
                                app.rpath(end+1) = cellstr(fullfile(main,nameold{i}));
                            else
                                continue
                            end
                            
                        elseif app.SubfoldersButton.Value == true % default format, csv files in subfolders, 1 testpoint each
                            % Renames file names by replacing . with _ so sorting is consistent in esr/rig
                            dot_index = strfind(sub{i},'.');
                            sub{i}(dot_index) = '_';
                            
                            space_index = strfind(sub{i},' ');
                            sub{i}(space_index) = '_';
                            
                            zero_index = strfind(sub{i},'zero');
                            sub{i}(zero_index) = '0';
                            
                            % Gets the ith subfolder name
                            subfiles = fullfile(main,nameold{i},'*.csv');
                            finfo = dir(subfiles);
                            if ~dirinfo(i).isdir % checks selected right folder
                                continue
                            elseif isempty(finfo)
                                disp('Subfolder with no .csv files detected, skipped');
                                continue
                            end
                            
                            if length(finfo)>1 % Checks if more than 1 csv in each subfolder, combines multiple csv into first
                                if length(finfo)>5 % unlikely for normal test data to contain this many csv in 1 testpoint
                                    msgbox('Select the correct RIG folder (contains subfolders with .csv in each)','Error','error')
                                    delete(bar)
                                    return
                                end
                                first = gettable(app,fullfile(main,nameold{i},finfo(1).name));
                                rarray = table2array(first);
                                for j = 2:length(finfo) % loop over each csv file in subfolder
                                    rig_next = gettable(app,fullfile(main,nameold{i},finfo(j).name));
                                    rarray = [rarray;table2array(rig_next)];
                                    delete(fullfile(main,nameold{i},finfo(j).name)); % Deletes all but the first, so won't keep on appending tables when operating again
                                end
                                first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                                delete(fullfile(main,nameold{i},finfo(1).name));
                                writetable(first, fullfile(main,nameold{i},finfo(1).name))
                                
                                % Appends file names and paths to array
                                path(end+1) = cellstr(fullfile(main,sub{i},finfo(1).name));
                                app.rpath(end+1) = cellstr(fullfile(main,nameold{i},finfo(1).name));
                                disp([num2str(length(finfo)) ' csv files in ' sub{i} ' have been merged into 1']);
                            else
                                path(end+1) = cellstr(fullfile(main,sub{i},finfo.name));
                                app.rpath(end+1) = cellstr(fullfile(main,nameold{i},finfo.name));
                            end
                        end
                        
                        if getappdata(bar,'canceling')
                            delete(bar)
                            app.sfile = [];
                            disp('Canceled');
                            return
                        end
                        progress = i/length(dirinfo)-0.1;
                        waitbar(progress,bar,['Loading...(' num2str(progress*100,'%.1f') '%)']);
                    end
                    
                    if isempty(app.rpath)
                        msgbox('No data files found, try again','Error','error')
                        delete(bar)
                        return
                    end
    
                    % check equal length
                    if ~isempty(app.epath)
                        if length(app.epath) < length(app.rpath)
                            msgbox(['There are ' num2str(length(app.rpath)-length(app.epath)) ' more RIG files than ESR. Check and try again'],'Error','error');
                            app.error = 1;
                        elseif length(app.epath) > length(app.rpath)
                            msgbox(['There are ' num2str(length(app.epath)-length(app.rpath)) ' more ESR files than RIG. Check and try again'],'Error','error');
                            app.error = 1;
                        else
                            app.error = 0;
                        end
                        disp('Files opened');
                    end
                    
                    [~,I] = natsortfiles(app,path); % gets index of new sorted order
                    app.rpath = app.rpath(I); % sorts old name using index from sorted new name
                    
                    waitbar(0.95,bar,['Loading...(' num2str(0.95*100,'%.1f') '%)']);
                    
                    %Load and select Column headers list box
                    rig = gettable(app,app.rpath{1});
                    app.rig1 = rig;
                    app.rigListBox.Items = sort(rig.Properties.VariableNames);
                    xvars_r = ["Time","Coriolis"];
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.rigListBox.Value = string(app.rigListBox.Items(rlog));
                    
                    % display number of variabled selected
                    app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                    app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                    
                    waitbar(1,bar,'Files Opened!');
                    pause(0.3)
                    delete(bar)
                else
                    disp('User Selected Cancel');
                end
            catch
                msgbox('Wrong file types / formats found. Please check ''help''and try again','Error','error')
            end
        end

        % Button pushed function: PlotESRButton
        function PlotESRButtonPushed(app, event)
            % Check correct inputs
            if isempty(app.epath) && isempty(app.sfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.esrListBox.Value)<2                
                msgbox('Select Time Axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.esrListBox.Value{1},'Time'),1)) && isempty(find(contains(app.esrListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            % Plots selected columns against time column
            figure
            hold on
            xlabel('Time (ms)')
            title('ESR')
            grid           
            for i = 2:length(app.esrListBox.Value)
                plot(app.esr1.(app.esrListBox.Value{1}),app.esr1.(app.esrListBox.Value{i}))
            end
            legend(app.esrListBox.Value(2:end))
        end

        % Button pushed function: PlotRIGButton
        function PlotRIGButtonPushed(app, event)
            if isempty(app.rpath) && isempty(app.sfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            elseif length(app.rigListBox.Value)<2                
                msgbox('Select Time axis AND other axes to plot', 'Error', 'error');
                return
            elseif isempty(find(contains(app.rigListBox.Value{1},'Time'),1)) && isempty(find(contains(app.rigListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            figure
            hold on
            xlabel('Time (ms)')
            title('RIG')
            grid           
            for i = 2:length(app.rigListBox.Value)
                plot(app.rig1.(app.rigListBox.Value{1}),app.rig1.(app.rigListBox.Value{i}))
            end
            legend(app.rigListBox.Value(2:end))
        end

        % Value changed function: TestPointsDropDown
        function TestPointsDropDownValueChanged(app, event)
            % Seletet test point for plotting selected columns
            
            if isempty(app.epath) && isempty(app.rpath)
                msgbox('Open either ESR and RIG data files first','Error','error')
                return
            end
            
            if ~isequal(app.TestPointsDropDown.Value,'Select')
                k = find(ismember(app.TestPointsDropDown.Items,app.TestPointsDropDown.Value)); % Get index of selected point
                
                                             
                if ~isempty(app.epath)
                    % code from open/refresh, changes list box items and auto selects some
                    app.esr1 = gettable(app,app.epath{k});
                    app.esrListBox.Items = sort(app.esr1.Properties.VariableNames);
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    app.esrListBox.Value = string(app.esrListBox.Items(elog));
                end
                if ~isempty(app.rpath)
                    app.rig1 = gettable(app,app.rpath{k});  
                    app.rigListBox.Items = sort(app.rig1.Properties.VariableNames);
                    xvars_r = ["Time","Coriolis"];
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.rigListBox.Value = string(app.rigListBox.Items(rlog));
                end
                
                app.RefreshButton.Enable = 'on'; % for (if) new save summary
                app.error2=1;
                
                % display number of variabled selected
                app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                
            end
        end

        % Button pushed function: SaveSummaryButton
        function SaveSummaryButtonPushed(app, event)
            % Check correct input
            if isempty(app.epath) || isempty(app.rpath)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif app.AverageCheckBox.Value == false && app.MaximumCheckBox.Value == false && app.MinimumCheckBox.Value == false
                msgbox('Select Type of Statistic to Save', 'Error', 'error');
                return
            elseif isempty(app.esrListBox.Value) || isempty(app.rigListBox.Value)
                msgbox('Select columns in the List Box first','Error','error')
                return
            elseif app.error == 1
                msgbox('Different number of files in ESR/RIG','Error','error')
                return
            elseif app.error2 == 1
                msgbox('Refresh Listbox first then try again','Error','error')
                return
            end
            
            % Opens 'save as' window for user to choose file path and name
            [app.sfile , app.spath] = uiputfile({'*.xlsx'},'Save As','summary.xlsx');
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            
            if app.sfile ~=0
                % Makes sure file is writable (not opened)
                if fopen([app.spath app.sfile],'a') == -1
                    msgbox('Close existing flow data window and try again','Error','error');
                    return
                else
                    fclose('all');
                end
                delete([app.spath app.sfile]);
            else
                app.sfile = [];
                return
            end
            
            bar = waitbar(0.01,'Loading...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
            setappdata(bar,'canceling',0);
            
            for i = 1:length(app.epath) % iterate over each test point
                esr = gettable(app,app.epath{i});
                rig = gettable(app,app.rpath{i});
                
                % sorts columns from headers (different order for test points)
                esr = esr(:,sort(esr.Properties.VariableNames));
                rig = rig(:,sort(rig.Properties.VariableNames));
                
                if i == 1
                    we = width(esr); % for checking if testpoint has extra columns 
                    wr = width(rig);
                    elist = length(app.esrListBox.Value); % for checking new selection's lengths equal later
                    rlist = length(app.rigListBox.Value);
                    esr_include = getlog(app,esr.Properties.VariableNames,app.esrListBox.Value); % gets logical array for indexing of columns to include
                    rig_include = getlog(app,rig.Properties.VariableNames,app.rigListBox.Value);
                    
                    % Declare variables into correct size and titles
                    avg = cell(length(app.epath)+1,length(find(esr_include))+length(find(rig_include))+2);
                    avg(1,1) = {'Average'};
                    maxi = cell(length(app.epath)+1,length(find(esr_include))+length(find(rig_include))+2);
                    maxi(1,1) = {'Maximum'};
                    mini = cell(length(app.epath)+1,length(find(esr_include))+length(find(rig_include))+2);
                    mini(1,1) = {'Minimum'};
                
                % if different number of columns in next csv file    
                elseif width(esr) ~= we || width(rig) ~= wr
                    uiwait(msgbox(['Different number of columns in ' app.name{i} ', select columns again'],'Warning','warn'));
                    figure(app.SummaryandPlotsforFlowDataUIFigure);
                    
                    app.esrListBox.Items = esr.Properties.VariableNames; % updates listbox's contents to current file
                    app.rigListBox.Items = rig.Properties.VariableNames;
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","MassFuelFlow","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                    xvars_r = ["Coriolis","Time"];
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.esrListBox.Value = string(app.esrListBox.Items(elog));
                    app.rigListBox.Value = string(app.rigListBox.Items(rlog));
                    
                    msg = msgbox('Select new columns then click OK','Select Columns','modal');
                    frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                    frames(end).setAlwaysOnTop(1);
                    uiwait(msg);
                    
                    while length(app.esrListBox.Value) ~= elist % Checks new selection is compatible (same length)
                        msg = msgbox(['There should be ' num2str(elist) ' columns in ESR (' num2str(length(app.esrListBox.Value)) ' selected). Click OK when corrected'],'Select New');
                        frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                        frames(end).setAlwaysOnTop(1);
                        uiwait(msg);
                    end
                    while length(app.rigListBox.Value) ~= rlist
                        msg = msgbox(['There should be ' num2str(rlist) ' columns in RIG (' num2str(length(app.rigListBox.Value)) ' selected). Click OK when corrected'],'Select New');
                        frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                        frames(end).setAlwaysOnTop(1);
                        uiwait(msg);
                    end
                    figure(bar);
                    
                    esr_include = getlog(app,esr.Properties.VariableNames,app.esrListBox.Value); % new logical array for indexing
                    rig_include = getlog(app,rig.Properties.VariableNames,app.rigListBox.Value);
                    we = width(esr); % new no. of columns
                    wr = width(rig);
                end
                
                esr = esr(:,esr_include);
                rig = rig(:,rig_include);
                
                % average,max,min value calculation for this test point in the iteration, and is added to corresponding matrix
                if app.AverageCheckBox.Value == true         
                    average_esr = varfun(@mean,esr); % single row of mean values
                    average_esr = table2array(average_esr);
                    average_rig = varfun(@mean,rig);
                    average_rig = table2array(average_rig);
                    
                    % add shortened name of file
                    avg(i+1,1) = app.name(i);
                    avg(i+1,2:length(average_esr)+1) = num2cell(average_esr);
                    avg(i+1,end-length(average_rig)+1:end) = num2cell(average_rig);
                else
                    avg = [];
                end
                
                if app.MaximumCheckBox.Value == true                
                    max_esr = varfun(@max,esr);
                    max_esr = table2array(max_esr);
                    max_rig = varfun(@max,rig);
                    max_rig = table2array(max_rig);

                    maxi(i+1,1) = app.name(i);
                    maxi(i+1,2:length(max_esr)+1) = num2cell(max_esr);
                    maxi(i+1,end-length(max_rig)+1:end) = num2cell(max_rig);
                else
                    maxi = [];
                end
                
                if app.MinimumCheckBox.Value == true                    
                    min_esr = varfun(@min,esr);
                    min_esr = table2array(min_esr);
                    min_rig = varfun(@min,rig);
                    min_rig = table2array(min_rig); 
                    
                    mini(i+1,1) = app.name(i);
                    mini(i+1,2:length(min_esr)+1) = num2cell(min_esr);
                    mini(i+1,end-length(min_rig)+1:end) = num2cell(min_rig);
                else
                    mini = [];
                end
                
                if getappdata(bar,'canceling')
                    delete(bar)
                    app.sfile = [];
                    disp('Save Summary Canceled');
                    return
                end
                progress = i/length(app.epath)-0.01;
                waitbar(progress,bar,['Loading...(' num2str(progress*100,'%.1f') '%)']);
            end
            
            % for flow equation calculations use later
            app.eavg = array2table(avg(2:end,2:width(esr)+1),'VariableNames',[esr.Properties.VariableNames]);
            app.ravg = array2table(avg(2:end,end-width(rig)+1:end),'VariableNames',[rig.Properties.VariableNames]);
            
            % combine avg, maxi and mini into main matrix to be exported
            app.matrix=[cell(1,1), esr.Properties.VariableNames,cell(1,1),rig.Properties.VariableNames;
                avg;cell(1,width(esr)+width(rig)+2);maxi;cell(1,width(esr)+width(rig)+2);mini;cell(1,width(esr)+width(rig)+2)];
            
            app.RefreshButton.Enable = 'on'; % for (if) saving new summary
            app.error2 = 1;
            xlswrite([app.spath app.sfile],app.matrix,1)
            disp('Sumamry Saved');
            app.Lamp.Color = [0 1 0];
            waitbar(1,bar,'Summary Saved!');
            pause(0.3)
            delete(bar)
        end

        % Button pushed function: OpenSummaryButton
        function OpenSummaryButtonPushed(app, event)
            % Opens saved summary file
            if isempty(app.sfile)
                msgbox('No summary File Found', 'Error', 'error');
            elseif exist([app.spath app.sfile],'file')
                if app.Lamp.Color == [0 1 0] % Ensures new file is complete (takes 10 seconds)
                    winopen([app.spath app.sfile])
                end
            end
        end

        % Button pushed function: CalculateandPlotFlowButton
        function CalculateandPlotFlowButtonPushed(app, event)
            if isempty(app.eavg) || isempty(app.ravg)
                msgbox('Save/import summary first', 'Error', 'error');
                return
            end
            
            % Different K1 values from user input
            K2 = app.KCGEditField.Value;
            K1 = app.KHLEditField.Value;
            
            % find columns with flow rate equation variables to calculate + plot
            rho  = getcolumn(app,app.ravg,'DensityMeter'); % rig
            if isempty(find(rho, 1))
                rho = getcolumn(app,app.eavg,'FuelDensity');
                msgbox('No density in RIG, used density in ESR instead','Info','help');
            end
            Pout = getcolumn(app,app.eavg,'Outlet'); % esr 
            Pher = getcolumn(app,app.ravg,'HoseEnd'); % rig
            Pl = getcolumn(app,app.ravg,'Latch'); % rig
            Qe = getcolumn(app,app.eavg,'FuelFlow').^2; % esr
            Qr = getcolumn(app,app.ravg,'FuelFlow').^2; % rig
            if size(Qe,2)>1 || size(Qr,2)>1
                msgbox('More than one fuel flows found! Deselect and save summary again (Limit, Mass allowed)','Error','error');
                return
            end
            Phee = getcolumn(app,app.eavg,'HoseEndPressure'); % esr
            
            empty = [isempty(rho),isempty(Pout),isempty(Pher),isempty(Pl),isempty(Qe),isempty(Qr),isempty(Phee)]; % Print which variable missing
            vars = ["Density","Outlet Pressure(Esr)", "Hose End Pressure(Rig)","Latch Pressure(Rig)","Fuel Flow(Esr)","Fuel Flow(Rig)","Hose End Pressure(Esr)"];
            if any(empty)
                msgbox(['Variables Missing in Summary: ' vars(empty)],'Error','error')
                return
            end

            % For later plotting coupling losses use
            app.hose = Pher;
            app.latch = Pl;
            app.Q = Qe;
            
            % Clears axes, calculates specific values in flow equation
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            avgrho = mean(rho);
            Phead = avgrho*9.81*4.21/6894.757; % Pressure gained from catenary (psi)
            
            if isequal(app.PodDropDown.Value,'910')
                K1=zeros(1,length(rho));
                for i = 1:length(rho)
                    K1(i) = app.KHLEditField.Value * rho2c(app,rho(i),sqrt(Qe(i)));
                end
            end
            
            
            dPh = avgrho*app.KHLEditField.Value; % slope of RCU estimation
            dPl = avgrho*(app.KHLEditField.Value+app.KCLEditField.Value);
            
            % change export column header and use latch/hose end pressure
            yl = Pout - Pl + Phead;
            yh = Pout - Pher + Phead; 
            if app.LatchButton.Value == true
                y = yl;
                K1 = K1 + app.KCLEditField.Value; % Include Coupling Loss Factor
                dP = dPl;
            else
                y = yh;
                dP = dPh;
            end
            
            % Change formulae/values if 908/905 (metric/imperial)
            if isequal(app.PodDropDown.Value,'908') || isequal(app.PodDropDown.Value,'908EH')
                Qe = Qe/(60^2); % L/s
                Qr = Qr/(15.85032^2); % L/s
                calPhe = Pout - (K1.*avgrho).*Qe/6.89475 + K2*avgrho/6.894757; % psi
                y = y*6.894757; % kPa
                yh = yh*6.894757;
                yl = yl*6.894757;
                unit = 'SI';
            elseif isequal(app.PodDropDown.Value,'905') || isequal(app.PodDropDown.Value,'912')
                calPhe = Pout - (K1.*avgrho/119.8).*Qe + K2*avgrho/119.8; % psi
                avgrho = avgrho / 119.8;
                unit = 'Imperial';
            end  
            
            % set dimension for export flow data dimensions
            if length(rho) <= 4
                app.matrix2 = cell(6,21);
            else
                app.matrix2 = cell(length(rho)+1,21);
            end
            
            
            app.matrix2(1,1) = {['Units: ' unit]};
            app.matrix2(2:3,1:2) = [{'avg. rho'},{'P(catenary)'};num2cell(avgrho),num2cell(Phead)];
            app.matrix2(5:6,1:3) = [{'K(HL):'},{'K(CL):'},{'K(CG):'};num2cell(app.KHLEditField.Value),num2cell(app.KCLEditField.Value),num2cell(app.KCGEditField.Value)];
            app.matrix2(2,5:15) = [{'Q^2 esr'},{'Q^2 rig'},{'P(OUT)-P(HE) corrected for catenary'},{'P(OUT)-P(L) corrected for catenary'},{'Coupling Loss'},{''},{'Q^2 (RCU)'},{'dP HE (RCU)'},{'dP L (RCU)'},{'Calculated P(HE)'},{'% Difference'}];
            app.matrix2(3:length(rho)+2,5:9) = num2cell([Qe,Qr,yh,yl,Pher-Pl]);
            app.matrix2(3:4,11:13) = num2cell([[0;max(Qe)],[0;max(Qe)*dPh],[0;max(Qe)*dPl]]);
            app.matrix2(3:length(rho)+2,14:15) = num2cell([calPhe,100*(calPhe-Phee)./Phee]);
            
            
            % for later export use (flow calculation data)
%             app.matrix2 = [{'avg. rho'},avgrho,cell(1,length(rho)-1);{'P(catenary)'},Phead,cell(1,length(rho)-1);cell(1,length(rho)+1); {'Q^2 esr'},num2cell(Qe');{'Q^2 rig'},num2cell(Qr');
%                 {'P(OUT)-P(HE) corrected for catenary'},num2cell(yh');{'P(OUT)-P(L) corrected for catenary'},num2cell(yl');
%                 {'Q^2 (RCU)'},num2cell(max(Qe)),cell(1,length(rho)-1);{'dP HE (RCU)'},num2cell(dPh*max(Qe)),cell(1,length(rho)-1);{'dP L (RCU)'},num2cell(dPl*max(Qe)),cell(1,length(rho)-1);cell(1,length(rho)+1);{'Calculated P(HE)'},num2cell(calPhe');{'% Difference'},num2cell(100*(calPhe'-Phee')./Phee')];
%             app.matrix2 = [[{['Units: ' unit]};cell(1,1);{'K(HL):'}; num2cell(app.KHLEditField.Value);{'K(CL):'}; num2cell(app.KCLEditField.Value);{'K(CG):'}; num2cell(app.KCGEditField.Value);cell(5,1)],app.matrix2];
            
            % If exclude chosen, changes arrays to shorter version, excluding data if Hose End Pressure > 50
            include = Pher <= 50;
            if isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{2})
                avgrho = mean(rho(include));
                y = y(include);
                Qe = Qe(include);
                Qr = Qr(include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('All points excluded','Warning','warn');
                    return
                end
            elseif isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{3})
                avgrho = mean(rho(~include));
                y = y(~include);
                Qe = Qe(~include);
                Qr = Qr(~include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('No points excluded','Warning','warn');
                    return
                end
            end          
            
            % plot in preview axes
            m1 = Qe\y; % Slope of y = mx
            app.KlossEditField.Value = m1/avgrho;
            plot(app.UIAxes,Qe,y, 'or')
            plot(app.UIAxes,[0;Qe],[0;Qe*m1],'b')
            plot(app.UIAxes,[0,max(Qe)],[0,dP*max(Qe)],'g')
            legend(app.UIAxes,'Data points','Best Fit through 0','RCU estimation','Location','northwest') 
            m2 = Qr\y; % Slope of y = mx
            app.SlopeEditField_2.Value = m2/avgrho;
            plot(app.UIAxes_2,Qr,y,'or')
            plot(app.UIAxes_2,[0;Qr],[0;Qr*m2],'b')
            plot(app.UIAxes_2,[0,max(Qr)],[0,dP*max(Qr)],'g')
            
            meh = Qe\yh;
            mrh = Qr\yh;
            mel = Qe\yl;
            mrl = Qr\yl;
            
            % for later export use (flow calculation data)
            app.matrix2(2,17:21) = [{'slope esr HE'},{'slope rig HE'},{''},{'slope esr L'},{'slope rig L'}];
            app.matrix2(3,17:21) = [num2cell([meh,mrh]),cell(1,1),num2cell([mel,mrl])];
            app.matrix2(4,17:21) = [{'Kloss esr HE'},{'Kloss rig HE'},{''},{'Kloss esr L'},{'Kloss rig L'}];
            app.matrix2(5,17:21) = [num2cell([meh/avgrho,mrh/avgrho]),cell(1,1),num2cell([mel/avgrho,mrl/avgrho])];  
            
            disp('Flow Data Calculated and Plotted');
        end

        % Button pushed function: ExportFlowDataButton
        function ExportFlowDataButtonPushed(app, event)
            % Exports useful data generated in flow equation calculations including dP, check on P(HE), slope, K loss etc
            
            if isempty(app.epath) && isempty(app.sfile)
                msgbox('Open Files / Import or Save Summary First', 'Error', 'error');
                return
            end            
            if isempty(app.matrix2)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Appends new data to summary or make new excel file 
            if app.ToSummaryButton.Value == true
                % Makes sure file is writable (not opened)
                if fopen([app.spath app.sfile],'a') == -1
                    msgbox('Close existing flow data window and try again','Error','error');
                    return
                else
                    fclose('all');
                end
                xlswrite([app.spath app.sfile],app.matrix2,1,[char(size(app.matrix,2)+66) , '1'])
                app.Lamp_2.Color = [0 1 0];
                disp('Flow Data Saved to Summary');
                
            elseif app.AsNewButton.Value == true             
                [app.dfile , app.dpath] = uiputfile({'*.xlsx'},'Save As','flow_data.xlsx');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if app.dfile ~=0
                    if fopen([app.dpath app.dfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    delete([app.dpath app.dfile]);
                    xlswrite([app.dpath app.dfile],app.matrix2)
                    app.Lamp_2.Color = [0 1 0];
                    disp('Flow Data Saved');
                else
                    app.dfile = [];
                end
            elseif app.SummaryCopyButton.Value == true
                [app.dfile , app.dpath] = uiputfile({'*.xlsx'},'Save As','Summary_flow.xlsx');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if isequal([app.dfile app.dpath],[app.sfile app.spath]) % error if summary copy same file
                    msgbox('Cannot copy to itself. Select ''To Summary'' instead','Error','error')
                    return
                end
                if app.dfile ~=0
                    if fopen([app.dpath app.dfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    delete([app.dpath app.dfile]);
                    copyfile([app.spath app.sfile],[app.dpath app.dfile]) % copy summary to new file then performs same functions as 'to summary'
                    xlswrite([app.dpath app.dfile],app.matrix2,1,[char(size(app.matrix,2)+66) , '1'])
                    app.Lamp_2.Color = [0 1 0];
                    disp('Flow Data Saved to Summary Copy');
                else
                    app.dfile = [];
                end
            end    
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Opens Flow Data (either summary or new)
            if app.ToSummaryButton.Value == true
                if exist([app.spath app.sfile],'file')
                    winopen([app.spath app.sfile])
                else
                    msgbox('No summary File Found', 'Error', 'error');
                end
            else
                if exist([app.dpath app.dfile],'file')
                    winopen([app.dpath app.dfile])
                else
                    msgbox('No Data File Found', 'Error', 'error');
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            % Changes y axes labels for Hose End / Latch Pressure
            if app.HoseEndButton.Value == true
                if isequal(app.PodDropDown.Value,'905') || isequal(app.PodDropDown.Value,'912')
                    ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)');
                elseif isequal(app.PodDropDown.Value,'908') || isequal(app.PodDropDown.Value,'908EH')
                    ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (kPa)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (kPa)');
                end
                app.KCLEditField.Enable = 'off';
                app.CouplingRegulationKnob.Enable = 'off';
            else
                if isequal(app.PodDropDown.Value,'905') || isequal(app.PodDropDown.Value,'912')
                    ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (psi)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (psi)');
                elseif isequal(app.PodDropDown.Value,'908') || isequal(app.PodDropDown.Value,'908EH')
                    ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (kPa)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (kPa)');
                end
                app.KCLEditField.Enable = 'on';
                app.CouplingRegulationKnob.Enable = 'on';
            end            
        end

        % Button pushed function: PlotCouplingLossButton
        function PlotCouplingLossButtonPushed(app, event)
            if isempty(app.epath) && isempty(app.sfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.hose)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Import and separate variables
            Phe = app.hose;
            Pl = app.latch;
            Q = sqrt(app.Q);
            dP = Phe - Pl;            
            dP_1 = dP(Phe<=50);
            dP_2 = dP(Phe>50);
            Q_1 = Q(Phe<=50);
            Q_2 = Q(Phe>50);
            
            % Plot the 2 sets of data as scattered points and different colours
            hold on
            xlabel('ESR Fuel flow (USgpm)')
            ylabel('MA4 Losses (psi)')
            title('Coupling Pressure Losses')
            grid
            plot(Q_1,dP_1,'ob','MarkerFaceColor', 'b')
            plot(Q_2,dP_2,'or','MarkerFaceColor', 'r')
            legend('Regulators Opened','Regulators Closed','Location','northwest')
        end

        % Button pushed function: OpenESRButton
        function OpenESRButtonPushed(app, event)
            % Opens folder selection window
            try
                main = uigetdir('','Open Esr Folder');
                figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
                if ~isequal(main, 0)
                    dirinfo = dir(main);
                    dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                    [path,app.epath] = deal({});
                    
                    bar = waitbar(0.1,'Loading...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
                    setappdata(bar,'canceling',0);
                    
                    filename = cell(1,length(dirinfo)); % cell array of file names
                    for i = 1:length(dirinfo)
                        filename{i} = dirinfo(i).name;
                    end
                    filenameold = filename; % one is unchanged as it's used in file path, other changed for sorting
                    
                    for i = 1:length(dirinfo)
                        if dirinfo(i).isdir % if item is a folder
                            continue
                        end
                        if app.csvButton.Value == true % check only take .csv files
                            if contains(dirinfo(i).name,'.csv')
                                
                                % Renames file names by replacing . with _ so sorting is consistent in esr/rig
                                dot_index = strfind(filename{i},'.'); 
                                dot_index = dot_index(1:end-1);
                                filename{i}(dot_index) = '_';
                                
                                space_index = strfind(filename{i},' '); 
                                filename{i}(space_index) = '_';
                                
                                zero_index = strfind(filename{i},'zero');
                                filename{i}(zero_index) = '0';
                                
                                path(end+1) = cellstr(fullfile(main,filename{i}));
                                app.epath(end+1) = cellstr(fullfile(main,filenameold{i}));
                            else
                                continue
                            end
                            
                        elseif app.noextensionsButton.Value == true % takes only blank extensions
                            [~,~,ext] = fileparts(fullfile(main,dirinfo(i).name));
                            if isempty(ext) % no extension
                                dot_index = strfind(filename{i},'.'); 
                                filename{i}(dot_index) = '_';
                                
                                space_index = strfind(filename{i},' '); 
                                filename{i}(space_index) = '_';
                                
                                zero_index = strfind(filename{i},'zero');
                                filename{i}(zero_index) = '0';
                                
                                path(end+1) = cellstr(fullfile(main,filename{i}));
                                app.epath(end+1) = cellstr(fullfile(main,filenameold{i}));
                            end
                        end
                        
                        if getappdata(bar,'canceling') % cancel button in waitbar
                            delete(bar)
                            app.sfile = [];
                            disp('Canceled');
                            return
                        end
                        progress = i/length(dirinfo)-0.1;
                        waitbar(progress,bar,['Loading...(' num2str(progress*100,'%.1f') '%)']);
                    end
                    
                    if isempty(app.epath)
                        msgbox('No data files found, try again','Error','error')
                        delete(bar)
                        return
                    end
                    
                    % check equal length
                    if ~isempty(app.rpath)
                        if length(app.epath) < length(app.rpath)
                            msgbox(['There are ' num2str(length(app.rpath)-length(app.epath)) ' more RIG files than ESR. Move extra testpoint csv into one subfolder'],'Error','error');
                            app.error = 1;                        
                        elseif length(app.epath) > length(app.rpath)
                            msgbox(['There are ' num2str(length(app.epath)-length(app.rpath)) ' more ESR files than RIG. Merge same testpoint csv into same spreadsheet'],'Error','error');
                            app.error = 1;
                        else
                            app.error = 0;
                        end
                        disp('Files opened');
                    end
                    
                    [~,I] = natsortfiles(app,path); % gets index of new sorted order
                    app.epath = app.epath(I); % sorts old name using index from sorted new name
                    [~,app.name,~] = cellfun(@fileparts,app.epath, 'UniformOutput',false);
                    app.TestPointsDropDown.Items = app.name;
                    
                    waitbar(0.95,bar,['Loading...(' num2str(0.95*100,'%.1f') '%)']);
                    
                    %Load and select Column headers list box
                    esr = gettable(app,app.epath{1});
                    app.esr1 = esr;
                    app.esrListBox.Items = sort(esr.Properties.VariableNames);
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    app.esrListBox.Value = string(app.esrListBox.Items(elog));
                    
                    % display number of variabled selected
                    app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                    app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                    
                    waitbar(1,bar,'Files Opened!');
                    pause(0.3)
                    delete(bar)
                else
                    disp('User Selected Cancel');
                end
            catch
                msgbox('Wrong file types / formats found. Please check ''help''and try again','Error','error')
            end
        end

        % Button pushed function: ExpandColmunsButton
        function ExpandColmunsButtonPushed(app, event)
            % expands the list box to allow most/all items to be seen at once, also auto selects the searched common column names to save time
            if app.status == 0
                app.esrListBox.Position(4) = app.esrListBox.Position(4) + 450;
                app.esrListBox.Position(2) = app.esrListBox.Position(2) - 450;
                app.rigListBox.Position(4) = app.rigListBox.Position(4) + 120;
                app.rigListBox.Position(2) = app.rigListBox.Position(2) - 120;
                app.status = 1;
                
                
            else % returns box to default size
                app.esrListBox.Position(4) = app.esrListBox.Position(4) - 450;
                app.esrListBox.Position(2) = app.esrListBox.Position(2) + 450;
                app.rigListBox.Position(4) = app.rigListBox.Position(4) - 120;
                app.rigListBox.Position(2) = app.rigListBox.Position(2) + 120;
                app.status = 0;
            end
        end

        % Button pushed function: HelpOnFilesButton
        function HelpOnFilesButtonPushed(app, event)
            msgbox(sprintf(['ESR folder: all data files in 1 folder\n    .csv => can contain other file types but data files must have .csv\n    no extension => can have a mix of blank/.csv but no other file types' ...
                '\n\nRIG folder: all have .csv, ignores other file types\n    subfolders => subfolder for each test point (can have many csv files)\n    all in 1 => same as ESR ''.csv''']) ,'About !','help');
        end

        % Value changed function: PodDropDown
        function PodDropDownValueChanged(app, event)
            % Changes K values (but can still be changed manually)
            if isequal(app.PodDropDown.Value,'905')
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.KHLEditField.Value = 8.12e-5;
                app.KCGEditField.Value = 1.1544;
            elseif isequal(app.PodDropDown.Value,'908')
                xlabel(app.UIAxes, 'Q^2 (L/s)^2');
                xlabel(app.UIAxes_2, 'Q^2 (L/s)^2');
                app.KHLEditField.Value = 9.95e-4;
                app.KCGEditField.Value = 5.68e-2;
                app.KCLEditField.Value = 3.08e-5;
            elseif isequal(app.PodDropDown.Value,'908EH')
                xlabel(app.UIAxes, 'Q^2 (L/s)^2');
                xlabel(app.UIAxes_2, 'Q^2 (L/s)^2');
                app.KHLEditField.Value = 4.294e-3;
                app.KCGEditField.Value = 0;
                app.KCLEditField.Value = 4.69e-5;
            elseif isequal(app.PodDropDown.Value,'910')
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.KHLEditField.Value = 7.78e-4;
                app.KCGEditField.Value = 4.25e-2;
                app.KCLEditField.Value = 4.69e-5;
            elseif isequal(app.PodDropDown.Value,'912')
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.KHLEditField.Value = 8.077e-5;
                app.KCGEditField.Value = 1.11;
            end
            
            % KCL not found for 905 and 912
        end

        % Button pushed function: FlowEquationButton
        function FlowEquationButtonPushed(app, event)
            msgbox(sprintf('910: P(O) - P(HE) + Kcg*rho = (C(RE)*Khl + Kcl)*rho*Q^2\nOthers: P(O) - P(HE) + Kcg*rho = (Khl + Kcl)*rho*Q^2\n(P = Pressure, K = correction factor, rho = density, Q = flow rate)'))
        end

        % Button pushed function: ImportSummaryButton
        function ImportSummaryButtonPushed(app, event)
            % Imports summary excel created using this app, saves time opening files and saving summary, go straight to flow calculations            
            [file,path] = uigetfile({'*xlsx';'*.xls'});
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            try
                if ~isequal(file,0)
                    [~,~,sum_cell] = xlsread(fullfile(path,file)); % uses raw cell array with [NaN] instead of []
                    % quick chec on correct format
                    if ~any(isnan(sum_cell{1,1})) || ~any(isnan(sum_cell{2,2})) || (~isequal(sum_cell{2,1},'Average') && ~isequal(sum_cell{2,1},'Maximum') && ~isequal(sum_cell{2,1},'Minimum'))
                        msgbox('Invalid Summary Format! Must be exported using this app','Error','error')
                        return
                    end
                    % find index of blank column separating esr and rig
                    for i = 2:size(sum_cell,2)
                        if ~exist('idx1','var')
                            if any(isnan(sum_cell{1,i}))
                                idx1 = i;
                            end
                        else
                            if any(isnan(sum_cell{1,i}))
                                idx2 = i;
                                break
                            end
                            
                        end
                    end
                    if ~exist('idx2','var')
                        idx2 = size(sum_cell,2) +1;
                    end
                    % add to global variables
                    app.matrix = sum_cell(:,1:idx2-1); % for export flow data use
                    app.eavg = array2table(sum_cell(3:end,2:idx1-1),'VariableNames',sum_cell(1,2:idx1-1));
                    app.ravg = array2table(sum_cell(3:end,idx1+1:idx2-1),'VariableNames',sum_cell(1,idx1+1:idx2-1));
                    app.esrListBox.Items = sum_cell(1,2:idx1-1);
                    app.rigListBox.Items = sum_cell(1,idx1+1:idx2-1);
                    app.sfile = file;
                    app.spath = path;
                    app.Lamp.Color = [0 1 0]; % for opening button
                    disp('Summary Imported');
                end
            catch % any error returns this msg
                msgbox('Invalid Summary Format! Must be exported using this app','Error','error')
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Reload first testpoint's variables into listbox (state after files opening)
            
            if ~isempty(app.epath)
                esr = gettable(app,app.epath{1});
                app.esrListBox.Items = sort(esr.Properties.VariableNames);
                vars = ["OutletPressure","InletPressure","HoseEndPressure","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState","PressureRequest"]; % Exclude due to dublicated names
                elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                app.esrListBox.Value = string(app.esrListBox.Items(elog));
                app.RefreshButton.Enable = 'off';
            end
            if ~isempty(app.rpath)
                rig = gettable(app,app.rpath{1});
                app.rigListBox.Items = sort(rig.Properties.VariableNames);
                xvars_r = ["Time","Coriolis"];
                rlog = ~contains(app.rigListBox.Items,xvars_r);
                app.rigListBox.Value = string(app.rigListBox.Items(rlog));
                app.RefreshButton.Enable = 'off';
            end
            
            % display number of variabled selected
            app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
            app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
            app.error2 = 0;
            app.TestPointsDropDown.Value = app.TestPointsDropDown.Items(1);
            disp('Listboxes Refreshed');
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Adds .csv extension to all data files selected that have no extension

            [file,path] = uigetfile('*.*','MultiSelect', 'on');
            for i = 1:length(file)
                [~,~,ext] = fileparts(fullfile(path,file{i}));
                if ~isempty(ext)
                    disp([file{i} ' already has an extnsion, skipped']);
                    continue
                end
                movefile([path,file{i}],[path,file{i},'.csv'])
            end
            disp('Extensions added');
        end

        % Value changed function: esrListBox
        function esrListBoxValueChanged(app, event)
            % display number of variabled selected
            app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
            app.RefreshButton.Enable = 'on'; % refresh + re-autoselect variables
            
            % for reordering incorrect filename orders
            if app.reorder == 1 % insert above
                L = app.esrListBox.Items;
                log1 = app.insert_idx; % log index of items to insert
                idx2 = find(log1); % actual index of ^
                idx3 = find(contains(app.esrListBox.Items,app.esrListBox.Value)); % insert above this

                app.esrListBox.Items = {}; % renew listbox
                for i = 1:length(L)
                    if ~any(ismember(idx2,i)) && ~isequal(idx3,i) % it just works 
                        app.esrListBox.Items(end+1) = L(i);
                    elseif isequal(idx3,i)
                        app.esrListBox.Items(end+1:end+length(idx2)) = L(log1);
                        app.esrListBox.Items(end+1) = L(idx3);
                    end
                end

                app.reorder = 0;
                app.InsertAboveButton.Value = 0;
                
            elseif app.reorder == 2 % complete reorder all
                if length(app.esrListBox.Value) ~= 1
                    msgbox('Only select one item as the next item (do not multiselect)','Error','error')
                    return
                end
                if any(contains(app.esrListBox.Value,app.V))
                    msgbox('Select non-highlighted items, reset if mistakes were made','Error','error')
                    return
                end
                app.V(end+1) = app.esrListBox.Value;
                L = app.esrListBox.Items;
                log = contains(app.esrListBox.Items,app.V);
                app.esrListBox.Items = [app.V,L(~log)];
                app.esrListBox.Value = app.V;
            end
        end

        % Value changed function: rigListBox
        function rigListBoxValueChanged(app, event)
             % display number of variabled selected
            app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
            app.RefreshButton.Enable = 'on';
            
            % for reordering incorrect filename orders
            if app.reorder == 1
                L = app.rigListBox.Items;
                log1 = app.insert_idx; % log index of items to insert
                idx2 = find(log1); % actual index of ^
                idx3 = find(contains(app.rigListBox.Items,app.rigListBox.Value)); % insert above this

                app.rigListBox.Items = {}; % renew listbox
                for i = 1:length(L)
                    if ~any(ismember(idx2,i)) && ~isequal(idx3,i) % it just works 
                        app.rigListBox.Items(end+1) = L(i);
                    elseif isequal(idx3,i)
                        app.rigListBox.Items(end+1:end+length(idx2)) = L(log1);
                        app.rigListBox.Items(end+1) = L(idx3);
                    end
                end

                app.reorder = 0;
                app.InsertAboveButton.Value = 0;
            
            elseif app.reorder == 2
                if length(app.rigListBox.Value) ~= 1
                    msgbox('Only select one item as the next item (do not multiselect)','Error','error')
                    return
                end
                if any(contains(app.rigListBox.Value,app.V))
                    msgbox('Select non-highlighted items, reset if mistakes were made','Error','error')
                    return
                end
                app.V(end+1) = app.rigListBox.Value;
                L = app.rigListBox.Items;
                log = contains(app.rigListBox.Items,app.V);
                app.rigListBox.Items = [app.V,L(~log)];
                app.rigListBox.Value = app.V;
            end
            
        end

        % Button pushed function: FileOrderButton
        function FileOrderButtonPushed(app, event)
            % Expands listbox horizontally (side by side), then displays file order, if choosen incorrect, can click to change order
            
            if isempty(app.epath) || isempty(app.rpath)
                msgbox('Open both ESR and RIG files to compare their order','Error','error')
                return
            end
            
            app.summary.Visible = 'off';
            app.flow.Visible = 'off';
            app.RefreshButton.Visible = 'off';
            app.TestPointsDropDown.Visible = 'off';
            app.PlotRIGButton.Visible = 'off';
            app.PlotESRButton.Visible = 'off';
            app.SelectedEsrEditField.Visible = 'off';
            app.SelectedRigEditField.Visible = 'off';
            app.SelectedEditFieldLabel.Visible = 'off';
            app.SelectedEditField_2Label.Visible = 'off';
            
            app.esrListBox.Position(3) = 319;
            app.rigListBox.Position(1) = 331;
            app.rigListBox.Position(3) = 319;
            
            if length(app.epath) >=29
                len = 27;
            else
                len = length(app.epath);
            end
            app.esrListBox.Position(2) = app.esrListBox.Position(2) - len * 20 -10  + app.esrListBox.Position(4);
            app.esrListBox.Position(4) = len * 20 +10; % auto sizes to fit items
            app.rigListBox.Position(2) = app.rigListBox.Position(2) - len * 20 -10 + app.rigListBox.Position(4);
            app.rigListBox.Position(4) = len * 20 +10;
            
            app.esrListBox.Items = app.name;
            [~,rname,~] = cellfun(@fileparts,app.rpath,'UniformOutput',false); % gets rig file names
            app.rigListBox.Items = rname;
            app.esrListBox.Value = {};
            app.rigListBox.Value = {};
            
            app.CorrectButton.Visible = 'on';
            app.IncorrectButton.Visible = 'on';
            
            disp('Showing File Orders');
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            eval(app.EditField.Value)
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            % restore appearance and update file order
            app.esrListBox.Position = [8,475,255,140];
            app.rigListBox.Position = [395,475,255,140];
            app.summary.Visible = 'on';
            app.flow.Visible = 'on';
            app.RefreshButton.Visible = 'on';
            app.TestPointsDropDown.Visible = 'on';
            app.PlotRIGButton.Visible = 'on';
            app.PlotESRButton.Visible = 'on';
            app.SelectedEsrEditField.Visible = 'on';
            app.SelectedRigEditField.Visible = 'on';
            app.SelectedEditFieldLabel.Visible = 'on';
            app.SelectedEditField_2Label.Visible = 'on';
            app.CorrectButton.Visible = 'off';
            app.IncorrectButton.Visible = 'off';
            app.Swap2Button.Visible = 'off';
            app.InsertAboveButton.Visible = 'off';
            app.ReorderAllButton.Visible = 'off';
            app.ResettoDefaultButton.Visible = 'off';
            
            
            for i = 1:length(app.name) % each epath item and listbox item
                [p,~,e] = fileparts(app.epath{i});
                app.epath{i} = fullfile(p,[app.esrListBox.Items{i},e]);
            end
            [~,app.name,~] = cellfun(@fileparts,app.epath, 'UniformOutput',false);
            
            temp = app.rpath;
            for i = 1:length(app.rpath) % each listbox item
                logi = contains(temp,app.rigListBox.Items{i});
                app.rpath{i} = temp{logi};
            end
            
            app.RefreshButtonPushed; % Reload variables into listbox
            disp('File order correct');
        end

        % Button pushed function: IncorrectButton
        function IncorrectButtonPushed(app, event)
            % Shows options to reorder file order
            
            app.Swap2Button.Visible = 'on';
            app.InsertAboveButton.Visible = 'on';
            app.ReorderAllButton.Visible = 'on';
            app.ResettoDefaultButton.Visible = 'on';
        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            % Reset listbox order to original order
            
            app.esrListBox.Items = app.name;
            [~,rname,~] = cellfun(@fileparts,app.rpath,'UniformOutput',false); % gets rig file names
            app.rigListBox.Items = rname;
            disp('File Order Reset to Default');
        end

        % Button pushed function: Swap2Button
        function Swap2ButtonPushed(app, event)
            % ability to click on 2 items to swap

            if length(app.esrListBox.Value) == 2
                L = app.esrListBox.Items;
                idx = find(contains(app.esrListBox.Items,app.esrListBox.Value));
                app.esrListBox.Items = [L(1:idx(1)-1),L(idx(2)),L(idx(1)+1:idx(2)-1),L(idx(1)),L(idx(2)+1:end)]; % reassemble listbox items
            end    
            if length(app.rigListBox.Value) == 2
                L = app.rigListBox.Items;
                idx = find(contains(app.rigListBox.Items,app.rigListBox.Value));
                app.rigListBox.Items = [L(1:idx(1)-1),L(idx(2)),L(idx(1)+1:idx(2)-1),L(idx(1)),L(idx(2)+1:end)];
            end
            if length(app.rigListBox.Value) ~= 2 && length(app.esrListBox.Value) ~= 2
                msgbox('Select 2 items to swap','Error','error')
                return
            end
        end

        % Value changed function: ReorderAllButton
        function ReorderAllButtonValueChanged(app, event)
            % ability to select one more items
            
            % main change's code in esr/rig ListBox
            if app.ReorderAllButton.Value == 1
                app.esrListBox.Value = {};
                app.rigListBox.Value = {};
                app.reorder = 2;
            else
                app.esrListBox.Value = {};
                app.rigListBox.Value = {};
                app.reorder = 0;
                app.V = {};
            end
        end

        % Value changed function: InsertAboveButton
        function InsertAboveButtonValueChanged(app, event)
            % ability to select one more items

            % main change's code in esr/rig ListBox
            if app.InsertAboveButton.Value == 1
                if ~isempty(app.esrListBox.Value) && ~isempty(app.rigListBox.Value)
                    msgbox('Only operate on either ESR or RIG!, deselect one to continue','Error','error')
                    app.InsertAboveButton.Value = 0;
                    return
                end
                
                if ~isempty(app.esrListBox.Value)
                    app.insert_idx = contains(app.esrListBox.Items,app.esrListBox.Value);
                    app.esrListBox.Value = {};
                    app.reorder = 1;
                elseif ~isempty(app.rigListBox.Value)
                    app.insert_idx = contains(app.rigListBox.Items,app.rigListBox.Value);
                    app.rigListBox.Value = {};
                    app.reorder = 1;
                else
                    msgbox('Select 1 or more items to insert','Error','error')
                    return
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryandPlotsforFlowDataUIFigure = uifigure;
            app.SummaryandPlotsforFlowDataUIFigure.Name = 'Summary and Plots for Flow Data';
            app.flow = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.UIAxes = uiaxes(app.flow);
            title(app.UIAxes, 'ESR Fuel Flow')
            xlabel(app.UIAxes, 'Q^2 (USgpm)^2')
            ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.CalculateandPlotFlowButton = uibutton(app.flow, 'push');
            app.CalculateandPlotFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotFlowButtonPushed, true);
            app.CalculateandPlotFlowButton.Tooltip = {'Gets new K1 from slope and compares with RCU''s estimation'};
            app.CalculateandPlotFlowButton.Text = 'Calculate and Plot Flow';
            app.UIAxes_2 = uiaxes(app.flow);
            title(app.UIAxes_2, 'RIG Fuel Flow')
            xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2')
            ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)')
            app.Lamp_2 = uilamp(app.flow);
            app.ButtonGroup_5 = uibuttongroup(app.flow);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.HoseEndButton = uiradiobutton(app.ButtonGroup_5);
            app.HoseEndButton.Text = 'Hose End';
            app.HoseEndButton.Value = true;
            app.LatchButton = uiradiobutton(app.ButtonGroup_5);
            app.LatchButton.Tooltip = {'Exclude coupling in End Pressure'};
            app.LatchButton.Text = 'Latch';
            app.PlotCouplingLossButton = uibutton(app.flow, 'push');
            app.PlotCouplingLossButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCouplingLossButtonPushed, true);
            app.PlotCouplingLossButton.Tooltip = {'P(HE) - P(L) against Flow Rate'; ' indicates regulator opened/closed'};
            app.PlotCouplingLossButton.Text = 'Plot Coupling Loss';
            app.ELabel = uilabel(app.flow);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.flow);
            app.RLabel.Text = 'R';
            app.SlopeEditField_2 = uieditfield(app.flow, 'numeric');
            app.SlopeEditField_2.ValueDisplayFormat = '%.4e';
            app.SlopeEditField_2.Editable = 'off';
            app.KlossEditFieldLabel = uilabel(app.flow);
            app.KlossEditFieldLabel.Text = 'K(loss)';
            app.KlossEditField = uieditfield(app.flow, 'numeric');
            app.KlossEditField.ValueDisplayFormat = '%.4e';
            app.KlossEditField.Editable = 'off';
            app.ExportFlowDataButton = uibutton(app.flow, 'push');
            app.ExportFlowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFlowDataButtonPushed, true);
            app.ExportFlowDataButton.Tooltip = {'Add data to summary or on its own'};
            app.ExportFlowDataButton.Text = 'Export Flow Data';
            app.ButtonGroup_4 = uibuttongroup(app.flow);
            app.ToSummaryButton = uiradiobutton(app.ButtonGroup_4);
            app.ToSummaryButton.Text = 'To Summary';
            app.ToSummaryButton.Value = true;
            app.AsNewButton = uiradiobutton(app.ButtonGroup_4);
            app.AsNewButton.Text = 'As New';
            app.SummaryCopyButton = uiradiobutton(app.ButtonGroup_4);
            app.SummaryCopyButton.Text = 'Summary Copy';
            app.OpenButton = uibutton(app.flow, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open excel of summary or new flow data'};
            app.OpenButton.Text = 'Open';
            app.CouplingRegulationKnobLabel = uilabel(app.flow);
            app.CouplingRegulationKnobLabel.Enable = 'off';
            app.CouplingRegulationKnobLabel.Text = 'Coupling Regulation';
            app.CouplingRegulationKnob = uiknob(app.flow, 'discrete');
            app.CouplingRegulationKnob.Items = {'All Data', 'Remove >50', 'Excluded'};
            app.CouplingRegulationKnob.Enable = 'off';
            app.CouplingRegulationKnob.Tooltip = {'Exclude points if P(HE) > 50 psi'};
            app.CouplingRegulationKnob.Value = 'All Data';
            app.KHLEditFieldLabel = uilabel(app.flow);
            app.KHLEditFieldLabel.Text = {'K(HL)'; ''};
            app.KHLEditField = uieditfield(app.flow, 'numeric');
            app.KHLEditField.Tooltip = {'Hose Loss (K1)'};
            app.KHLEditField.Value = 8.12e-05;
            app.KCGEditFieldLabel = uilabel(app.flow);
            app.KCGEditFieldLabel.Text = 'K(CG)';
            app.KCGEditField = uieditfield(app.flow, 'numeric');
            app.KCGEditField.Tooltip = {'Catenary Gain (K2)'};
            app.KCGEditField.Value = 1.1544;
            app.KCLEditFieldLabel = uilabel(app.flow);
            app.KCLEditFieldLabel.Enable = 'off';
            app.KCLEditFieldLabel.Text = {'K(CL)'; ''};
            app.KCLEditField = uieditfield(app.flow, 'numeric');
            app.KCLEditField.Enable = 'off';
            app.KCLEditField.Tooltip = {'Coupling Loss (Included for Latch)'};
            app.summary = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SaveSummaryButton = uibutton(app.summary, 'push');
            app.SaveSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSummaryButtonPushed, true);
            app.SaveSummaryButton.Tooltip = {'Saves summary of selected columns as .xlsx'; 'Make sure to have same columns when re-selecting'};
            app.SaveSummaryButton.Text = 'Save Summary';
            app.OpenSummaryButton = uibutton(app.summary, 'push');
            app.OpenSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSummaryButtonPushed, true);
            app.OpenSummaryButton.Text = 'Open Summary';
            app.Lamp = uilamp(app.summary);
            app.Lamp.Tooltip = {'Green for successful save'};
            app.ExpandColmunsButton = uibutton(app.summary, 'push');
            app.ExpandColmunsButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandColmunsButtonPushed, true);
            app.ExpandColmunsButton.Tooltip = {'Press again to collapse'};
            app.ExpandColmunsButton.Text = 'Expand Colmuns';
            app.AverageCheckBox = uicheckbox(app.summary);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MaximumCheckBox = uicheckbox(app.summary);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MinimumCheckBox = uicheckbox(app.summary);
            app.MinimumCheckBox.Text = 'Minimum';
            app.ImportSummaryButton = uibutton(app.summary, 'push');
            app.ImportSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSummaryButtonPushed, true);
            app.ImportSummaryButton.Tooltip = {'Import summary created using this app'; ' for flow calculations'};
            app.ImportSummaryButton.Text = 'Import Summary';
            app.top = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.HelpOnFilesButton = uibutton(app.top, 'push');
            app.HelpOnFilesButton.ButtonPushedFcn = createCallbackFcn(app, @HelpOnFilesButtonPushed, true);
            app.HelpOnFilesButton.Tooltip = {'File importng format tips'};
            app.HelpOnFilesButton.Text = 'Help On Files';
            app.PodDropDownLabel = uilabel(app.top);
            app.PodDropDownLabel.Text = 'Pod';
            app.PodDropDown = uidropdown(app.top);
            app.PodDropDown.Items = {'905', '908', '908EH', '910', '912'};
            app.PodDropDown.ValueChangedFcn = createCallbackFcn(app, @PodDropDownValueChanged, true);
            app.PodDropDown.Tooltip = {'905 (imperial)'; '908 (SI)'; 'Affects flow equation calculations'};
            app.PodDropDown.Value = '905';
            app.ButtonGroup_6 = uibuttongroup(app.top);
            app.csvButton = uiradiobutton(app.ButtonGroup_6);
            app.csvButton.Tooltip = {'If contains other file types'; ' but data files must have .csv'};
            app.csvButton.Text = '.csv';
            app.csvButton.Value = true;
            app.noextensionsButton = uiradiobutton(app.ButtonGroup_6);
            app.noextensionsButton.Tooltip = {'If any mix of no extension and .csv'; ' but no other file types'};
            app.noextensionsButton.Text = 'no extensions';
            app.OpenESRButton = uibutton(app.ButtonGroup_6, 'push');
            app.OpenESRButton.ButtonPushedFcn = createCallbackFcn(app, @OpenESRButtonPushed, true);
            app.OpenESRButton.Tooltip = {'ESR folder containing all data'; ' ignores other file types if .csv selected'};
            app.OpenESRButton.Text = 'Open ESR';
            app.AddButton = uibutton(app.ButtonGroup_6, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Tooltip = {'Add .csv to all selected files. Use if folder contains a mix of file types / blank extension.'};
            app.AddButton.Text = 'Add';
            app.ButtonGroup_7 = uibuttongroup(app.top);
            app.SubfoldersButton = uiradiobutton(app.ButtonGroup_7);
            app.SubfoldersButton.Tooltip = {'Default test output format'; ' can contain multiple .csv files in each subfolder'};
            app.SubfoldersButton.Text = 'Subfolders';
            app.SubfoldersButton.Value = true;
            app.Allin1folderButton = uiradiobutton(app.ButtonGroup_7);
            app.Allin1folderButton.Tooltip = {'Similar to ESR folder'};
            app.Allin1folderButton.Text = 'All in 1 folder';
            app.OpenRIGButton = uibutton(app.ButtonGroup_7, 'push');
            app.OpenRIGButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRIGButtonPushed, true);
            app.OpenRIGButton.Tooltip = {'RIG folder containing subfolder for each testpoint (one or more csv files in each)'};
            app.OpenRIGButton.Text = 'Open RIG';
            app.FlowEquationButton = uibutton(app.top, 'push');
            app.FlowEquationButton.ButtonPushedFcn = createCallbackFcn(app, @FlowEquationButtonPushed, true);
            app.FlowEquationButton.Tooltip = {'Flow Rate/Pressure Equation used in bottom section'};
            app.FlowEquationButton.Text = 'Flow Equation';
            app.FileOrderButton = uibutton(app.top, 'push');
            app.FileOrderButton.ButtonPushedFcn = createCallbackFcn(app, @FileOrderButtonPushed, true);
            app.FileOrderButton.Tooltip = {'Displays file orders in listbox'; ' options to rearrange'};
            app.FileOrderButton.Text = 'File Order';
            app.PlotRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotRIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRIGButtonPushed, true);
            app.PlotRIGButton.Tooltip = {'Select x-axis as the first selection'; 'then multiselect one or more y-axes'};
            app.PlotRIGButton.Text = 'Plot RIG';
            app.PlotESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotESRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotESRButtonPushed, true);
            app.PlotESRButton.Tooltip = {'Select x-axis as the first selection'; 'then multiselect one or more y-axes'};
            app.PlotESRButton.Text = 'Plot ESR';
            app.TestPointsDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDownLabel.Text = 'Test Points';
            app.TestPointsDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDown.Items = {'Select'};
            app.TestPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointsDropDownValueChanged, true);
            app.TestPointsDropDown.Tooltip = {'For individual variable plots'};
            app.TestPointsDropDown.Value = 'Select';
            app.RefreshButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Tooltip = {'Refresh both listboxes into the first testpoint for ''Save Summary'''};
            app.RefreshButton.Text = 'Refresh';
            app.SelectedEditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.SelectedEsrEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SelectedEsrEditField.ValueDisplayFormat = '%.0f';
            app.SelectedEsrEditField.Editable = 'off';
            app.SelectedEditField_2Label = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectedEditField_2Label.Text = 'Selected';
            app.SelectedRigEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SelectedRigEditField.ValueDisplayFormat = '%.0f';
            app.SelectedRigEditField.Editable = 'off';
            app.esrListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.esrListBox.Items = {};
            app.esrListBox.Multiselect = 'on';
            app.esrListBox.ValueChangedFcn = createCallbackFcn(app, @esrListBoxValueChanged, true);
            app.esrListBox.Tooltip = {'Hold down Ctrl to multiselect'};
            app.esrListBox.Value = {};
            app.rigListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.rigListBox.Items = {};
            app.rigListBox.Multiselect = 'on';
            app.rigListBox.ValueChangedFcn = createCallbackFcn(app, @rigListBoxValueChanged, true);
            app.rigListBox.Tooltip = {'Hold down Ctrl to multiselect'};
            app.rigListBox.Value = {};
            app.EditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'text');
            app.ExecuteButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Tooltip = {'For Debug use only'; ''};
            app.ExecuteButton.Text = 'Execute';
            app.CorrectButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Visible = 'off';
            app.CorrectButton.Tooltip = {'Redisplays GUI'};
            app.CorrectButton.Text = 'Correct';
            app.IncorrectButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.IncorrectButton.ButtonPushedFcn = createCallbackFcn(app, @IncorrectButtonPushed, true);
            app.IncorrectButton.Visible = 'off';
            app.IncorrectButton.Tooltip = {'Options to rearrange items '};
            app.IncorrectButton.Text = 'Incorrect';
            app.Swap2Button = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.Swap2Button.ButtonPushedFcn = createCallbackFcn(app, @Swap2ButtonPushed, true);
            app.Swap2Button.Visible = 'off';
            app.Swap2Button.Tooltip = {'Multiselect 2 items then press this to swap'};
            app.Swap2Button.Text = 'Swap 2';
            app.ResettoDefaultButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Visible = 'off';
            app.ResettoDefaultButton.Tooltip = {'Return to original order'};
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.ReorderAllButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'state');
            app.ReorderAllButton.ValueChangedFcn = createCallbackFcn(app, @ReorderAllButtonValueChanged, true);
            app.ReorderAllButton.Visible = 'off';
            app.ReorderAllButton.Tooltip = {'Click in correct order the new list'; ' no need to multiselect'};
            app.ReorderAllButton.Text = 'Reorder All';
            app.InsertAboveButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'state');
            app.InsertAboveButton.ValueChangedFcn = createCallbackFcn(app, @InsertAboveButtonValueChanged, true);
            app.InsertAboveButton.Visible = 'off';
            app.InsertAboveButton.Tooltip = {'Select one or more items'; ' press this'; ' then select the item which they are to be placed above of'};
            app.InsertAboveButton.Text = 'Insert Above';
        end
    end

    methods (Access = public)

        % Construct app
        function app = esr_rig_analysis_15

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryandPlotsforFlowDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryandPlotsforFlowDataUIFigure)
        end
    end
end