classdef fuelflow_analysis_5_0_2018b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryandPlotsforFlowDataUIFigure  matlab.ui.Figure
        flow                         matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        CalculateandPlotFlowButton   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        Lamp_2                       matlab.ui.control.Lamp
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        HoseEndButton                matlab.ui.control.RadioButton
        LatchButton                  matlab.ui.control.RadioButton
        PlotCouplingLossButton       matlab.ui.control.Button
        ELabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        SlopeEditField_2             matlab.ui.control.NumericEditField
        KlossEditFieldLabel          matlab.ui.control.Label
        KlossEditField               matlab.ui.control.NumericEditField
        ExportFlowDataButton         matlab.ui.control.Button
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        ToSummaryButton              matlab.ui.control.RadioButton
        AsNewButton                  matlab.ui.control.RadioButton
        SummaryCopyButton            matlab.ui.control.RadioButton
        OpenButton                   matlab.ui.control.Button
        CouplingRegulationKnobLabel  matlab.ui.control.Label
        CouplingRegulationKnob       matlab.ui.control.DiscreteKnob
        KHLEditFieldLabel            matlab.ui.control.Label
        KHLEditField                 matlab.ui.control.NumericEditField
        KCGEditFieldLabel            matlab.ui.control.Label
        KCGEditField                 matlab.ui.control.NumericEditField
        KCLEditFieldLabel            matlab.ui.control.Label
        KCLEditField                 matlab.ui.control.NumericEditField
        AddgraphstoexcelCheckBox     matlab.ui.control.CheckBox
        dHmEditFieldLabel            matlab.ui.control.Label
        dHEditField                  matlab.ui.control.NumericEditField
        HighlightifPHE50CheckBox     matlab.ui.control.CheckBox
        summary                      matlab.ui.container.Panel
        SaveSummaryButton            matlab.ui.control.Button
        OpenSummaryButton            matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        ExpandColmunsButton          matlab.ui.control.Button
        AverageCheckBox              matlab.ui.control.CheckBox
        MaximumCheckBox              matlab.ui.control.CheckBox
        MinimumCheckBox              matlab.ui.control.CheckBox
        ImportSummaryButton          matlab.ui.control.Button
        top                          matlab.ui.container.Panel
        FilesHelpButton              matlab.ui.control.Button
        PodDropDownLabel             matlab.ui.control.Label
        PodDropDown                  matlab.ui.control.DropDown
        ButtonGroup_6                matlab.ui.container.ButtonGroup
        csvButton                    matlab.ui.control.RadioButton
        noextensionsButton           matlab.ui.control.RadioButton
        OpenESRButton                matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        ButtonGroup_7                matlab.ui.container.ButtonGroup
        SubfoldersButton             matlab.ui.control.RadioButton
        Allin1folderButton           matlab.ui.control.RadioButton
        OpenRIGButton                matlab.ui.control.Button
        EquationsButton              matlab.ui.control.Button
        FileOrderButton              matlab.ui.control.Button
        MemoButton                   matlab.ui.control.Button
        PlotRIGButton                matlab.ui.control.Button
        PlotESRButton                matlab.ui.control.Button
        TestPointsDropDownLabel      matlab.ui.control.Label
        TestPointsDropDown           matlab.ui.control.DropDown
        RefreshButton                matlab.ui.control.Button
        SelectedEditFieldLabel       matlab.ui.control.Label
        SelectedEsrEditField         matlab.ui.control.NumericEditField
        SelectedEditField_2Label     matlab.ui.control.Label
        SelectedRigEditField         matlab.ui.control.NumericEditField
        esrListBox                   matlab.ui.control.ListBox
        rigListBox                   matlab.ui.control.ListBox
        EditField                    matlab.ui.control.EditField
        ExecuteButton                matlab.ui.control.Button
        CorrectButton                matlab.ui.control.Button
        IncorrectButton              matlab.ui.control.Button
        Swap2Button                  matlab.ui.control.Button
        ResetDefaultButton           matlab.ui.control.Button
        ReorderAllButton             matlab.ui.control.StateButton
        InsertAboveButton            matlab.ui.control.StateButton
        RemoveButton                 matlab.ui.control.Button
        MergeButton                  matlab.ui.control.Button
    end

    % APP CREATED BY CODY LAU (INTERN) VERSION 5.0 05/09/2019
    %========================================================
    
    
    properties (Access = public)
        
        % GLOBAL VARIABLES
       
        esr1 % for first plots (1 test point)
        rig1
        % file = file names, path = full paths
        epath% esr
        rpath% rig
        sfile % save summary
        spath
        eavg % Fuel equation
        ravg
        dfile % save flow data
        dpath
        matrix
        matrix2
        hose % for coupling loss plot
        latch
        Q
        name
        status = 0 % expand listbox
        error = 0 % open file imbalance esr/rig
        reorder = 0 % reorder listbox file name order
        insert_idx % index for lot to be inseted into
        Ve = {} % listbox Values for reordering all
        Vr = {}
        Excel % create graphs in export in excel
        Workbook
        refresh = 0
        
    end
    
    %==================================================================================================================================================
    
    % ALL FUNCTIONS
    methods (Access = public)
        
        function rig = gettable(app,path)
            % function which opens and returns a table for a given file path
            fid = fopen(path,'r'); %Found there was a ',' at the end of csv variables, so readtable thinks there's +1 variables.
            str = fgetl(fid);
            fclose(fid);
            vars = regexp(str, ',', 'split');
            if isempty(vars{end}) %gets rid of the ',' end term.
                vars = matlab.lang.makeValidName(vars(1:end-1));
                rig = readtable(path, 'readvariablenames', false);
                rig.Properties.VariableNames = vars;
            else
                rig = readtable(path, 'readvariablenames', true);
            end
            if any(contains(rig.Properties.VariableNames,'Var'))
                error('Non-csv file exists in folder, correct and try again (Don''t worry, this is a programmed warning)') % Produce error to terminate all code outside of this function
            end
        end
        
    %==================================================================================================================================================
    
        function col = getcol(app,table,name,xname)
            % gets a column in a table as an array searching by variable name
            % name = array search each item in turn
            % xname = array of names to exclude
            col = [];
            for i = 1:length(name)
                c = contains(table.Properties.VariableNames,name(i));
                if length(find(c)) > 1
                    if nargin == 4
                        for j = 1:length(xname)
                            c = c & ~contains(table.Properties.VariableNames,xname(j));
                        end
                    end
                end
                if length(find(c)) > 1
                    c=find(c);
                    switch length(c)
                        case 2
                            var = questdlg(['Select the correct variable for: ' char(name(1))],'Choose One Variable',table.Properties.VariableNames{c(1)},table.Properties.VariableNames{c(2)},'');
                            if isempty(var);error('Variable Selection Cancelled');end % intentional error when clicking on X, simply try again
                            col = cell2mat(table.(var));
                        case 3
                            var = questdlg(['Select the correct variable for: ' char(name(1))],'Choose One Variable',table.Properties.VariableNames{c(1)},table.Properties.VariableNames{c(2)},table.Properties.VariableNames{c(3)},'');
                            if isempty(var);error('Variable Selection Cancelled');end % intentional error when clicking on X, simply try again
                            col = cel2mat(table.(var));
                        otherwise
                            error(['Too many variable columns found for: ' char(name(1)) ', redo summary and only select one column for this variable']);
                    end
                else
                    col = cell2mat(table{:,c});
                end
                if ~isempty(col)
                    break % ends if this name in name array produces results
                end
            end
        end
       
    %==================================================================================================================================================
    
        function log_array = getlog(app,list,value)
            % gets logical array of columns to include given the list of exact column header names (from all var)
            % ie var = list.Items, list = list.Values
            
            [~,idx] = ismember(list,value);
            log_array = logical(idx);
        end
        
    %==================================================================================================================================================
    
        function C = rho2c(app,density,flowrate)
            % takes density => viscosity => Reynold's number => Reynold's Correction Factor to be used in 910 flow equation
            if density <= 773
                A = [8.59415305976075E-09   -5.59208021888923E-06];
            elseif density <= 832
                A = [2.38402723512495E-15   -9.14947972622544E-12   1.40392422313046E-08    -1.07659438678346E-05   4.12584245862576E-03    -6.32131305709171E-01];	
            elseif density >832
                A = [1.70744624416575E-11   -5.74268841367930E-08   7.24368939505538E-05    -4.06129135030113E-02   8.53963882581498E+00];
            end
            V = polyval(A, density);
            
            Re = 4*0.001*flowrate/(pi*0.05588*V); % K(LtoM) * Hose Diameter * Flow Rate / (Cross Area * Viscosity) as defined in RP910E-75-ES-045_9
            
            if Re <= 5078
                B = [-4.267201030479049e-09    -2.153867836103919e-04   3.112789509388798e+00];
                C = polyval(B,Re);
            elseif Re <= 7.5e4
                B = [6.398531531217704e+01   -4.592807528896194e-01    7.298284194055338e-01];
               	f = @(b,x) b(1).*x.^b(2)+b(3); % Non-integer power (non-discrete polynomial)
                C = f(B,Re);
            elseif Re <= 5e5
                B = [3.207728059299550e+02   -6.305019204696392e-01    8.299952643688508e-01];
                f = @(b,x) b(1).*x.^b(2)+b(3);
                C = f(B,Re);
            elseif Re > 5e5
                B = [-2.135027382370963e-20    9.674970001826801e-14   -1.590283356135035e-07     9.692671422144649e-01];
                C = polyval(B,Re);
            end
        end
        
    %==================================================================================================================================================
    
        function [X,ndx,dbg] = natsortfiles(app,X,varargin)
            % Taken from Google, will sort X into correct order, ie 1.1 1.2 1.11 instead of 1.1 1.11 1.12
            
            %% Input Wrangling %%
            assert(iscell(X),'First input <X> must be a cell array.')
            tmp = cellfun('isclass',X,'char') & cellfun('size',X,1)<2 & cellfun('ndims',X)<3;
            assert(all(tmp(:)),'First input <X> must be a cell array of strings (1xN character).')
            
            %% Split and Sort File Names/Paths %%
            % Split full filepaths into file [path,name,extension]:
            [pth,fnm,ext] = cellfun(@fileparts,X(:),'UniformOutput',false);
            % Split path into {dir,subdir,subsubdir,...}:
            pth = regexp(pth,'[^/\\]+','match'); % either / or \ as filesep.
            len = cellfun('length',pth);
            num = max(len);
            vec{numel(len)} = [];
            % Natural-order sort of the file extensions and filenames:
            if nargout<3 % faster:
            	[~,ndx] = natsort(app,ext,varargin{:});
            	[~,ids] = natsort(app,fnm(ndx),varargin{:});
            else % for debugging:
            	[~,ndx,dbg{num+2}] = natsort(app,ext,varargin{:});
            	[~,ids,tmp] = natsort(app,fnm(ndx),varargin{:});
            	[~,idd] = sort(ndx);
            	dbg{num+1} = tmp(idd,:);
            end
            ndx = ndx(ids);
            % Natural-order sort of the directory names:
            for k = num:-1:1
            	idx = len>=k;
            	vec(:) = {''};
            	vec(idx) = cellfun(@(c)c(k),pth(idx));
            	if nargout<3 % faster:
            		[~,ids] = natsort(app,vec(ndx),varargin{:});
            	else % for debugging:
            		[~,ids,tmp] = natsort(app,vec(ndx),varargin{:});
            		[~,idd] = sort(ndx);
            		dbg{k} = tmp(idd,:);
            	end
            	ndx = ndx(ids);
            end
            % Return the sorted array and indices:
            ndx = reshape(ndx,size(X));
            X = X(ndx);
        end
        
    %==================================================================================================================================================
    
        function [X,ndx,dbg] = natsort(app,X,xpr,varargin) % used in natsortfiles
            assert(iscell(X),'First input <X> must be a cell array.')
            tmp = cellfun('isclass',X,'char') & cellfun('size',X,1)<2 & cellfun('ndims',X)<3;
            assert(all(tmp(:)),'First input <X> must be a cell array of char row vectors (1xN char).')
            % Regular expression:
            if nargin<3 || isnumeric(xpr)&&isempty(xpr)
            	xpr = '\d+';
            else
            	assert(ischar(xpr)&&isrow(xpr),'Second input <xpr> must be a regular expression (char row vector).')
            end
            % Optional arguments:
            tmp = cellfun('isclass',varargin,'char') & 1==cellfun('size',varargin,1) & 2==cellfun('ndims',varargin);
            assert(all(tmp(:)),'All optional arguments must be char row vectors (1xN char).')
            % Character case matching:
            ChrM = strcmpi(varargin,'matchcase');
            ChrX = strcmpi(varargin,'ignorecase')|ChrM;
            % Sort direction:
            DrnD = strcmpi(varargin,'descend');
            DrnX = strcmpi(varargin,'ascend')|DrnD;
            % Relative sort-order of numbers compared to characters:
            RsoB = strcmpi(varargin,'beforechar');
            RsoA = strcmpi(varargin,'afterchar');
            RsoX = strcmpi(varargin,'asdigit')|RsoB|RsoA;
            % SSCANF conversion format:
            FmtX = ~(ChrX|DrnX|RsoX);
            %
            if nnz(FmtX)>1
            	tmp = sprintf(', ''%s''',varargin{FmtX});
            	error('Overspecified optional arguments:%s.',tmp(2:end))
            end
            if nnz(DrnX)>1
            	tmp = sprintf(', ''%s''',varargin{DrnX});
            	error('Sort direction is overspecified:%s.',tmp(2:end))
            end
            if nnz(RsoX)>1
            	tmp = sprintf(', ''%s''',varargin{RsoX});
            	error('Relative sort-order is overspecified:%s.',tmp(2:end))
            end
            %% Split Strings %%
            % Split strings into number and remaining substrings:
            [MtS,MtE,MtC,SpC] = regexpi(X(:),xpr,'start','end','match','split',varargin{ChrX});
            % Determine lengths:
            MtcD = cellfun(@minus,MtE,MtS,'UniformOutput',false);
            LenZ = cellfun('length',X(:))-cellfun(@sum,MtcD);
            LenY = max(LenZ);
            LenX = numel(MtC);
            dbg = cell(LenX,LenY);
            NuI = false(LenX,LenY);
            ChI = false(LenX,LenY);
            ChA = char(double(ChI));
            ndx = 1:LenX;
            for k = ndx(LenZ>0)
            	% Determine indices of numbers and characters:
            	ChI(k,1:LenZ(k)) = true;
            	if ~isempty(MtS{k})
            		tmp = MtE{k} - cumsum(MtcD{k});
            		dbg(k,tmp) = MtC{k};
            		NuI(k,tmp) = true;
            		ChI(k,tmp) = false;
            	end
            	% Transfer characters into char array:
            	if any(ChI(k,:))
            		tmp = SpC{k};
            		ChA(k,ChI(k,:)) = [tmp{:}];
            	end
            end
            %% Convert Number Substrings %%
            if nnz(FmtX) % One format specifier
            	fmt = varargin{FmtX};
            	err = ['The supplied format results in an empty output from sscanf: ''',fmt,''''];
            	pct = '(?<!%)(%%)*%'; % match an odd number of % characters.
            	[T,S] = regexp(fmt,[pct,'(\d*)([bdiuoxfeg]|l[diuox])'],'tokens','split');
            	assert(isscalar(T),'Unsupported optional argument: ''%s''',fmt)
            	assert(isempty(T{1}{2}),'Format specifier cannot include field-width: ''%s''',fmt)
            	switch T{1}{3}(1)
            		case 'b' % binary
            			fmt = regexprep(fmt,[pct,'(\*?)b'],'$1%$2[01]');
            			val = dbg(NuI);
            			if numel(S{1})<2 || ~strcmpi('0B',S{1}(end-1:end))
            				% Remove '0B' if not specified in the format string:
            				val = regexprep(val,'(0B)?([01]+)','$2','ignorecase');
            			end
            			val = cellfun(@(s)sscanf(s,fmt),val, 'UniformOutput',false);
            			assert(~any(cellfun('isempty',val)),err)
            			NuA(NuI) = cellfun(@(s)sum(pow2(s-'0',numel(s)-1:-1:0)),val);
            		case 'l' % 64-bit
            			NuA(NuI) = cellfun(@(s)sscanf(s,fmt),dbg(NuI)); %slow!
            		otherwise % double
            			NuA(NuI) = sscanf(sprintf('%s\v',dbg{NuI}),[fmt,'\v']); % fast!
            	end
            else % No format specifier -> double
            	NuA(NuI) = sscanf(sprintf('%s\v',dbg{NuI}),'%f\v');
            end
            % Note: NuA's class is determined by SSCANF or the custom binary parser.
            NuA(~NuI) = 0;
            NuA = reshape(NuA,LenX,LenY);
            %% Debugging Array %%
            if nargout>2
            	dbg(:) = {''};
            	for k = reshape(find(NuI),1,[])
            		dbg{k} = NuA(k);
            	end
            	for k = reshape(find(ChI),1,[])
            		dbg{k} = ChA(k);
            	end
            end
            %% Sort Columns %%
            if ~any(ChrM) % ignorecase
            	ChA = upper(ChA);
            end
            ide = ndx.';
            % From the last column to the first...
            for n = LenY:-1:1
            	% ...sort the characters and number values:
            	[C,idc] = sort(ChA(ndx,n),1,varargin{DrnX});
            	[~,idn] = sort(NuA(ndx,n),1,varargin{DrnX});
            	% ...keep only relevant indices:
            	jdc = ChI(ndx(idc),n); % character
            	jdn = NuI(ndx(idn),n); % number
            	jde = ~ChI(ndx,n)&~NuI(ndx,n); % empty
            	% ...define the sort-order of numbers and characters:
            	jdo = any(RsoA)|(~any(RsoB)&C<'0');
            	% ...then combine these indices in the requested direction:
            	if any(DrnD) % descending
            		idx = [idc(jdc&~jdo);idn(jdn);idc(jdc&jdo);ide(jde)];
            	else % ascending
            		idx = [ide(jde);idc(jdc&jdo);idn(jdn);idc(jdc&~jdo)];
            	end
            	ndx = ndx(idx);
            end
            ndx  = reshape(ndx,size(X));
            X = X(ndx);
        end
        
    %==================================================================================================================================================    
    
        function addchart(app,chartname,array,series,xname,yname,title,column) 
            % used in export flow data, adds a chart with one or more series 
            % chartname  = something different
            % array = array of column numbers in excel (must be even width due to xy pairs)
            % series = cell array of series names (same length as width of array/2)
            % xname,yname = axes names
            % column = location in excel (integer)
            
            % checks input correct
            if nargin ~= 8
                msgbox('Missing input variables, include: (app,chartname,array,series,xname,yname,title,column)','Error','error')
                return
            elseif rem(length(array),2)
                msgbox('Array must be even length (x-y pairs of data)','Error','error')
                return
            elseif length(series) ~= length(array)/2
                msgbox(['There are ' num2str(length(array)/2) ' pairs of data but only ' num2str(length(series)) ' series names'],'Error','error')
                return
            end
            
%             try

                % VBA (visual basics application) codes
                
                try
                    Chart = app.Excel.ActiveSheet.Shapes.AddChart2; % See https://docs.microsoft.com/en-us/office/vba/api/excel.shapes.addchart2
                catch
                    try
                        Chart = app.Excel.ActiveSheet.Shapes.AddChart;
                    catch
                        app.Workbook.Close
                        error('Error in AddChart (different version of excel), cannot use add chart option *sad*')
                    end
                end
                Chart.Name = chartname;
                
                ExpChart = app.Excel.ActiveSheet.ChartObjects(chartname);
                ExpChart.Activate;
                
                stop = 0;
                while stop == 0 % deletes all series that were automatically added
                    try
                        Series = invoke(app.Excel.ActiveChart,'SeriesCollection',1);
                        invoke(Series,'Delete');
                    catch % will get error if try to delete empty series, so stops at this point
                        stop = 1;
                    end
                end
                
                app.Excel.ActiveChart.ChartType = 'xlXYScatter'; % Chart Types: https://docs.microsoft.com/en-us/office/vba/api/excel.xlcharttype
                sheet = 'Sheet1';
                % add elements to chart, for loop over each series/line
                for i = 1:length(array)/2
                    % prefer to get values from excel so is editable / see which column is plotted
                    % See Series 'Properties' in https://docs.microsoft.com/en-us/office/vba/api/excel.series(object)
                    NewSeries = invoke(app.Excel.ActiveChart.SeriesCollection,'NewSeries');
                    NewSeries.XValues = ['=' sheet '!' xlcolumn(app,array(i*2-1)) '3' ':' xlcolumn(app,array(i*2-1)) int2str(size(app.matrix2,1))];
                    NewSeries.Values  = ['=' sheet '!' xlcolumn(app,array(i*2)) '3' ':' xlcolumn(app,array(i*2)) int2str(size(app.matrix2,1))];
                    NewSeries.Name    = series{i};
                    NewSeries.MarkerSize = 4;
                    NewSeries.MarkerStyle = 'xlMarkerStyleCircle';
                    NewSeries.Format.Line.Visible = 'msoFalse';
                    
                    if length(array) ~= 2
                        NewSeries.MarkerForegroundColorIndex = i+2; % official excel color index, 3 4 5 are red green blue
                        NewSeries.MarkerBackgroundColorIndex = i+2;
                        % See Trendline 'Properties' in https://docs.microsoft.com/en-us/office/vba/api/excel.trendline(object)
                        Trend = NewSeries.Trendlines.Add;
                        Trend.Type = 'xlLinear';
                        Trend.Intercept = 0;
                        Trend.Format.Line.Weight = 2;
                        Trend.Border.ColorIndex = i+2;
                        if isequal(series{i},'ESR') || isequal(series{i},'RIG')
                            Trend.Format.Line.DashStyle = 'msoLineDash';
                        end
                    else
                        NewSeries.MarkerForegroundColorIndex = 5; % blue for coupling loss
                        NewSeries.MarkerBackgroundColorIndex = 5;
                    end
                end
                
                % Set the x-axis
                Axes = invoke(app.Excel.ActiveChart,'Axes',1);
                set(Axes,'HasTitle',1);
                set(Axes.AxisTitle,'Caption',xname)
                % Set the y-axis
                Axes = invoke(app.Excel.ActiveChart,'Axes',2);
                set(Axes,'HasTitle',1);
                set(Axes.AxisTitle,'Caption',yname)
                % Give the Chart a title
                app.Excel.ActiveChart.HasTitle = 1;
                app.Excel.ActiveChart.ChartTitle.Characters.Text = title;
                % Set Legend
                app.Excel.ActiveChart.HasLegend = 1;
                % Set Location and Size 
                Location  =  [xlcolumn(app,column) int2str(size(app.matrix,1)+5)];
                GetPlacement = get(app.Excel.ActiveSheet,'Range', Location);
                ExpChart.Width = 500;
                ExpChart.Height= 300;
                ExpChart.Left  = GetPlacement.Left;
                ExpChart.Top   = GetPlacement.Top;
%             catch
%                 msgbox('Invalid Input Parameters','Error','error')
%             end
                
        end
        
    %==================================================================================================================================================
    
        function loc = xlcolumn(app,column)
            %XLCOLUMN Converts Excel column name to column number and vise versa. 
            % e.g. xlcolumn(5) = 'E'; xlcolumn(28) = 'AB', xlcolumn('C') = 3;
            
            if isnumeric(column)
                if column>256
                    error('Excel is limited to 256 columns! Enter an integer number <257');
                end
                letters = {'A','B','C','D','E','F','G','H','I','J','K','L','M','N','O','P','Q','R','S','T','U','V','W','X','Y','Z'};
                count = 0;
                if column-26<=0
                    loc = char(letters(column));
                else
                    while column-26>0
                        count = count + 1;
                        column = column - 26;
                    end
                    loc = [char(letters(count)) char(letters(column))];
                end
            else
                letters = ['A','B','C','D','E','F','G','H','I','J','K','L','M','N','O','P','Q','R','S','T','U','V','W','X','Y','Z'];
                if size(column,2)==1
                    loc =strfind(column,letters);
                elseif size(column,2)==2
                    loc1 =strfind(column(1),letters);
                    loc2 =strfind(column(2),letters);
                    loc = (26 + 26*loc1)-(26-loc2);
                    if loc>256
                        error('Excel is limited to 256 columns! Enter column number between A & IV');
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenRIGButton
        function OpenRIGButtonPushed(app, event)
            % Opens folder selection window
            try
                main = uigetdir('','Open Rig Folder');
                figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
                if ~isequal(main, 0)
                    dirinfo = dir(main);
                    dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                    [path,app.rpath] = deal({});
                    
                    bar = waitbar(0,'Opening Files...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
                    setappdata(bar,'canceling',0);
                    
                    
                    sub = cell(1,length(dirinfo));
                    for i = 1:length(dirinfo)
                        sub{i} = dirinfo(i).name;
                    end
                    nameold = sub;
                    
                    % Iterates over each subfolder
                    for i = 1:length(dirinfo)
                        if app.Allin1folderButton.Value == true % simiar to ESR
                            if contains(dirinfo(i).name,'.csv') % check only take .csv files
                                
                                % Renames file names by replacing . with _ so sorting is consistent in esr/rig
                                dot_index = strfind(sub{i},'.'); 
%                                 dot_index = dot_index(1:end-1);
                                sub{i}(dot_index) = '_';
                                
                                dot_index = strfind(sub{i},' ');
                                sub{i}(dot_index) = '_';
                                
                                dot_index = strfind(sub{i},'zero');
                                sub{i}(dot_index) = '0';
                                
                                path(end+1) = cellstr(fullfile(main,sub{i}));
                                app.rpath(end+1) = cellstr(fullfile(main,nameold{i}));
                            else
                                continue
                            end
                            
                        elseif app.SubfoldersButton.Value == true % default format, csv files in subfolders, 1 testpoint each
                            % Renames file names by replacing . with _ so sorting is consistent in esr/rig
                            dot_index = strfind(sub{i},'.');
                            sub{i}(dot_index) = '_';
                            
                            space_index = strfind(sub{i},' ');
                            sub{i}(space_index) = '_';
                            
                            zero_index = strfind(sub{i},'zero');
                            sub{i}(zero_index) = '0';
                            
                            % Gets the ith subfolder name
                            subfiles = fullfile(main,nameold{i},'*.csv');
                            finfo = dir(subfiles);
                            if ~dirinfo(i).isdir % checks selected right folder
                                continue
                            elseif isempty(finfo)
                                disp('Subfolder with no .csv files detected, skipped');
                                continue
                            end
                            
                            if length(finfo)>1 % Checks if more than 1 csv in each subfolder, combines multiple csv into first
                                if length(finfo)>5 % unlikely for normal test data to contain this many csv in 1 testpoint
                                    msgbox('Select the correct RIG folder (contains subfolders with .csv in each)','Error','error')
                                    delete(bar)
                                    return
                                end
                                first = gettable(app,fullfile(main,nameold{i},finfo(1).name));
                                rarray = table2array(first);
                                for j = 2:length(finfo) % loop over each csv file in subfolder
                                    rig_next = gettable(app,fullfile(main,nameold{i},finfo(j).name));
                                    rarray = [rarray;table2array(rig_next)];
                                    delete(fullfile(main,nameold{i},finfo(j).name)); % Deletes all but the first, so won't keep on appending tables when operating again
                                end
                                first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                                delete(fullfile(main,nameold{i},finfo(1).name));
                                writetable(first, fullfile(main,nameold{i},finfo(1).name))
                                
                                % Appends file names and paths to array
                                path(end+1) = cellstr(fullfile(main,sub{i},finfo(1).name));
                                app.rpath(end+1) = cellstr(fullfile(main,nameold{i},finfo(1).name));
                                disp([num2str(length(finfo)) ' csv files in ' sub{i} ' have been merged into 1']);
                            else
                                path(end+1) = cellstr(fullfile(main,sub{i},finfo.name));
                                app.rpath(end+1) = cellstr(fullfile(main,nameold{i},finfo.name));
                            end
                        end
                        
                        if getappdata(bar,'canceling')
                            delete(bar)
                            app.sfile = [];
                            disp('Canceled');
                            return
                        end
                        progress = 0.95*i/length(dirinfo);
                        waitbar(progress,bar,['Opening Files...(' num2str(progress*100,'%.1f') '%)']);
                    end
                    
                    if isempty(app.rpath)
                        msgbox('No data files found, try again','Error','error')
                        delete(bar)
                        return
                    end
    
                    % check equal length
                    if ~isempty(app.epath)
                        if length(app.epath) < length(app.rpath)
                            msgbox(['There are ' num2str(length(app.rpath)-length(app.epath)) ' more RIG files than ESR. Check and try again'],'Error','error');
                            app.error = 1;
                        elseif length(app.epath) > length(app.rpath)
                            msgbox(['There are ' num2str(length(app.epath)-length(app.rpath)) ' more ESR files than RIG. Check and try again'],'Error','error');
                            app.error = 1;
                        else
                            app.error = 0;
                        end
                        disp('Both ESR/RIG Files opened');
                    end
                    
                    [~,I] = natsort(app,path); % gets index of new sorted order
                    app.rpath = app.rpath(I); % sorts old name using index from sorted new name
                    
                    waitbar(0.99,bar,'Opening Files...(99%)');
                    
                    %Load and select Column headers list box
                    rig = gettable(app,app.rpath{1});
                    app.rig1 = rig;
                    app.rigListBox.Items = sort(rig.Properties.VariableNames);
                    xvars_r = ["Time","Coriolis"];
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.rigListBox.Value = app.rigListBox.Items(rlog);
                    
                    % display number of variabled selected
                    app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                    app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                    
                    waitbar(1,bar,'Files Opened!');
                    delete(bar)
                else
                    disp('User Selected Cancel');
                end
                figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            catch
                delete(bar)
                msgbox('Wrong file types / formats found. Please check ''help''and try again','Error','error')
            end
        end

        % Button pushed function: PlotESRButton
        function PlotESRButtonPushed(app, event)
            % Check correct inputs
            if isempty(app.epath) && isempty(app.sfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            end
            if length(app.esrListBox.Value)<2                
                msgbox('Select Time Axis AND other axes to plot', 'Error', 'error');
                return
            end
            if isempty(find(contains(app.esrListBox.Value{1},'Time'),1)) && isempty(find(contains(app.esrListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            % Plots selected columns against time column
            figure
            hold on
            xlabel('Time (ms)')
            title('ESR')
            grid           
            for i = 2:length(app.esrListBox.Value)
                plot(app.esr1.(app.esrListBox.Value{1}),app.esr1.(app.esrListBox.Value{i}))
            end
            legend(app.esrListBox.Value(2:end))
            
            disp('Figure plotted');
        end

        % Button pushed function: PlotRIGButton
        function PlotRIGButtonPushed(app, event)
            if isempty(app.rpath) && isempty(app.sfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif isempty(app.TestPointsDropDown.Value) || isequal(app.TestPointsDropDown.Value,'Select')
                msgbox('Select Test Point First', 'Error', 'error');
                return
            elseif length(app.rigListBox.Value)<2                
                msgbox('Select Time axis AND other axes to plot', 'Error', 'error');
                return
            elseif isempty(find(contains(app.rigListBox.Value{1},'Time'),1)) && isempty(find(contains(app.rigListBox.Value{1},'Tick'),1))
                msgbox('Include Time Axis as First Selection', 'Error', 'error');
                return
            end
            
            figure
            hold on
            xlabel('Time (ms)')
            title('RIG')
            grid           
            for i = 2:length(app.rigListBox.Value)
                plot(app.rig1.(app.rigListBox.Value{1}),app.rig1.(app.rigListBox.Value{i}))
            end
            legend(app.rigListBox.Value(2:end))
            
            disp('Figure plotted');
        end

        % Value changed function: TestPointsDropDown
        function TestPointsDropDownValueChanged(app, event)
            % Seletet test point for plotting selected columns
            
            if isempty(app.epath) && isempty(app.rpath)
                msgbox('Open either ESR and RIG data files first','Error','error')
                return
            end
            
            if ~isequal(app.TestPointsDropDown.Value,'Select')
                k = find(ismember(app.TestPointsDropDown.Items,app.TestPointsDropDown.Value)); % Get index of selected point
                
                                             
                if ~isempty(app.epath)
                    % code from open/refresh, changes list box items and auto selects some
                    app.esr1 = gettable(app,app.epath{k});
                    app.esrListBox.Items = sort(app.esr1.Properties.VariableNames);
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    app.esrListBox.Value = app.esrListBox.Items(elog);
                end
                if ~isempty(app.rpath)
                    app.rig1 = gettable(app,app.rpath{k});  
                    app.rigListBox.Items = sort(app.rig1.Properties.VariableNames);
                    xvars_r = ["Time","Coriolis"];
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.rigListBox.Value = app.rigListBox.Items(rlog);
                end
                
                app.RefreshButton.Enable = 'on'; % for (if) new save summary
                
                % display number of variabled selected
                app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                
                app.refresh = 1;
            end
        end

        % Button pushed function: SaveSummaryButton
        function SaveSummaryButtonPushed(app, event)
            % Check correct input
            if isempty(app.epath) || isempty(app.rpath)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            elseif app.AverageCheckBox.Value == false && app.MaximumCheckBox.Value == false && app.MinimumCheckBox.Value == false
                msgbox('Select Type of Statistic to Save', 'Error', 'error');
                return
            elseif isempty(app.esrListBox.Value) || isempty(app.rigListBox.Value)
                msgbox('Select columns in the List Box first','Error','error')
                return
            elseif app.error == 1
                msgbox('Different number of files in ESR/RIG','Error','error')
                return
            elseif app.refresh == 1
                msgbox('Press the Refresh button first','Error','error')
                return
            end

            
            % Opens 'save as' window for user to choose file path and name
            [app.sfile , app.spath] = uiputfile({'*.xlsx'},'Save As',[app.PodDropDown.Value '_summary' '.xlsx']);
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            
            if app.sfile ~=0
                % Makes sure file is writable (not opened)
                if fopen([app.spath app.sfile],'a') == -1
                    msgbox('Close existing flow data window and try again','Error','error');
                    return
                else
                    fclose('all');
                end
                delete([app.spath app.sfile]);
            else
                app.sfile = [];
                return
            end
            
            bar = waitbar(0.01,'Creating Summary...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
            setappdata(bar,'canceling',0);
            
            for i = 1:length(app.epath) % iterate over each test point
                try % would get error if empty columns or weird formatted csv
                    which = 'ESR => ';
                    esr = gettable(app,app.epath{i});
                    which = 'RIG => ';
                    rig = gettable(app,app.rpath{i});
                catch
                    a = questdlg(['Non test data csv or empty columns exist in: ' which app.name{i}],'Action','Cancel Save','Skip Testpoint','');
                    switch a
                        case 'Cancel Save'
                            app.refresh = 1;
                            delete(bar)
                            return
                        case 'Skip Testpoint'
                            continue
                    end
                end
                
                % sorts columns from headers (different order for test points)
                esr = esr(:,sort(esr.Properties.VariableNames));
                rig = rig(:,sort(rig.Properties.VariableNames));
                
                if i == 1
                    we = width(esr); % for checking if testpoint has extra columns 
                    wr = width(rig);
                    ve = app.esrListBox.Value; % for displaying previously selected items if diff. amount selected later
                    vr = app.rigListBox.Value;
                    esr_include = getlog(app,esr.Properties.VariableNames,app.esrListBox.Value); % gets logical array for indexing of columns to include
                    rig_include = getlog(app,rig.Properties.VariableNames,app.rigListBox.Value);
                    
                    % Declare variables into correct size and titles
                    avg = cell(length(app.epath)+1,length(find(esr_include))+length(find(rig_include))+2);
                    avg(1,1) = {'Average'};
                    maxi = cell(length(app.epath)+1,length(find(esr_include))+length(find(rig_include))+2);
                    maxi(1,1) = {'Maximum'};
                    mini = cell(length(app.epath)+1,length(find(esr_include))+length(find(rig_include))+2);
                    mini(1,1) = {'Minimum'};
                
                % if different number of columns in next csv file    
                elseif width(esr) ~= we || width(rig) ~= wr
                    uiwait(msgbox(['Different number of columns in ' app.name{i} ', select columns again'],'Warning','warn'));
                    figure(app.SummaryandPlotsforFlowDataUIFigure);
                    
                    app.esrListBox.Items = esr.Properties.VariableNames; % updates listbox's contents to current file
                    app.rigListBox.Items = rig.Properties.VariableNames;
                    app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                    app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","MassFuelFlow","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState","Request"]; % Exclude due to dublicated names
                    xvars_r = ["Coriolis","Time"];
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    rlog = ~contains(app.rigListBox.Items,xvars_r);
                    app.esrListBox.Value = app.esrListBox.Items(elog);
                    app.rigListBox.Value = app.rigListBox.Items(rlog);
                    
                    msg = msgbox('Select new columns then click OK','Select Columns','Select Columns','info');
                    frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                    frames(end).setAlwaysOnTop(1);
                    uiwait(msg);
                    
                    
                    n=0;
                    while length(app.esrListBox.Value) ~= length(ve) % Checks new selection is compatible (same length)
                        if n >= 1 % if clicked on X or wrong 3 times, options to cancel, skip or continue trying
                            a = questdlg('What do you want to do?','Action','Cancel Save','Skip Testpoint','Continue Reselecting','');
                            switch a
                                case 'Cancel Save'
                                    app.refresh = 1;
                                    delete(bar)
                                    return
                                case 'Skip Testpoint'
                                    n='skip'; % for continuing for loop, not this while loop
                                    break
                                case 'Continue Reselecting'
                                    n = 0;
                            end
                        end
                        
                        if length(app.esrListBox.Value) < length(ve)
                            word = ' more ';
                        else
                            word = ' fewer ';
                        end
                        msg = msgbox([sprintf(['Select ' num2str(abs(length(ve)-length(app.esrListBox.Value))) word 'item(s) in ESR, previously selected:\n']), string(sort(ve)), sprintf('\nClick OK when correct')],'Select New', 'warn');
                        frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                        frames(end).setAlwaysOnTop(1);
                        uiwait(msg);
                        n=n+1;
                    end
                    if isequal(n,'skip')
                        continue
                    end


                    n=0;
                    while length(app.rigListBox.Value) ~= length(vr) % will not go away unless equal
                        if n >= 1 % if clicked on X or wrong 3 times, options to cancel, skip or continue trying
                            a = questdlg('What do you want to do?','Action','Cancel Save','Skip Testpoint','Continue Reselecting','');
                            switch a
                                case 'Cancel Save'
                                    app.refresh = 1;
                                    delete(bar)
                                    return
                                case 'Skip Testpoint'
                                    n='skip'; % for continuing for loop, not this while loop
                                    disp(['Skipped Testpoint: ' app.name{i}]);
                                    break
                                case 'Continue Reselecting'
                                    n = 0;
                            end
                        end
                        
                        if length(app.rigListBox.Value) < length(vr) % for displayng error message more/fewer to select
                            word = ' more ';
                        else
                            word = ' fewer ';
                        end
                        msg = msgbox([sprintf(['Select ' num2str(abs(length(vr)-length(app.rigListBox.Value))) word 'item(s) in RIG, previously selected:\n']), string(sort(vr)), sprintf('\nClick OK when correct')],'Select New', 'warn');
                        frames = java.awt.Frame.getFrames(); % msgbox to be always on top
                        frames(end).setAlwaysOnTop(1);
                        uiwait(msg);
                        n=n+1;
                    end
                    if isequal(n,'skip')
                        continue
                    end
                    
                    figure(bar);
                    
                    esr_include = getlog(app,esr.Properties.VariableNames,app.esrListBox.Value); % new logical array for indexing
                    rig_include = getlog(app,rig.Properties.VariableNames,app.rigListBox.Value);
                    we = width(esr); % new no. of columns
                    wr = width(rig);
                    ve = app.esrListBox.Value; % new selected columns
                    vr = app.rigListBox.Value;
                end
                
                esr = esr(:,esr_include);
                rig = rig(:,rig_include);
                
                % average,max,min value calculation for this test point in the iteration, and is added to corresponding matrix
                if app.AverageCheckBox.Value == true         
                    average_esr = varfun(@mean,esr); % single row of mean values
                    average_esr = table2array(average_esr);
                    average_rig = varfun(@mean,rig);
                    average_rig = table2array(average_rig);
                    
                    % add shortened name of file
                    avg(i+1,1) = app.name(i);
                    avg(i+1,2:length(average_esr)+1) = num2cell(average_esr);
                    avg(i+1,end-length(average_rig)+1:end) = num2cell(average_rig);
                else
                    avg = [];
                end
                
                if app.MaximumCheckBox.Value == true                
                    max_esr = varfun(@max,esr);
                    max_esr = table2array(max_esr);
                    max_rig = varfun(@max,rig);
                    max_rig = table2array(max_rig);
    
                    maxi(i+1,1) = app.name(i);
                    maxi(i+1,2:length(max_esr)+1) = num2cell(max_esr);
                    maxi(i+1,end-length(max_rig)+1:end) = num2cell(max_rig);
                else
                    maxi = [];
                end
                
                if app.MinimumCheckBox.Value == true                    
                    min_esr = varfun(@min,esr);
                    min_esr = table2array(min_esr);
                    min_rig = varfun(@min,rig);
                    min_rig = table2array(min_rig); 
                    
                    mini(i+1,1) = app.name(i);
                    mini(i+1,2:length(min_esr)+1) = num2cell(min_esr);
                    mini(i+1,end-length(min_rig)+1:end) = num2cell(min_rig);
                else
                    mini = [];
                end
                
                if getappdata(bar,'canceling')
                    delete(bar)
                    app.sfile = [];
                    disp('Save Summary Canceled');
                    app.refresh = 1;
                    return
                end
                progress = 0.01 + 0.94*i/length(app.epath);
                waitbar(progress,bar,['Creating Summary...(' num2str(progress*100,'%.1f') '%)']);
            end
            
            if ~isempty(avg)
                % for flow equation calculations use later
                app.eavg = array2table(avg(2:end,2:width(esr)+1),'VariableNames',[esr.Properties.VariableNames]);
                app.ravg = array2table(avg(2:end,end-width(rig)+1:end),'VariableNames',[rig.Properties.VariableNames]);
            end
            
            % combine avg, maxi and mini into main matrix to be exported
            app.matrix=[cell(1,1), esr.Properties.VariableNames,cell(1,1),rig.Properties.VariableNames;
                avg;cell(1,width(esr)+width(rig)+2);maxi;cell(1,width(esr)+width(rig)+2);mini;cell(1,width(esr)+width(rig)+2)];
            
            waitbar(0.97,bar,'Exporting Summary(97%)');
            app.RefreshButton.Enable = 'on'; % for (if) saving new summary
            app.refresh = 1;
            xlswrite([app.spath app.sfile],app.matrix,1)
            disp('Sumamry Saved');
            app.Lamp.Color = [0 1 0];
            waitbar(1,bar,'Summary Saved!(100%)');
            delete(bar)
        end

        % Button pushed function: OpenSummaryButton
        function OpenSummaryButtonPushed(app, event)
            % Opens saved summary file
            if isempty(app.sfile)
                msgbox('No summary File Found', 'Error', 'error');
            elseif exist([app.spath app.sfile],'file')
                if app.Lamp.Color == [0 1 0] % Ensures new file is complete (takes 10 seconds)
                    winopen([app.spath app.sfile])
                    disp('Summary Opened in Excel');
                end
            else
                msgbox('No summary File Found', 'Error', 'error');
            end
        end

        % Button pushed function: CalculateandPlotFlowButton
        function CalculateandPlotFlowButtonPushed(app, event)
            if isempty(app.eavg) || isempty(app.ravg)
                msgbox('Save/import summary first', 'Error', 'error');
                return
            end
            
            % Different K1 values from user input
            K2 = app.KCGEditField.Value;
            khl = app.KHLEditField.Value;
            kcl = app.KCLEditField.Value;
            K1 = khl;
            
%====================================================================================================================================
            % find columns with flow rate equation variables to calculate + plot (EDIT AS REQUIRED)
            % getcol(app, a, b, c)
            % a = app.eavg for ESR, a = app.ravg for RIG
            % b = ["string1","string2" ... ], string array with strings to search which the variable contains
            % c = ["string3","string4" ...], string array with strings to search which exclude variables that could contain b but not the variable wanted
            
            rho  = getcol(app,app.ravg,"Density"); % rig
            if isempty(rho)
                rho = getcol(app,app.eavg,"Density");
            end
            rhoe = getcol(app,app.eavg,"Density");
            Pout = getcol(app,app.eavg,"Outlet"); % esr 
            Pher = getcol(app,app.ravg,["HoseEnd","Hose_End"]); % rig
            Pl = getcol(app,app.ravg,"Latch"); % rig
            Qe = getcol(app,app.eavg,["FuelFlow","Fuel_Flow"],["Mass","Limit"]).^2; % esr
            Qr = getcol(app,app.ravg,["FuelFlow","Fuel_Flow"],["Mass","Limit"]).^2; % rig
            Phee = getcol(app,app.eavg,["HoseEndPressure","Hose_End_Pressure"]); % esr
%====================================================================================================================================
            empty = [isempty(rho),isempty(Pout),isempty(Pher),isempty(Pl),isempty(Qe),isempty(Qr),isempty(Phee)]; % Print which variable missing
            vars = ["Density(Esr/Rig)","Outlet Pressure(Esr)", "Hose End Pressure(Rig)","Latch Pressure(Rig)","Fuel Flow(Esr)","Fuel Flow(Rig)","Hose End Pressure(Esr)"];
            if any(empty)
                msgbox(['Variables Missing in Summary: ' vars(empty)],'Error','error')
                return
            end

            % For later plotting coupling losses use
            app.hose = Pher;
            app.latch = Pl;
            app.Q = Qe;
            
            % Clears axes, calculates specific values in flow equation
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            avgrho = mean(rho);
            Phead = avgrho*9.81 * app.dHEditField.Value /6894.757; % Pressure gained from catenary (psi) using user input delta Height (dH)
            
            if isequal(app.PodDropDown.Value,'910') % 910 has different K1 values for each test point
                K=zeros(1,length(rho));
                for i = 1:length(rho)
                    K(i) = khl * rho2c(app,rho(i),sqrt(Qe(i)));
                end
                khl = K;
            end
            
            
            dPh = avgrho*app.KHLEditField.Value; % slope of RCU estimation
            dPl = avgrho*(app.KHLEditField.Value+kcl);
            
            % change export column header and use latch/hose end pressure
            yl = Pout - Pl + Phead;
            yh = Pout - Pher + Phead; 
            if app.LatchButton.Value == true
                y = yl;
%                 K1 = K1 + kcl; % Include Coupling Loss Factor
                dP = dPl;
            else
                y = yh;
                dP = dPh;
            end
            
            % Change formulae/values if 908/905 (metric/imperial)
            if isequal(app.PodDropDown.Value,'908') || isequal(app.PodDropDown.Value,'908EH')
                Qe = Qe/(60^2); % L/s
                Qr = Qr/(15.85032^2); % L/s
                calPhe = Pout - ((khl'+kcl).*rhoe).*Qe/6.89475 + K2.*rhoe/6.894757; % psi
                y = y*6.894757; % kPa
                yh = yh*6.894757;
                yl = yl*6.894757;
                unit = {'(L/s)^2','(L/s)^2','kPa','kPa','psi','','(L/s)^2','kPa','kPa','psig','%'};
            elseif isequal(app.PodDropDown.Value,'905') || isequal(app.PodDropDown.Value,'912') || isequal(app.PodDropDown.Value,'910')
                avgrho = avgrho / 119.8;
                rhoe = rhoe./119.8;
                calPhe = Pout - ((khl'+kcl).*rhoe).*Qe + K2.*rhoe; % psi
                dP = dP / 119.8;
                dPh = dPh / 119.8;
                dPl = dPl / 119.8;
                unit = {'USgpm','USgpm','psi','psi','psi','','USgpm','psi','psi','psig','%'};
            end  
            
            % set dimension for export flow data dimensions
            if length(rho) <= 5
                app.matrix2 = cell(7,21);
            else
                app.matrix2 = cell(length(rho)+3,21);
            end
            
            maxQ = max([Qe;Qr]);
            % add values and items to matrix to be exported
            app.matrix2(1,4:15) = [{'Units:'}, unit];
            app.matrix2(3:4,1:2) = [{'avg. rho(kg/m^3)'},{'P(catenary)(psi)'};num2cell(avgrho),num2cell(Phead)];
            app.matrix2(6:7,1:3) = [{'K(HL):'},{'K(CL):'},{'K(CG):'};num2cell(app.KHLEditField.Value),num2cell(kcl),num2cell(K2)];
            app.matrix2(2,5:15) = [{'Q^2 esr'},{'Q^2 rig'},{'dP P(O)-P(HE) corrected for catenary'},{'dP P(O)-P(L) corrected for catenary'},{'Coupling Loss'},{''},{'Q^2 (RCU)'},{'dP HE (RCU)'},{'dP L (RCU)'},{'Calculated P(HE)'},{'% Diff. Calculated vs Measured P(HE)'}];
            app.matrix2(3:length(rho)+2,5:9) = num2cell([Qe,Qr,yh,yl,Pher-Pl]);
            app.matrix2(3:4,11:13) = num2cell([[0;maxQ],[0;maxQ*dPh],[0;maxQ*dPl]]);
            app.matrix2(3:length(rho)+2,14:15) = num2cell([calPhe,100*(calPhe-Phee)./Phee]);
            
            % If exclude chosen, changes arrays to shorter version, excluding data if Hose End Pressure > 50
            include = Pher <= 50;
            if isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{2})
                avgrho = mean(rho(include));
                y = y(include);
                Qe = Qe(include);
                Qr = Qr(include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('All points excluded','Warning','warn');
                    return
                end
            elseif isequal(app.CouplingRegulationKnob.Value, app.CouplingRegulationKnob.Items{3})
                avgrho = mean(rho(~include));
                y = y(~include);
                Qe = Qe(~include);
                Qr = Qr(~include);
                if isempty(Qe) % To prevent index error on dP below
                    msgbox('No points excluded','Warning','warn');
                    return
                end
            end          
            
            % plot in preview axes
            m1 = Qe\y; % Slope of y = mx
            app.KlossEditField.Value = m1/avgrho;
            plot(app.UIAxes,Qe,y, 'or')
            plot(app.UIAxes,[0;Qe],[0;Qe*m1],'b')
            plot(app.UIAxes,[0,max(Qe)],[0,dP*max(Qe)],'g')
            legend(app.UIAxes,'Data points','Best Fit through 0','RCU estimation','Location','northwest') 
            m2 = Qr\y; % Slope of y = mx
            app.SlopeEditField_2.Value = m2/avgrho;
            plot(app.UIAxes_2,Qr,y,'or')
            plot(app.UIAxes_2,[0;Qr],[0;Qr*m2],'b')
            plot(app.UIAxes_2,[0,max(Qr)],[0,dP*max(Qr)],'g')
            
            meh = Qe\yh; % slope
            mrh = Qr\yh;
            mel = Qe\yl;
            mrl = Qr\yl;
            
            % for later export use (flow calculation data)
            app.matrix2(2,17:21) = [{'slope esr HE'},{'slope rig HE'},{''},{'slope esr L'},{'slope rig L'}];
            app.matrix2(3,17:21) = [num2cell([meh,mrh]),cell(1,1),num2cell([mel,mrl])];
            app.matrix2(4,17:21) = [{'Kloss esr HE'},{'Kloss rig HE'},{''},{'Kloss esr L'},{'Kloss rig L'}];
            app.matrix2(5,17:21) = [num2cell([meh/avgrho,mrh/avgrho]),cell(1,1),num2cell([mel/avgrho,mrl/avgrho])];  
            
            disp('Flow Data Calculated and Plotted');
        end

        % Button pushed function: ExportFlowDataButton
        function ExportFlowDataButtonPushed(app, event)
            % Exports useful data generated in flow equation calculations including dP, check on P(HE), slope, K loss etc
            
            if isempty(app.epath) && isempty(app.sfile)
                msgbox('Open Files / Import or Save Summary First', 'Error', 'error');
                return
            end            
            if isempty(app.matrix2)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Appends new data to summary or make new excel file 
            if app.ToSummaryButton.Value == true
                % Makes sure file is writable (not opened)
                if fopen([app.spath app.sfile],'a') == -1
                    msgbox('Close existing summary window and try again','Error','error');
                    return
                else
                    fclose('all');
                    bar = waitbar(0,'Loading...'); % Initiate loading bar figure
                end
                xlswrite([app.spath app.sfile],app.matrix2,1,[xlcolumn(app,size(app.matrix,2)+2) , '1'])
                app.dpath = app.spath; % for adding chart
                app.dfile = app.sfile;
                n = size(app.matrix,2);
                
            elseif app.AsNewButton.Value == true             
                [app.dfile , app.dpath] = uiputfile({'*.xlsx'},'Save As','flow_data.xlsx');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                bar = waitbar(0,'Exporting Flow Calculations...'); % Initiate loading bar figure
                if app.dfile ~=0
                    if fopen([app.dpath app.dfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                    end
                    delete([app.dpath app.dfile]);
                    xlswrite([app.dpath app.dfile],app.matrix2)
                    n = -1;
                    
                else
                    app.dfile = [];
                    return
                end
            elseif app.SummaryCopyButton.Value == true
                [app.dfile , app.dpath] = uiputfile({'*.xlsx'},'Save As','Summary_flow.xlsx');
                figure(app.SummaryandPlotsforFlowDataUIFigure);
                if isequal([app.dfile app.dpath],[app.sfile app.spath]) % error if summary copy same file
                    msgbox('Cannot copy to itself. Select ''To Summary'' instead','Error','error')
                    return
                end
                if app.dfile ~=0
                    if fopen([app.dpath app.dfile],'a') == -1
                        msgbox('Close existing flow data window and try again','Error','error');
                        return
                    else
                        fclose('all');
                        bar = waitbar(0,'Exporting Flow Calculations...'); % Initiate loading bar figure
                    end
                    delete([app.dpath app.dfile]);
                    copyfile([app.spath app.sfile],[app.dpath app.dfile]) % copy summary to new file then performs same functions as 'to summary'
                    xlswrite([app.dpath app.dfile],app.matrix2,1,[xlcolumn(app,size(app.matrix,2)+2) , '1'])
                    n = size(app.matrix,2);
                else
                    app.dfile = [];
                    return
                end
            end 
            
            waitbar(0.1,bar,'Exporting Flow Calculations...(10%)');
            
            % Add graphs in excel format onto summary
            if app.AddgraphstoexcelCheckBox.Value == true
                app.Excel = actxserver('Excel.Application'); % Opens Excel on COM server
                waitbar(0.35,bar,'Adding Charts to Excel...(30%)');
                app.Workbook = invoke(app.Excel.Workbooks,'Open', [app.dpath app.dfile]); % Opens summary or flow data file in excel
                waitbar(0.6,bar,'Adding Charts to Excel...(60%)');
                
                % Add charts using written addchart function
                addchart(app,'HE',[n+6,n+8,n+7,n+8,n+12,n+13],[{'ESR'},{'RIG'},{'RCU estimation'}],'Q^2','dP P(O)-P(HE) corrected for catenary','Coupling Loss Excluded (HoseEnd)',n)
                waitbar(0.7,bar,'Adding Charts to Excel...(70%)');
                addchart(app,'L',[n+6,n+9,n+7,n+9,n+12,n+14],[{'ESR'},{'RIG'},{'RCU estimation'}],'Q^2','dP P(O)-P(L) corrected for catenary','Coupling Loss Included (Latch)',n+12)
                waitbar(0.8,bar,'Adding Charts to Excel...(80%)');
                if n ~= -1 % only plot coupling loss if averages exist for Q (x-axis)
                    Qe = find(contains(app.eavg.Properties.VariableNames,'FuelFlow') | contains(app.eavg.Properties.VariableNames,'Fuel_Flow'),1);
                    if isempty(Qe)
                        msgbox('FuelFlow Variable not found in ESR Summary, cannot plot coupling loss :(','Error','error')
                        app.Workbook.Save
                        app.Workbook.Close
                        return
                    end
                    addchart(app,'CL',[Qe+1,n+10],[{'Coupling Loss'}],'Q(ESR)','P(HE)-P(L)','Coupling Loss',n-12)
                    waitbar(0.9,bar,'Adding Charts to Excel...(90%)');
                end
                
                disp('Charts added to Excel');
                
                if app.HighlightifPHE50CheckBox.Value == true
                    log = app.hose > 50; % logical array for P(HE) > 50
                    idx = find(log);
                    k = contains(app.ravg.Properties.VariableNames,'HoseEnd') | contains(app.ravg.Properties.VariableNames,'Hose_End');
                    if ~any(k)
                        msgbox('HoseEnd Pressure Variable not found in RIG Summary, Highlight function is broken :(','Error','error')
                        app.Workbook.Save
                        app.Workbook.Close
                        return
                    end
                    He = xlcolumn(app,find(k,1) + size(app.eavg,2) + 2);
                    for i = 1:length(find(log))
                        app.Workbook.Worksheets.Item(1).Range([He num2str(idx(i) + 2)]).Interior.ColorIndex = 40;
                    end
                end
                
                app.Excel.Range(['A1:' xlcolumn(app,size(app.matrix,2)+22) '2']).Select;
                app.Excel.Selection.WrapText = true;
                
                app.Workbook.Save
                app.Workbook.Close
            end
            
            waitbar(1,bar,'Export Complete');
            app.Lamp_2.Color = [0 1 0];
            disp('Flow Data Saved');
            delete(bar)
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Opens Flow Data (either summary or new)
            if app.Lamp_2.Color == [0 1 0]
                if exist([app.dpath app.dfile],'file')
                    winopen([app.dpath app.dfile])
                    disp('Summary/Flow Data Opened in Excel');
                else
                    msgbox('No Summary / Data File Found', 'Error', 'error');
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            % Changes y axes labels for Hose End / Latch Pressure
            if app.HoseEndButton.Value == true
                if isequal(app.PodDropDown.Value,'905') || isequal(app.PodDropDown.Value,'912')
                    ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (psi)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (psi)');
                elseif isequal(app.PodDropDown.Value,'908') || isequal(app.PodDropDown.Value,'908EH')
                    ylabel(app.UIAxes, 'P(OUT) - P(HE) + K2*rho (kPa)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(HE) + K2*rho (kPa)');
                end
                app.CouplingRegulationKnob.Enable = 'off';
            else
                if isequal(app.PodDropDown.Value,'905') || isequal(app.PodDropDown.Value,'912')
                    ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (psi)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (psi)');
                elseif isequal(app.PodDropDown.Value,'908') || isequal(app.PodDropDown.Value,'908EH')
                    ylabel(app.UIAxes, 'P(OUT) - P(L) + K2*rho (kPa)');
                    ylabel(app.UIAxes_2, 'P(OUT) - P(L) + K2*rho (kPa)');
                end
                app.CouplingRegulationKnob.Enable = 'on';
            end            
        end

        % Button pushed function: PlotCouplingLossButton
        function PlotCouplingLossButtonPushed(app, event)
            if isempty(app.epath) && isempty(app.sfile)
                msgbox('Open Data Files First', 'Error', 'error');
                return
            end
            if isempty(app.hose)
                msgbox('Plot and Calculate First!', 'Error', 'error');
                return
            end
            
            % Import and separate variables
            Phe = app.hose;
            Pl = app.latch;
            Q = sqrt(app.Q);
            dP = Phe - Pl;            
            dP_1 = dP(Phe<=50);
            dP_2 = dP(Phe>50);
            Q_1 = Q(Phe<=50);
            Q_2 = Q(Phe>50);
            
            % Plot the 2 sets of data as scattered points and different colours
            hold on
            xlabel('ESR Fuel flow (USgpm)')
            ylabel('MA4 Losses (psi)')
            title('Coupling Pressure Losses')
            grid
            plot(Q_1,dP_1,'ob','MarkerFaceColor', 'b')
            plot(Q_2,dP_2,'or','MarkerFaceColor', 'r')
            legend('Regulators Opened','Regulators Closed','Location','northwest')
            
            disp('Coupling Loss Plotted');
        end

        % Button pushed function: OpenESRButton
        function OpenESRButtonPushed(app, event)
            % Opens folder selection window
            try
                main = uigetdir('','Open Esr Folder');
                figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
                if ~isequal(main, 0)
                    dirinfo = dir(main);
                    dirinfo(ismember({dirinfo.name},{'.','..'})) = []; % Removes current directories . and ..
                    [path,app.epath] = deal({});
                    
                    bar = waitbar(0,'Opening Files...(0%)','CreateCancelBtn','setappdata(gcbf,''canceling'',1)'); % Initiate loading bar figure
                    setappdata(bar,'canceling',0);
                    
                    filename = cell(1,length(dirinfo)); % cell array of file names
                    for i = 1:length(dirinfo)
                        filename{i} = dirinfo(i).name;
                    end
                    filenameold = filename; % one is unchanged as it's used in file path, other changed for sorting
                    
                    for i = 1:length(dirinfo)
                        if dirinfo(i).isdir % if item is a folder
                            continue
                        end
                        if app.csvButton.Value == true % check only take .csv files
                            if contains(dirinfo(i).name,'.csv')
                                
                                % Renames file names by replacing . with _ so sorting is consistent in esr/rig
                                dot_index = strfind(filename{i},'.');
                                filename{i}(dot_index) = '_';
                                
                                space_index = strfind(filename{i},' '); 
                                filename{i}(space_index) = '_';
                                
                                zero_index = strfind(filename{i},'zero');
                                filename{i}(zero_index) = '0';
                                
                                path(end+1) = cellstr(fullfile(main,filename{i}));
                                app.epath(end+1) = cellstr(fullfile(main,filenameold{i}));
                            else
                                continue
                            end
                            
                        elseif app.noextensionsButton.Value == true % takes only blank extensions
                            [~,~,ext] = fileparts(fullfile(main,dirinfo(i).name));
                            if isempty(ext) % no extension
                                dot_index = strfind(filename{i},'.'); 
                                filename{i}(dot_index) = '_';
                                
                                space_index = strfind(filename{i},' '); 
                                filename{i}(space_index) = '_';
                                
                                zero_index = strfind(filename{i},'zero');
                                filename{i}(zero_index) = '0';
                                
                                path(end+1) = cellstr(fullfile(main,filename{i}));
                                app.epath(end+1) = cellstr(fullfile(main,filenameold{i}));
                            end
                        end
                        
                        if getappdata(bar,'canceling') % cancel button in waitbar
                            delete(bar)
                            app.sfile = [];
                            disp('Canceled');
                            return
                        end
                        progress = 0.95*i/length(dirinfo);
                        waitbar(progress,bar,['Opening Files...(' num2str(progress*100,'%.1f') '%)']);
                    end
                    
                    if isempty(app.epath)
                        msgbox('No data files found, try again','Error','error')
                        delete(bar)
                        return
                    end
                    
                    % check equal length
                    if ~isempty(app.rpath)
                        if length(app.epath) < length(app.rpath)
                            msgbox(['There are ' num2str(length(app.rpath)-length(app.epath)) ' more RIG files than ESR. Move extra testpoint csv into one subfolder'],'Error','error');
                            app.error = 1;                        
                        elseif length(app.epath) > length(app.rpath)
                            msgbox(['There are ' num2str(length(app.epath)-length(app.rpath)) ' more ESR files than RIG. Merge same testpoint csv into same spreadsheet'],'Error','error');
                            app.error = 1;
                        else
                            app.error = 0;
                        end
                        disp('Both ESR/RIG Files Opened');
                    end
                    
                    [~,I] = natsort(app,path); % gets index of new sorted order
                    app.epath = app.epath(I); % sorts old name using index from sorted new name
                    [~,app.name,~] = cellfun(@fileparts,app.epath, 'UniformOutput',false);
                    for i = 1:length(app.name) % remove 'ESR' in name if exists
                        k = strfind(app.name{i},'ESR');
                        if length(k) == 1
                            app.name{i}(k:k+2) = '';
                        end
                    end
                    app.TestPointsDropDown.Items = app.name;
                    
                    waitbar(0.99,bar,'Opening Files...(99%)');
                    
                    %Load and select Column headers list box
                    esr = gettable(app,app.epath{1});
                    app.esr1 = esr;
                    app.esrListBox.Items = sort(esr.Properties.VariableNames);
                    vars = ["OutletPressure","InletPressure","HoseEndPressure","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                    xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState"]; % Exclude due to dublicated names
                    elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                    app.esrListBox.Value = app.esrListBox.Items(elog);
                    
                    % display number of variabled selected
                    app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
                    app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
                    
                    waitbar(1,bar,'Files Opened!(100%)');
                    delete(bar)
                    disp('ESR Files Opened');
                else
                    disp('User Selected Cancel');
                end
                figure(app.SummaryandPlotsforFlowDataUIFigure); % GUI window would become minimised, this reopens
            catch
                delete(bar)
                msgbox('Wrong file types / formats found. Please check ''help''and try again','Error','error')
            end
        end

        % Button pushed function: ExpandColmunsButton
        function ExpandColmunsButtonPushed(app, event)
            % expands the list box to allow most/all items to be seen at once, also auto selects the searched common column names to save time
            if ~isempty(app.epath)
                if app.status == 0
                    if length(app.esrListBox.Items) >=29 % so doesn't expand to longer than window size
                        elen = 27;
                    else
                        elen = length(app.esrListBox.Items);
                    end
                    if length(app.rigListBox.Items) >=29
                        rlen = 27;
                    else
                        rlen = length(app.rigListBox.Items);
                    end
                    app.esrListBox.Position(2) = app.esrListBox.Position(2) - elen * 20 -10  + app.esrListBox.Position(4);
                    app.esrListBox.Position(4) = elen * 20 +10 ; % auto sizes to fit items
                    app.rigListBox.Position(2) = app.rigListBox.Position(2) - rlen * 20 -10 + app.rigListBox.Position(4);
                    app.rigListBox.Position(4) = rlen * 20 +10;
                
                    app.status = 1;
                    
                    
                else % returns box to default size
                    app.esrListBox.Position = [8,470,255,140];
                    app.rigListBox.Position = [395,470,255,140];
    
                    app.status = 0;
                end
            end
        end

        % Button pushed function: FilesHelpButton
        function FilesHelpButtonPushed(app, event)
            msgbox(sprintf(['ESR folder: all data files in 1 folder\n    .csv => can contain other file types but data files must have .csv\n    no extension => can have a mix of blank/.csv but no other file types' ...
                '\n\nRIG folder: all have .csv, ignores other file types\n    subfolders => subfolder for each test point (can have many csv files)\n    all in 1 => same as ESR ''.csv''']) ,'About !','help');
        end

        % Value changed function: PodDropDown
        function PodDropDownValueChanged(app, event)
            % Changes K values (but can still be changed manually)
            if isequal(app.PodDropDown.Value,'905')
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.KHLEditField.Value = 8.12e-5;
                app.KCGEditField.Value = 1.1544;
                app.KCLEditField.Value = 0;
            elseif isequal(app.PodDropDown.Value,'908')
                xlabel(app.UIAxes, 'Q^2 (L/s)^2');
                xlabel(app.UIAxes_2, 'Q^2 (L/s)^2');
                app.KHLEditField.Value = 9.95e-4;
                app.KCGEditField.Value = 5.68e-2;
                app.KCLEditField.Value = 3.08e-5;
            elseif isequal(app.PodDropDown.Value,'908EH')
                xlabel(app.UIAxes, 'Q^2 (L/s)^2');
                xlabel(app.UIAxes_2, 'Q^2 (L/s)^2');
                app.KHLEditField.Value = 4.294e-3;
                app.KCGEditField.Value = 0;
                app.KCLEditField.Value = 4.69e-5;
            elseif isequal(app.PodDropDown.Value,'910')
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.KHLEditField.Value = 7.78e-4;
                app.KCGEditField.Value = 4.25e-2;
                app.KCLEditField.Value = 4.69e-5;
            elseif isequal(app.PodDropDown.Value,'912')
                xlabel(app.UIAxes, 'Q^2 (USgpm)^2');
                xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2');
                app.KHLEditField.Value = 8.077e-5;
                app.KCGEditField.Value = 1.11;
                app.KCLEditField.Value = 0;
            end
            
            % KCL not found for 905 and 912
        end

        % Button pushed function: EquationsButton
        function EquationsButtonPushed(app, event)
            msgbox(sprintf('910: P(O) - P(HE) + Kcg*rho = (C(RE)*Khl + Kcl)*rho*Q^2\nOthers: P(O) - P(HE) + Kcg*rho = (Khl + Kcl)*rho*Q^2\n(P = Pressure, K = correction factor, rho = density, Q = flow rate)'))
        end

        % Button pushed function: ImportSummaryButton
        function ImportSummaryButtonPushed(app, event)
            % Imports summary excel created using this app, saves time opening files and saving summary, go straight to flow calculations            
            [file,path] = uigetfile({'*xlsx';'*.xls'});
            figure(app.SummaryandPlotsforFlowDataUIFigure);
            try
                if ~isequal(file,0)
                    [~,~,sum_cell] = xlsread(fullfile(path,file)); % uses raw cell array with [NaN] instead of []
                    % quick chec on correct format
                    if ~any(isnan(sum_cell{1,1})) || ~any(isnan(sum_cell{2,2})) || (~isequal(sum_cell{2,1},'Average') && ~isequal(sum_cell{2,1},'Maximum') && ~isequal(sum_cell{2,1},'Minimum'))
                        msgbox('Invalid Summary Format! Must be exported using this app','Error','error')
                        return
                    end
                    % find index of blank column separating esr and rig
                    for i = 2:size(sum_cell,2)
                        if ~exist('idx1','var')
                            if any(isnan(sum_cell{1,i}))
                                idx1 = i;
                            end
                        else
                            if any(isnan(sum_cell{1,i}))
                                idx2 = i;
                                break
                            end
                            
                        end
                    end
                    if ~exist('idx2','var')
                        idx2 = size(sum_cell,2) +1;
                    end
                    % add to global variables
                    app.matrix = sum_cell(:,1:idx2-1); % for export flow data use
                    app.eavg = array2table(sum_cell(3:end,2:idx1-1),'VariableNames',matlab.lang.makeValidName(sum_cell(1,2:idx1-1)));
                    app.ravg = array2table(sum_cell(3:end,idx1+1:idx2-1),'VariableNames',matlab.lang.makeValidName(sum_cell(1,idx1+1:idx2-1)));
                    app.esrListBox.Items = sum_cell(1,2:idx1-1);
                    app.rigListBox.Items = sum_cell(1,idx1+1:idx2-1);
                    app.sfile = file;
                    app.spath = path;
                    app.Lamp.Color = [0 1 0]; % for opening button
                    disp('Summary Imported');
                end
            catch % any error returns this msg
                msgbox('Invalid Summary Format! Must be exported using this app','Error','error')
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Reload first testpoint's variables into listbox (state after files opening)
            
            if ~isempty(app.epath)
                esr = gettable(app,app.epath{1});
                app.esrListBox.Items = sort(esr.Properties.VariableNames);
                vars = ["OutletPressure","InletPressure","HoseEndPressure","RATSpeed","TargetFuel","Density","FuelMassFlow","FuelFlow"];
                xvars_e = ["PumpOutlet","FuelInlet","HoseEndPressure_psi_","DeliveryRate","FuelFlowLimit","RATSpeed2","SwitchState","PressureRequest"]; % Exclude due to dublicated names
                elog = contains(app.esrListBox.Items,vars) & ~contains(app.esrListBox.Items,xvars_e);
                app.esrListBox.Value = app.esrListBox.Items(elog);
                app.RefreshButton.Enable = 'off';
            end
            if ~isempty(app.rpath)
                rig = gettable(app,app.rpath{1});
                app.rigListBox.Items = sort(rig.Properties.VariableNames);
                xvars_r = ["Time","Coriolis"];
                rlog = ~contains(app.rigListBox.Items,xvars_r);
                app.rigListBox.Value = app.rigListBox.Items(rlog);
                app.RefreshButton.Enable = 'off';
            end
            
            % display number of variabled selected
            app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
            app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
            app.TestPointsDropDown.Value = app.TestPointsDropDown.Items(1);
            
            app.refresh = 0;
            disp('Listboxes Refreshed');
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Adds .csv extension to all data files selected that have no extension

            [file,path] = uigetfile('*.*','MultiSelect', 'on');
            if file ~= 0
                for i = 1:length(file)
                    [~,~,ext] = fileparts(fullfile(path,file{i}));
                    if ~isempty(ext)
                        disp([file{i} ' already has an extnsion, skipped']);
                        continue
                    end
                    movefile([path,file{i}],[path,file{i},'.csv'])
                end
                disp('Extensions added');
            end
        end

        % Value changed function: esrListBox
        function esrListBoxValueChanged(app, event)
            % display number of variabled selected
            app.SelectedEsrEditField.Value =  length(app.esrListBox.Value);
            app.RefreshButton.Enable = 'on'; % refresh + re-autoselect variables
            
            % for reordering incorrect filename orders
            if app.reorder == 1 % insert above
                L = app.esrListBox.Items;
                log1 = app.insert_idx; % log index of items to insert
                idx2 = find(log1); % actual index of ^
                idx3 = find(getlog(app,app.esrListBox.Items,app.esrListBox.Value)); % insert above this

                app.esrListBox.Items = {}; % renew listbox
                for i = 1:length(L)
                    if ~any(ismember(idx2,i)) && ~isequal(idx3,i) % if not index of items to insert and not index of item to insert above
                        app.esrListBox.Items(end+1) = L(i);
                    elseif isequal(idx3,i) % if index of item to insert above
                        app.esrListBox.Items(end+1:end+length(idx2)) = L(log1);
                        app.esrListBox.Items(end+1) = L(idx3);
                    end
                end

                app.reorder = 0;
                app.InsertAboveButton.Value = 0;
                app.esrListBox.Value = {};
                
            elseif app.reorder == 2 % complete reorder all
                if length(app.esrListBox.Value) ~= 1 % checks if multiselected
                    msgbox('Only select one item as the next item (do not multiselect), order reset','Error','error')
                    app.ReorderAllButton.Value = 0;
                    app.ResetDefaultButtonPushed
                    app.reorder = 0;
                    app.Ve = {};
                    return
                end
                if any(getlog(app,app.Ve,app.esrListBox.Value)) % checks if reselected
                    msgbox('Select non-highlighted items, order reset','Error','error')
                    app.ReorderAllButton.Value = 0;
                    app.ResetDefaultButtonPushed
                    app.reorder = 0;
                    app.Ve = {};
                    return
                end
                app.Ve(end+1) = app.esrListBox.Value;
                L = app.esrListBox.Items;
                log = getlog(app,app.esrListBox.Items,app.Ve);
                app.esrListBox.Items = [app.Ve,L(~log)];
                app.esrListBox.Value = app.Ve;
            end
        end

        % Value changed function: rigListBox
        function rigListBoxValueChanged(app, event)
             % display number of variabled selected
            app.SelectedRigEditField.Value =  length(app.rigListBox.Value);
            app.RefreshButton.Enable = 'on';
            
            % for reordering incorrect filename orders
            if app.reorder == 1
                L = app.rigListBox.Items;
                log1 = app.insert_idx; % log index of items to insert
                idx2 = find(log1); % actual index of ^
                idx3 = find(getlog(app,app.rigListBox.Items,app.rigListBox.Value)); % insert above this

                app.rigListBox.Items = {}; % renew listbox
                for i = 1:length(L)
                    if ~any(ismember(idx2,i)) && ~isequal(idx3,i) % it just works 
                        app.rigListBox.Items(end+1) = L(i);
                    elseif isequal(idx3,i)
                        app.rigListBox.Items(end+1:end+length(idx2)) = L(log1);
                        app.rigListBox.Items(end+1) = L(idx3);
                    end
                end

                app.reorder = 0;
                app.InsertAboveButton.Value = 0;
                app.rigListBox.Value = {};
            
            elseif app.reorder == 2
                if length(app.rigListBox.Value) ~= 1
                    msgbox('Only select one item as the next item (do not multiselect), order reset','Error','error')
                    app.ReorderAllButton.Value = 0;
                    app.ResetDefaultButtonPushed
                    app.reorder = 0;
                    app.Vr = {};
                    return
                end
                if any(contains(app.rigListBox.Value,app.Vr))
                    msgbox('Select non-highlighted items, order reset','Error','error')
                    app.ReorderAllButton.Value = 0;
                    app.ResetDefaultButtonPushed
                    app.reorder = 0;
                    app.Vr = {};
                    return
                end
                app.Vr(end+1) = app.rigListBox.Value;
                L = app.rigListBox.Items;
                log = getlog(app,app.rigListBox.Items,app.Vr);
                app.rigListBox.Items = [app.Vr,L(~log)];
                app.rigListBox.Value = app.Vr;
            end
            
        end

        % Button pushed function: FileOrderButton
        function FileOrderButtonPushed(app, event)
            % Expands listbox horizontally (side by side), then displays file order, if choosen incorrect, can click to change order
            
            if isempty(app.epath) || isempty(app.rpath)
                msgbox('Open both ESR and RIG files to compare their order','Error','error')
                return
            end
            
            app.summary.Visible = 'off';
            app.flow.Visible = 'off';
            app.RefreshButton.Visible = 'off';
            app.TestPointsDropDown.Visible = 'off';
            app.PlotRIGButton.Visible = 'off';
            app.PlotESRButton.Visible = 'off';
            app.SelectedEsrEditField.Visible = 'off';
            app.SelectedRigEditField.Visible = 'off';
            app.SelectedEditFieldLabel.Visible = 'off';
            app.SelectedEditField_2Label.Visible = 'off';
            app.top.Visible = 'off';
            
            app.esrListBox.Position(3) = 319; % set widths
            app.rigListBox.Position(1) = 331;
            app.rigListBox.Position(3) = 319;
            
            if length(app.epath) >=29 % set lengths so doesn't go beyond app boundary
                elen = 27;
            else
                elen = length(app.epath);
            end
            if length(app.rpath) >=29
                rlen = 27;
            else
                rlen = length(app.rpath);
            end
            app.esrListBox.Position(2) = app.esrListBox.Position(2) - elen * 20 -10  + app.esrListBox.Position(4);
            app.esrListBox.Position(4) = elen * 20 +10; % auto sizes to fit items
            app.rigListBox.Position(2) = app.rigListBox.Position(2) - rlen * 20 -10 + app.rigListBox.Position(4);
            app.rigListBox.Position(4) = rlen * 20 +10;
            
            [~,ename,~] = cellfun(@fileparts,app.epath,'UniformOutput',false); % gets rig file names
            app.esrListBox.Items = ename;
            [~,rname,~] = cellfun(@fileparts,app.rpath,'UniformOutput',false); % gets rig file names
            app.rigListBox.Items = rname;
            app.esrListBox.Value = {};
            app.rigListBox.Value = {};
            
            app.CorrectButton.Visible = 'on';
            app.IncorrectButton.Visible = 'on';
            
            disp('Showing File Orders');
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            eval(app.EditField.Value)
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            % restore appearance and update file order
            
            % if still not equal lengths
            if length(app.esrListBox.Items) ~= length(app.rigListBox.Items)
                a = questdlg(['Different number of testpoints (ESR: ' num2str(length(app.esrListBox.Items)) ', RIG: ' num2str(length(app.rigListBox.Items)) ')'],'Confirm','OK','Continue Editing','Continue Editing');
                switch a
                    case 'Continue Editing'
                        return
                end
            else
                app.error = 0;
            end
            
            app.esrListBox.Position = [8,470,255,140];
            app.rigListBox.Position = [395,470,255,140];
            app.summary.Visible = 'on';
            app.flow.Visible = 'on';
            app.RefreshButton.Visible = 'on';
            app.TestPointsDropDown.Visible = 'on';
            app.PlotRIGButton.Visible = 'on';
            app.PlotESRButton.Visible = 'on';
            app.SelectedEsrEditField.Visible = 'on';
            app.SelectedRigEditField.Visible = 'on';
            app.SelectedEditFieldLabel.Visible = 'on';
            app.SelectedEditField_2Label.Visible = 'on';
            app.CorrectButton.Visible = 'off';
            app.IncorrectButton.Visible = 'off';
            app.Swap2Button.Visible = 'off';
            app.InsertAboveButton.Visible = 'off';
            app.ReorderAllButton.Visible = 'off';
            app.ResetDefaultButton.Enable = 'on'; 
            app.ResetDefaultButton.Visible = 'off';
            app.RemoveButton.Visible = 'off';
            app.MergeButton.Visible = 'off';
            app.top.Visible = 'on';
            
            
            [p,~,e] = fileparts(app.epath{1});
            app.epath = cell(1,length(app.esrListBox.Items));
            app.name = cell(1,length(app.esrListBox.Items));
            for i = 1:length(app.esrListBox.Items) % each epath item and listbox item
                app.epath{i} = fullfile(p,[app.esrListBox.Items{i},e]);
                app.name{i} = app.esrListBox.Items{i};
                k = strfind(app.name{i},'ESR');
                if length(k) == 1
                    app.name{i}(k:k+2) = '';
                end
            end
            
            temp = app.rpath;
            app.rpath = cell(1,length(app.rigListBox.Items));
            for i = 1:length(app.rigListBox.Items) % each listbox item
                logi = contains(temp,app.rigListBox.Items{i});
                app.rpath{i} = temp{logi};
            end        
            
            app.RefreshButtonPushed; % Reload variables into listbox
            disp('File Order Correct');
        end

        % Button pushed function: IncorrectButton
        function IncorrectButtonPushed(app, event)
            % Shows options to reorder file order
            
            app.Swap2Button.Visible = 'on';
            app.InsertAboveButton.Visible = 'on';
            app.ReorderAllButton.Visible = 'on';
            app.ResetDefaultButton.Visible = 'on';
            app.RemoveButton.Visible = 'on';
            app.MergeButton.Visible = 'on';
            
            disp('File Order Incorrect, Showing Reorder Buttons');
        end

        % Button pushed function: ResetDefaultButton
        function ResetDefaultButtonPushed(app, event)
            % Reset listbox order to original order
            
            app.esrListBox.Items = app.name;
            [~,rname,~] = cellfun(@fileparts,app.rpath,'UniformOutput',false); % gets rig file names
            app.rigListBox.Items = rname;
            disp('File Order Reset to Default');
        end

        % Button pushed function: Swap2Button
        function Swap2ButtonPushed(app, event)
            % ability to click on 2 items to swap

            if length(app.esrListBox.Value) == 2
                L = app.esrListBox.Items;
                idx = find(contains(app.esrListBox.Items,app.esrListBox.Value));
                app.esrListBox.Items = [L(1:idx(1)-1),L(idx(2)),L(idx(1)+1:idx(2)-1),L(idx(1)),L(idx(2)+1:end)]; % reassemble listbox items
            end    
            if length(app.rigListBox.Value) == 2
                L = app.rigListBox.Items;
                idx = find(contains(app.rigListBox.Items,app.rigListBox.Value));
                app.rigListBox.Items = [L(1:idx(1)-1),L(idx(2)),L(idx(1)+1:idx(2)-1),L(idx(1)),L(idx(2)+1:end)];
            end
            if length(app.rigListBox.Value) ~= 2 && length(app.esrListBox.Value) ~= 2
                msgbox('Select 2 items to swap','Error','error')
                return
            end
        end

        % Value changed function: ReorderAllButton
        function ReorderAllButtonValueChanged(app, event)
            % ability to select one more items
            
            % main change's code in esr/rig ListBox
            if app.ReorderAllButton.Value == 1
                app.esrListBox.Value = {};
                app.rigListBox.Value = {};
                app.reorder = 2;
            else
                app.esrListBox.Value = {};
                app.rigListBox.Value = {};
                app.reorder = 0;
                app.Ve = {};
                app.Vr = {};
            end
        end

        % Value changed function: InsertAboveButton
        function InsertAboveButtonValueChanged(app, event)
            % ability to select one more items

            % main change's code in esr/rig ListBox
            if app.InsertAboveButton.Value == 1
                if ~isempty(app.esrListBox.Value) && ~isempty(app.rigListBox.Value)
                    msgbox('Only operate on either ESR or RIG!, deselect one to continue','Error','error')
                    app.InsertAboveButton.Value = 0;
                    return
                end
                
                if ~isempty(app.esrListBox.Value)
                    app.insert_idx = getlog(app,app.esrListBox.Items,app.esrListBox.Value);
                    app.esrListBox.Value = {};
                    app.reorder = 1;
                elseif ~isempty(app.rigListBox.Value)
                    app.insert_idx = getlog(app,app.rigListBox.Items,app.rigListBox.Value);
                    app.rigListBox.Value = {};
                    app.reorder = 1;
                else
                    msgbox('Select 1 or more items to insert','Error','error')
                    app.InsertAboveButton.Value = 0;
                    return
                end
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % removes selected items from their listbox, used if different number of testpoints in ESR/RIG
            
            log = getlog(app,app.esrListBox.Items,app.esrListBox.Value);
            app.esrListBox.Items = app.esrListBox.Items(~log);
            
            log = getlog(app,app.rigListBox.Items,app.rigListBox.Value);
            app.rigListBox.Items = app.rigListBox.Items(~log);
            
            app.esrListBox.Value = {};
            app.rigListBox.Value = {};
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            % merges all selected item's csv into one spreadsheet, name and position same as first in the list
            
            if length(app.esrListBox.Value) < 2 && length(app.rigListBox.Value) < 2
                msgbox('Select 2 or more items to merge into 1','Error','error')
                return
            end
            
            a = questdlg('WARNING: merge adds all csv selected onto the first file, deletes the rest','WARNING','Merge','Cancel','');
            if isequal(a,'Cancel')
                return
            end
            
            bar = waitbar(0,'Merging csv tables...(0%)');
            if length(app.esrListBox.Value) >= 2
                log = getlog(app,app.esrListBox.Items,app.esrListBox.Value);
                idx = find(log);
                log(idx(1)) = 0; % exclude first testpoint as it's kept in listbox
                [folder,~,e] = fileparts(app.epath{1});
                temp = app.esrListBox.Items;
                first = gettable(app,fullfile(folder,[temp{idx(1)},e])); waitbar(0.3,bar,'Merging csv tables...(30%)');
                rarray = table2array(first);
                for j = 2:length(idx) % loop over each csv file to merge with first
                    rig_next = gettable(app,fullfile(folder,[temp{idx(j)},e]));
                    rarray = [rarray;table2array(rig_next)];
                    waitbar(0.3+0.65*j/length(idx),bar,['Merging csv tables...(' num2str(100*(0.3+0.65*j/length(idx))) '%)']);
                    delete(fullfile(folder,[temp{idx(j)},e]))
                end
                first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                delete(fullfile(folder,[temp{idx(1)},e]));
                writetable(first, fullfile(folder,[temp{idx(1)},e]))
                waitbar(1,bar,'Merging csv tables...(100%)');
                app.esrListBox.Items = temp(~log);
                app.esrListBox.Value = {};
                delete(bar)
            end
            
            if length(app.rigListBox.Value) >= 2
                [~,rname,~] = cellfun(@fileparts,app.rpath,'UniformOutput',false);
                if ~isequal(app.rigListBox.Items,rname) % check order equal
                    msgbox('Merge RIG only works if order hasn''t been changed, press ''Correct'' and reload with ''File Order'' to merge','Error','error')
                    return
                end
                
                log = getlog(app,app.rigListBox.Items,app.rigListBox.Value);
                idx = find(log);
                log(idx(1)) = 0; % exclude first testpoint as it's kept in listbox
                  
                temp = app.rigListBox.Items;
                [folder1,~,e] = fileparts(app.rpath{idx(1)}); % uses rpath therefore must be original order
                first = gettable(app,fullfile(folder1,[temp{idx(1)},e])); waitbar(0.3,bar,'Merging csv tables...(30%)');
                rarray = table2array(first);
                for j = 2:length(idx) % loop over each csv file to merge with first
                    [folder,~,e] = fileparts(app.rpath{idx(j)});
                    rig_next = gettable(app,fullfile(folder,[temp{idx(j)},e]));
                    rarray = [rarray;table2array(rig_next)];
                    waitbar(0.3+0.65*j/length(idx),bar,['Merging csv tables...(' num2str(100*(0.3+0.65*j/length(idx))) '%)']);
                    rmdir(folder,'s') % removes folder and all files within after merging
                end
                first = array2table(rarray, 'VariableNames', first.Properties.VariableNames);
                delete(fullfile(folder1,[temp{idx(1)},e]));
                writetable(first, fullfile(folder1,[temp{idx(1)},e]))
                app.rigListBox.Items = temp(~log);
                app.rigListBox.Value = {};
                delete(bar)
                
                app.ResetDefaultButton.Enable = 'off'; 
            end
        end

        % Value changed function: AddgraphstoexcelCheckBox
        function AddgraphstoexcelCheckBoxValueChanged(app, event)
            if app.AddgraphstoexcelCheckBox.Value == false
                app.HighlightifPHE50CheckBox.Enable = 'off';
            else
                app.HighlightifPHE50CheckBox.Enable = 'on';
            end
        end

        % Button pushed function: MemoButton
        function MemoButtonPushed(app, event)
            % Opens the Memo written containing instructions and troubleshoots for this app
            % It is located in the H drive and will connect automatically if not connected (shortcut placed in S drive)
            
            if exist('H:','file') == 7
                winopen('H:\Admin\External\Memos & TNs\Memos\951 Flow Data Analysis App.docx');
            elseif exist('S:\Cody Lau\951 Flow Data Analysis App.docx - Shortcut.lnk','file') == 2
                winopen('S:\Cody Lau\951 Flow Data Analysis App.docx - Shortcut.lnk') % H drive connection shortcut, could be moved by others
                disp('Connected to the [H] drive')
            elseif exist('S:\Filer (BOH2-EUD-FAPS02CME-Performance) (H).lnk','file') == 2
                winopen('S:\Filer (BOH2-EUD-FAPS02CME-Performance) (H).lnk')
                winopen('H:\Admin\External\Memos & TNs\Memos\951 Flow Data Analysis App.docx');
            else
                msgbox('Connect to the [H] drive first','Error','error')
                return
            end
            disp('Memo Opened')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryandPlotsforFlowDataUIFigure = uifigure('Visible', 'off');
            app.SummaryandPlotsforFlowDataUIFigure.Name = 'Summary and Plots for Flow Data';
            app.flow = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.UIAxes = uiaxes(app.flow);
            title(app.UIAxes, 'ESR Fuel Flow')
            xlabel(app.UIAxes, 'Q^2 (USgpm)^2')
            ylabel(app.UIAxes, 'dP corrected for catenary (psi)')
            app.CalculateandPlotFlowButton = uibutton(app.flow, 'push');
            app.CalculateandPlotFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotFlowButtonPushed, true);
            app.CalculateandPlotFlowButton.Tooltip = {'Gets new K1 from slope and compares with RCU''s estimation'};
            app.CalculateandPlotFlowButton.Text = 'Calculate and Plot Flow';
            app.UIAxes_2 = uiaxes(app.flow);
            title(app.UIAxes_2, 'RIG Fuel Flow')
            xlabel(app.UIAxes_2, 'Q^2 (USgpm)^2')
            ylabel(app.UIAxes_2, 'dP corrected for catenary (psi)')
            app.Lamp_2 = uilamp(app.flow);
            app.ButtonGroup_5 = uibuttongroup(app.flow);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.HoseEndButton = uiradiobutton(app.ButtonGroup_5);
            app.HoseEndButton.Text = 'Hose End';
            app.HoseEndButton.Value = true;
            app.LatchButton = uiradiobutton(app.ButtonGroup_5);
            app.LatchButton.Tooltip = {'Exclude coupling in End Pressure'};
            app.LatchButton.Text = 'Latch';
            app.PlotCouplingLossButton = uibutton(app.flow, 'push');
            app.PlotCouplingLossButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCouplingLossButtonPushed, true);
            app.PlotCouplingLossButton.Tooltip = {'P(HE) - P(L) against Flow Rate'; ' indicates regulator opened/closed'};
            app.PlotCouplingLossButton.Text = 'Plot Coupling Loss';
            app.ELabel = uilabel(app.flow);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.flow);
            app.RLabel.Text = 'R';
            app.SlopeEditField_2 = uieditfield(app.flow, 'numeric');
            app.SlopeEditField_2.ValueDisplayFormat = '%.4e';
            app.SlopeEditField_2.Editable = 'off';
            app.KlossEditFieldLabel = uilabel(app.flow);
            app.KlossEditFieldLabel.Text = 'K(loss)';
            app.KlossEditField = uieditfield(app.flow, 'numeric');
            app.KlossEditField.ValueDisplayFormat = '%.4e';
            app.KlossEditField.Editable = 'off';
            app.ExportFlowDataButton = uibutton(app.flow, 'push');
            app.ExportFlowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFlowDataButtonPushed, true);
            app.ExportFlowDataButton.Tooltip = {'Add data to summary or on its own'};
            app.ExportFlowDataButton.Text = 'Export Flow Data';
            app.ButtonGroup_4 = uibuttongroup(app.flow);
            app.ToSummaryButton = uiradiobutton(app.ButtonGroup_4);
            app.ToSummaryButton.Text = 'To Summary';
            app.ToSummaryButton.Value = true;
            app.AsNewButton = uiradiobutton(app.ButtonGroup_4);
            app.AsNewButton.Text = 'As New';
            app.SummaryCopyButton = uiradiobutton(app.ButtonGroup_4);
            app.SummaryCopyButton.Text = 'Summary Copy';
            app.OpenButton = uibutton(app.flow, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open excel of summary or new flow data'};
            app.OpenButton.Text = 'Open';
            app.CouplingRegulationKnobLabel = uilabel(app.flow);
            app.CouplingRegulationKnobLabel.Enable = 'off';
            app.CouplingRegulationKnobLabel.Text = 'Coupling Regulation';
            app.CouplingRegulationKnob = uiknob(app.flow, 'discrete');
            app.CouplingRegulationKnob.Items = {'All Data', 'Remove >50', 'Excluded'};
            app.CouplingRegulationKnob.Enable = 'off';
            app.CouplingRegulationKnob.Tooltip = {'Exclude points if P(HE) > 50 psi'};
            app.CouplingRegulationKnob.Value = 'All Data';
            app.KHLEditFieldLabel = uilabel(app.flow);
            app.KHLEditFieldLabel.Text = {'K(HL)'; ''};
            app.KHLEditField = uieditfield(app.flow, 'numeric');
            app.KHLEditField.Tooltip = {'Hose Loss (K1)'};
            app.KHLEditField.Value = 8.12e-05;
            app.KCGEditFieldLabel = uilabel(app.flow);
            app.KCGEditFieldLabel.Text = 'K(CG)';
            app.KCGEditField = uieditfield(app.flow, 'numeric');
            app.KCGEditField.Tooltip = {'Catenary Gain (K2)'};
            app.KCGEditField.Value = 1.1544;
            app.KCLEditFieldLabel = uilabel(app.flow);
            app.KCLEditFieldLabel.Text = {'K(CL)'; ''};
            app.KCLEditField = uieditfield(app.flow, 'numeric');
            app.KCLEditField.Tooltip = {'Coupling Loss (Included for Latch)'};
            app.AddgraphstoexcelCheckBox = uicheckbox(app.flow);
            app.AddgraphstoexcelCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddgraphstoexcelCheckBoxValueChanged, true);
            app.AddgraphstoexcelCheckBox.Tooltip = {'3 charts in excel format: Q^2 vs dP (HoseEnd)'; ' Q^2 vs dP (Latch)'; ' Q vs coupling loss'};
            app.AddgraphstoexcelCheckBox.Text = 'Add graphs to excel';
            app.AddgraphstoexcelCheckBox.Value = true;
            app.dHmEditFieldLabel = uilabel(app.flow);
            app.dHmEditFieldLabel.Text = 'dH (m)';
            app.dHEditField = uieditfield(app.flow, 'numeric');
            app.dHEditField.Value = 4.21;
            app.HighlightifPHE50CheckBox = uicheckbox(app.flow);
            app.HighlightifPHE50CheckBox.Tooltip = {'Colours cell in summary if P(HE) > 50 in RIG'};
            app.HighlightifPHE50CheckBox.Text = 'Highlight if P(HE)>50';
            app.summary = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SaveSummaryButton = uibutton(app.summary, 'push');
            app.SaveSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSummaryButtonPushed, true);
            app.SaveSummaryButton.Tooltip = {'Saves summary of selected columns as .xlsx'; 'Make sure to have same columns when re-selecting'};
            app.SaveSummaryButton.Text = 'Save Summary';
            app.OpenSummaryButton = uibutton(app.summary, 'push');
            app.OpenSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSummaryButtonPushed, true);
            app.OpenSummaryButton.Text = 'Open Summary';
            app.Lamp = uilamp(app.summary);
            app.Lamp.Tooltip = {'Green for successful save'};
            app.ExpandColmunsButton = uibutton(app.summary, 'push');
            app.ExpandColmunsButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandColmunsButtonPushed, true);
            app.ExpandColmunsButton.Tooltip = {'Press again to collapse'};
            app.ExpandColmunsButton.Text = 'Expand Colmuns';
            app.AverageCheckBox = uicheckbox(app.summary);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.MaximumCheckBox = uicheckbox(app.summary);
            app.MaximumCheckBox.Text = 'Maximum';
            app.MinimumCheckBox = uicheckbox(app.summary);
            app.MinimumCheckBox.Text = 'Minimum';
            app.ImportSummaryButton = uibutton(app.summary, 'push');
            app.ImportSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSummaryButtonPushed, true);
            app.ImportSummaryButton.Tooltip = {'Import summary created using this app'; ' for flow calculations'};
            app.ImportSummaryButton.Text = 'Import Summary';
            app.top = uipanel(app.SummaryandPlotsforFlowDataUIFigure);
            app.FilesHelpButton = uibutton(app.top, 'push');
            app.FilesHelpButton.ButtonPushedFcn = createCallbackFcn(app, @FilesHelpButtonPushed, true);
            app.FilesHelpButton.Tooltip = {'File importng format tips'};
            app.FilesHelpButton.Text = 'Files Help';
            app.PodDropDownLabel = uilabel(app.top);
            app.PodDropDownLabel.Text = 'Pod';
            app.PodDropDown = uidropdown(app.top);
            app.PodDropDown.Items = {'905', '908', '908EH', '910', '912'};
            app.PodDropDown.ValueChangedFcn = createCallbackFcn(app, @PodDropDownValueChanged, true);
            app.PodDropDown.Tooltip = {'905 (imperial)'; '908 (SI)'; 'Affects flow equation calculations'};
            app.PodDropDown.Value = '905';
            app.ButtonGroup_6 = uibuttongroup(app.top);
            app.csvButton = uiradiobutton(app.ButtonGroup_6);
            app.csvButton.Tooltip = {'If contains other file types'; ' but data files must have .csv'};
            app.csvButton.Text = '.csv';
            app.csvButton.Value = true;
            app.noextensionsButton = uiradiobutton(app.ButtonGroup_6);
            app.noextensionsButton.Tooltip = {'If any mix of no extension and .csv'; ' but no other file types'};
            app.noextensionsButton.Text = 'no extensions';
            app.OpenESRButton = uibutton(app.ButtonGroup_6, 'push');
            app.OpenESRButton.ButtonPushedFcn = createCallbackFcn(app, @OpenESRButtonPushed, true);
            app.OpenESRButton.Tooltip = {'ESR folder containing all data'; ' ignores other file types if .csv selected'};
            app.OpenESRButton.Text = 'Open ESR';
            app.AddButton = uibutton(app.ButtonGroup_6, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Tooltip = {'Add .csv to all selected files. Use if folder contains a mix of file types / blank extension.'};
            app.AddButton.Text = 'Add';
            app.ButtonGroup_7 = uibuttongroup(app.top);
            app.SubfoldersButton = uiradiobutton(app.ButtonGroup_7);
            app.SubfoldersButton.Tooltip = {'Default test output format'; ' can contain multiple .csv files in each subfolder'};
            app.SubfoldersButton.Text = 'Subfolders';
            app.SubfoldersButton.Value = true;
            app.Allin1folderButton = uiradiobutton(app.ButtonGroup_7);
            app.Allin1folderButton.Tooltip = {'Similar to ESR folder'};
            app.Allin1folderButton.Text = 'All in 1 folder';
            app.OpenRIGButton = uibutton(app.ButtonGroup_7, 'push');
            app.OpenRIGButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRIGButtonPushed, true);
            app.OpenRIGButton.Tooltip = {'RIG folder containing subfolder for each testpoint (one or more csv files in each)'};
            app.OpenRIGButton.Text = 'Open RIG';
            app.EquationsButton = uibutton(app.top, 'push');
            app.EquationsButton.ButtonPushedFcn = createCallbackFcn(app, @EquationsButtonPushed, true);
            app.EquationsButton.Tooltip = {'Flow Rate/Pressure Equation used in bottom section'};
            app.EquationsButton.Text = 'Equations';
            app.FileOrderButton = uibutton(app.top, 'push');
            app.FileOrderButton.ButtonPushedFcn = createCallbackFcn(app, @FileOrderButtonPushed, true);
            app.FileOrderButton.Tooltip = {'Displays file orders in listbox'; ' options to rearrange'};
            app.FileOrderButton.Text = 'File Order';
            app.MemoButton = uibutton(app.top, 'push');
            app.MemoButton.ButtonPushedFcn = createCallbackFcn(app, @MemoButtonPushed, true);
            app.MemoButton.Tooltip = {'Open Memo Word doc (how to use app & FAQ)'};
            app.MemoButton.Text = 'Memo';
            app.PlotRIGButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotRIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRIGButtonPushed, true);
            app.PlotRIGButton.Tooltip = {'Select x-axis as the first selection'; 'then multiselect one or more y-axes'};
            app.PlotRIGButton.Text = 'Plot RIG';
            app.PlotESRButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.PlotESRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotESRButtonPushed, true);
            app.PlotESRButton.Tooltip = {'Select x-axis as the first selection'; 'then multiselect one or more y-axes'};
            app.PlotESRButton.Text = 'Plot ESR';
            app.TestPointsDropDownLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDownLabel.Text = 'Test Points';
            app.TestPointsDropDown = uidropdown(app.SummaryandPlotsforFlowDataUIFigure);
            app.TestPointsDropDown.Items = {'Select'};
            app.TestPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointsDropDownValueChanged, true);
            app.TestPointsDropDown.Tooltip = {'For individual variable plots'};
            app.TestPointsDropDown.Value = 'Select';
            app.RefreshButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Tooltip = {'Refresh both listboxes into the first testpoint for ''Save Summary'''};
            app.RefreshButton.Text = 'Refresh';
            app.SelectedEditFieldLabel = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.SelectedEsrEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SelectedEsrEditField.ValueDisplayFormat = '%.0f';
            app.SelectedEsrEditField.Editable = 'off';
            app.SelectedEditField_2Label = uilabel(app.SummaryandPlotsforFlowDataUIFigure);
            app.SelectedEditField_2Label.Text = 'Selected';
            app.SelectedRigEditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'numeric');
            app.SelectedRigEditField.ValueDisplayFormat = '%.0f';
            app.SelectedRigEditField.Editable = 'off';
            app.esrListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.esrListBox.Items = {};
            app.esrListBox.Multiselect = 'on';
            app.esrListBox.ValueChangedFcn = createCallbackFcn(app, @esrListBoxValueChanged, true);
            app.esrListBox.Tooltip = {'Hold down Ctrl to multiselect'};
            app.esrListBox.Value = {};
            app.rigListBox = uilistbox(app.SummaryandPlotsforFlowDataUIFigure);
            app.rigListBox.Items = {};
            app.rigListBox.Multiselect = 'on';
            app.rigListBox.ValueChangedFcn = createCallbackFcn(app, @rigListBoxValueChanged, true);
            app.rigListBox.Tooltip = {'Hold down Ctrl to multiselect'};
            app.rigListBox.Value = {};
            app.EditField = uieditfield(app.SummaryandPlotsforFlowDataUIFigure, 'text');
            app.EditField.Value = 'You found the Easter Egg! jk just a debug command window input bar...';
            app.ExecuteButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Tooltip = {'For Debug use only'; ''};
            app.ExecuteButton.Text = 'Execute';
            app.CorrectButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Visible = 'off';
            app.CorrectButton.Tooltip = {'Redisplays GUI'};
            app.CorrectButton.Text = 'Correct';
            app.IncorrectButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.IncorrectButton.ButtonPushedFcn = createCallbackFcn(app, @IncorrectButtonPushed, true);
            app.IncorrectButton.Visible = 'off';
            app.IncorrectButton.Tooltip = {'Options to rearrange items '};
            app.IncorrectButton.Text = 'Incorrect';
            app.Swap2Button = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.Swap2Button.ButtonPushedFcn = createCallbackFcn(app, @Swap2ButtonPushed, true);
            app.Swap2Button.Visible = 'off';
            app.Swap2Button.Tooltip = {'Multiselect 2 items then press this to swap'};
            app.Swap2Button.Text = 'Swap 2';
            app.ResetDefaultButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.ResetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultButtonPushed, true);
            app.ResetDefaultButton.Visible = 'off';
            app.ResetDefaultButton.Tooltip = {'Return to original order'};
            app.ResetDefaultButton.Text = 'Reset Default';
            app.ReorderAllButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'state');
            app.ReorderAllButton.ValueChangedFcn = createCallbackFcn(app, @ReorderAllButtonValueChanged, true);
            app.ReorderAllButton.Visible = 'off';
            app.ReorderAllButton.Tooltip = {'Click in correct order the new list'; ' no need to multiselect'};
            app.ReorderAllButton.Text = 'Reorder All';
            app.InsertAboveButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'state');
            app.InsertAboveButton.ValueChangedFcn = createCallbackFcn(app, @InsertAboveButtonValueChanged, true);
            app.InsertAboveButton.Visible = 'off';
            app.InsertAboveButton.Tooltip = {'Select one or more items'; ' press this'; ' then select the item which they are to be placed above of'};
            app.InsertAboveButton.Text = 'Insert Above';
            app.RemoveButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Visible = 'off';
            app.RemoveButton.Tooltip = {'Select items to remove from the lists'; ' then press this button'};
            app.RemoveButton.Text = 'Remove';
            app.MergeButton = uibutton(app.SummaryandPlotsforFlowDataUIFigure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Visible = 'off';
            app.MergeButton.Tooltip = {'Select testpoints to merge into the first csv spreadsheet (affects actual files)'};
            app.MergeButton.Text = 'Merge';
            % Show the figure after all components are created
            app.SummaryandPlotsforFlowDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuelflow_analysis_5_0_2018b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryandPlotsforFlowDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryandPlotsforFlowDataUIFigure)
        end
    end
end